classdef showClusterData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UITable   matlab.ui.control.Table
    end

    
    properties (Access = private)
        CallingApp % app that calls this app
        tableData % the passed table data
    end
    
    methods (Access = public)      
        function updateTable(app,tableData)
            % Rearrange the cluster data as a table (adding cluster IDs on the left and averaged values on top) and
            % update it.
            C = cell(size(tableData,1) + 1, 6);
            C(2:end, 2:end) = compose({'%.0f', '%.1f', '%u','%.1f','%.1f'},tableData);
            C(1,2:4) = compose({'%.0f', '%.1f', '%.1f'},mean(tableData(:,1:3),1));
            C(2:end,1) = cellstr(string(1:size(tableData,1))');
            C{1,1} = 'mean';
            app.UITable.Data = cell2table(C);
            app.UITable.ColumnName = [" ","area (nm²)","diameter (nm)","N","x position (nm)","y position (nm)"];
            app.UITable.ColumnWidth = 'fit';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, tableData)
            % Store the calling app and the passed table data.
            app.CallingApp = caller;
            app.tableData = tableData;

            % Update the table.
            updateTable(app, app.tableData);            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'cluster data';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = showClusterData(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end