classdef fineTuneLocParams < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        fineTuneLocParamsUIFigure     matlab.ui.Figure
        dataAndCalibrationFilesLabel  matlab.ui.control.Label
        sptPALMfilenameLabel          matlab.ui.control.Label
        sptPALMfilename               matlab.ui.control.Label
        noiseFilenameLabel            matlab.ui.control.Label
        noiseFilename                 matlab.ui.control.Label
        acquisitionParametersLabel    matlab.ui.control.Label
        conversionEditFieldLabel      matlab.ui.control.Label
        conversionEditField           matlab.ui.control.NumericEditField
        offsetEditFieldLabel          matlab.ui.control.Label
        offsetEditField               matlab.ui.control.NumericEditField
        pixelSizeEditFieldLabel       matlab.ui.control.Label
        pixelSizeEditField            matlab.ui.control.NumericEditField
        peakCandidateFindingLabel     matlab.ui.control.Label
        filterSizeEditFieldabel       matlab.ui.control.Label
        filterSizeEditField           matlab.ui.control.NumericEditField
        cutOffEditFieldLabel          matlab.ui.control.Label
        cutOffEditField               matlab.ui.control.NumericEditField
        fittingParametersLabel        matlab.ui.control.Label
        PSFROIsizeEditFieldLabel      matlab.ui.control.Label
        PSFROIsizeEditField           matlab.ui.control.NumericEditField
        previewFrameEditFieldLabel    matlab.ui.control.Label
        previewFrameEditField         matlab.ui.control.NumericEditField
        CancelButton                  matlab.ui.control.Button
        ApplyNewParametersButton      matlab.ui.control.Button
        statusLabel                   matlab.ui.control.Label
        colormapDropDownLabel         matlab.ui.control.Label
        colormapDropDown              matlab.ui.control.DropDown
        previewAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % app that calls this app
        InterruptDialog % Progress window that blocks user input to the calling app
        oldL % structure holding the initial fitting parameters
        L % structure holding the currently set fitting parameters
        newL % structure holding the new
        c % colors
        t % Tiff object
        hPreviewImage % handle of the preview image
        hScatterLocs % handle of the scatter plot for the localization coordinates
    end
    
    methods (Access = private)
        
        % This visual organization of this app is based on the MATLAB code from Jonas Ries, published as the fit3Dspline
        % package (specifically: simplefitter_GUI).
        
        function newStr = splitPathText(~, oldStr, nChars)
            % Splits an overlong filepath |oldsStr| into two chunks of at most |nChars| characters, resulting in a
            % string array |newStr| with its first line ending with '...' and its second line beginning with '...'.
            
            % Pre-allocate.
            newStr = cell(2,1);
            
            % First line.
            newStr{1} = [oldStr(1:nChars-3), '...'];
            
            % Check how much the second line must be shortened to avoid doubled sections.
            if length(oldStr) < 2 * nChars - 6
                newStr{2} = ['...',oldStr(length(oldStr)-nChars+6-1:end)];
            else
                newStr{2} = ['...',oldStr(end-nChars+1+3:end)];
            end
        end
        
        function calculatePreview(app)
            % Set the buttons.
            app.ApplyNewParametersButton.Enable = 'off';
            app.ApplyNewParametersButton.FontColor = app.c.pushButton.off(1,:);
            app.ApplyNewParametersButton.BackgroundColor = app.c.pushButton.off(2,:);
            app.CancelButton.Enable = 'off';
            app.CancelButton.FontColor = app.c.pushButton.off(1,:);
            app.CancelButton.BackgroundColor = app.c.pushButton.off(2,:);

            % Get the specified frame from the input sptPALM file.
            setDirectory(app.t,app.previewFrameEditField.Value);
            thisImage = read(app.t);

            % Update the image and hide the localizaton coordinates
            app.hPreviewImage.CData = thisImage;
            app.hScatterLocs.Visible = 'off';    

            % Rearrange the localization parameters.
            FitParams = rmfield(app.L, 'limits');
            FitParams = reshape(namedargs2cell(FitParams),2,[]);

            % Adapt the variance map for EMCCD files.
            if FitParams{2} == 1
                FitParams{2} = zeros(size(thisImage),'single');
            end

            % Perform the localization.
            app.statusLabel.Text = 'Fitting ...';
            pointList = localizeSMLMdata(thisImage, FitParams{:});

            if isempty(pointList)
                % When no localizations were found, just display the status message.
                app.statusLabel.Text = 'No localizations found, try a different frame or change parameters.';
            else
                % Else update the localization coordinates in the image and report the number of localizations.
                set(app.hScatterLocs, 'XData', pointList(:,2)/app.L.pixelSize, 'YData', pointList(:,3)/app.L.pixelSize);
                app.hScatterLocs.Visible = 'on';
                app.statusLabel.Text = sprintf('Preview done, %u localizations.', size(pointList,1));

                % Check if the parameters are different to the initial ones to properly color the button.
                if ~isequal(app.oldL, app.L)
                    app.ApplyNewParametersButton.Enable = 'on';
                    app.ApplyNewParametersButton.FontColor = app.c.pushButton.on(1,:);
                    app.ApplyNewParametersButton.BackgroundColor = app.c.pushButton.on(2,:);
                end
            end

            % Reenable the 'Cancel' button.
            app.CancelButton.Enable = 'on';
            app.CancelButton.FontColor = app.c.pushButton.on(1,:);
            app.CancelButton.BackgroundColor = app.c.pushButton.on(2,:);

            drawnow;
        end

        function closeApp(app)
            % Close the Tiff object.
            close(app.t);
            warning('on','imageio:tiffutils:libtiffWarning');

            % Close the progress dialog box, enabling user interaction with the calling app again.
            close(app.InterruptDialog);
            delete(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, inputFilename, noiseFilename, stackDimensions)
            % Store calling app.
            app.CallingApp = caller;

            % Overlay the calling app with a progress dialog box that disables user input.
            app.InterruptDialog = uiprogressdlg(app.CallingApp.MainUIFigure);

            % Create and store a Tiff object for the sptPALM input file (this is much faster if only one frame, i.e.
            % TIFF IFD, is needed at a time).
            warning('off','imageio:tiffutils:libtiffWarning');
            app.t = Tiff(inputFilename);

            % Get and store the initial parameters from the calling app and copy it.
            app.oldL = app.CallingApp.S.localization;
            app.L = app.oldL;

            % Get some colors.
            app.c = getColors;

            % Choose the colors for active / disabled push buttons, with rows: text color, background color.
            app.c.pushButton.on = [1,1,1; app.c.UT{'darkGreen',:}];
            app.c.pushButton.off = [0,0,0;0.96,0.96,0.96];
            
            % Display sptPALM filename (split into two rows if necessary).
            if length(inputFilename) > 30
                app.sptPALMfilename.Text = splitPathText(app, inputFilename, 30);
            else
                app.sptPALMfilename.Text = inputFilename;
            end

            % Display noise filename (split into two rows if necessary).
            if length(noiseFilename) > 30
                app.noiseFilename.Text = splitPathText(app, noiseFilename, 30);
            else
                app.noiseFilename.Text = noiseFilename;
            end
            
            % Fill in the current localization parameters.
            app.conversionEditField.Value = app.L.conversion;
            app.offsetEditField.Value = app.L.offset;
            app.pixelSizeEditField.Value = app.L.pixelSize;
            app.filterSizeEditField.Value = app.L.filterSize;
            app.cutOffEditField.Value = app.L.cutoff;
            app.PSFROIsizeEditField.Value = app.L.PSFROI;

            % Choose an image frame that is at around 10% of the total movie length.
            nFrames = stackDimensions(3);
            app.previewFrameEditField.Limits = [1, nFrames];
            app.previewFrameEditField.Value = ceil(round(nFrames/10, -(floor(log10(nFrames))-1)));

            % Prepare and store two image objects for the preview axes, the image itself (with scaled colors) and the
            % localizations as a scatter plot.
            app.hPreviewImage = imagesc(app.previewAxes,'CData',zeros(stackDimensions([2 1])));
            app.hScatterLocs = scatter(app.previewAxes,1,1,120,"white","LineWidth",1);

            % Adjust the axes limits to compensate for the pixel offset and set a colormap.
            app.previewAxes.XLim = [0, stackDimensions(1)] + 0.5;
            app.previewAxes.YLim = [0, stackDimensions(2)] + 0.5;
            app.previewAxes.Colormap = app.c.cmap{app.colormapDropDown.Value,:}{:};

            % Only allow some user interaction in the preview axes, hide the interaction bar.
            % Disable plot interactivity and hide toolbars for the left plot, but allow some plot interactions for the
            % right (magnified) track visualization plot.
            app.previewAxes.Toolbar.Visible = "off";
            app.previewAxes.Interactions = [zoomInteraction, panInteraction];
            enableDefaultInteractivity(app.previewAxes);
            
            % Calculate the preview for this frame.
            calculatePreview(app);
        end

        % Button pushed function: ApplyNewParametersButton
        function ApplyNewParametersButtonPushed(app, event)
            % Get the parameter structure S from the calling app.
            S = app.CallingApp.S;

            % Overwrite the localization parameters with the ones set in this app.
            S.localization = app.L;

            % Send the changed parameter structure to the calling app and close this app.
            app.CallingApp.externalChangeOfS(S);
            closeApp(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            closeApp(app);
        end

        % Close request function: fineTuneLocParamsUIFigure
        function fineTuneLocParamsUIFigureCloseRequest(app, event)
            closeApp(app);
        end

        % Value changed function: PSFROIsizeEditField, 
        % ...and 5 other components
        function conversionEditFieldValueChanged(app, event)
            % This handles changes of the following components: app.conversionEditField, app.offsetEditField,
            % app.pixelSizeEditField, app.filterSizeEditField, app.cutOffEditField, app.PSFROIsizeEditField.
            % If one of them is changed, all parameters are updated (for less clutter in the app).

            if strcmp(event.Source.Tag, 'PSFROI') && ~mod(event.Value,2)
                % For the PSF ROI, only odd values are allowed.
                event.Source.Value = event.Value + 1;
            end
            
            % Update |app.L| with the currently set localization parameters in the GUI.
            app.L.conversion = app.conversionEditField.Value;
            app.L.offset = app.offsetEditField.Value;
            app.L.pixelSize = app.pixelSizeEditField.Value;
            app.L.filterSize = app.filterSizeEditField.Value;
            app.L.cutoff = app.cutOffEditField.Value;
            app.L.PSFROI = app.PSFROIsizeEditField.Value;

            % Calculate the preview.
            calculatePreview(app);                        
        end

        % Value changed function: previewFrameEditField
        function previewFrameEditFieldValueChanged(app, event)
            % Calculate the preview.
            calculatePreview(app);
        end

        % Value changed function: colormapDropDown
        function colormapDropDownValueChanged(app, event)
            app.previewAxes.Colormap = app.c.cmap{app.colormapDropDown.Value,:}{:};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.fineTuneLocParamsUIFigure = uifigure('Visible', 'off');
            colormap(app.fineTuneLocParamsUIFigure, 'parula');
            app.fineTuneLocParamsUIFigure.Name = 'fine-tune localization parameters';
            app.fineTuneLocParamsUIFigure.CloseRequestFcn = createCallbackFcn(app, @fineTuneLocParamsUIFigureCloseRequest, true);
            app.previewAxes = uiaxes(app.fineTuneLocParamsUIFigure);
            title(app.previewAxes, 'Preview')
            app.previewAxes.YDir = 'reverse';
            app.previewAxes.XTick = [];
            app.previewAxes.YTick = [];
            app.colormapDropDown = uidropdown(app.fineTuneLocParamsUIFigure);
            app.colormapDropDown.Items = {'inferno', 'inferno (reversed)', 'plasma', 'plasma (reversed)', 'hot', 'hot (reversed)'};
            app.colormapDropDown.ValueChangedFcn = createCallbackFcn(app, @colormapDropDownValueChanged, true);
            app.colormapDropDown.Value = 'plasma';
            app.colormapDropDownLabel = uilabel(app.fineTuneLocParamsUIFigure);
            app.colormapDropDownLabel.Text = 'color map';
            app.statusLabel = uilabel(app.fineTuneLocParamsUIFigure);
            app.statusLabel.Text = 'idle';
            app.ApplyNewParametersButton = uibutton(app.fineTuneLocParamsUIFigure, 'push');
            app.ApplyNewParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyNewParametersButtonPushed, true);
            app.ApplyNewParametersButton.Text = {'Apply new'; 'parameters'};
            app.CancelButton = uibutton(app.fineTuneLocParamsUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.previewFrameEditField = uieditfield(app.fineTuneLocParamsUIFigure, 'numeric');
            app.previewFrameEditField.ValueDisplayFormat = '%.0f';
            app.previewFrameEditField.ValueChangedFcn = createCallbackFcn(app, @previewFrameEditFieldValueChanged, true);
            app.previewFrameEditField.Value = 200;
            app.previewFrameEditFieldLabel = uilabel(app.fineTuneLocParamsUIFigure);
            app.previewFrameEditFieldLabel.Text = 'frame';
            app.PSFROIsizeEditField = uieditfield(app.fineTuneLocParamsUIFigure, 'numeric');
            app.PSFROIsizeEditField.ValueDisplayFormat = '%.0f';
            app.PSFROIsizeEditField.ValueChangedFcn = createCallbackFcn(app, @conversionEditFieldValueChanged, true);
            app.PSFROIsizeEditField.Tag = 'PSFROI';
            app.PSFROIsizeEditField.Value = 13;
            app.PSFROIsizeEditFieldLabel = uilabel(app.fineTuneLocParamsUIFigure);
            app.PSFROIsizeEditFieldLabel.Text = 'PSF ROI size (pixel)';
            app.fittingParametersLabel = uilabel(app.fineTuneLocParamsUIFigure);
            app.fittingParametersLabel.Text = 'Fitting parameters:';
            app.cutOffEditField = uieditfield(app.fineTuneLocParamsUIFigure, 'numeric');
            app.cutOffEditField.ValueDisplayFormat = '%.1f';
            app.cutOffEditField.ValueChangedFcn = createCallbackFcn(app, @conversionEditFieldValueChanged, true);
            app.cutOffEditField.Value = 3.5;
            app.cutOffEditFieldLabel = uilabel(app.fineTuneLocParamsUIFigure);
            app.cutOffEditFieldLabel.Text = 'cut-off (photons)';
            app.filterSizeEditField = uieditfield(app.fineTuneLocParamsUIFigure, 'numeric');
            app.filterSizeEditField.ValueDisplayFormat = '%.1f';
            app.filterSizeEditField.ValueChangedFcn = createCallbackFcn(app, @conversionEditFieldValueChanged, true);
            app.filterSizeEditField.Value = 1.2;
            app.filterSizeEditFieldabel = uilabel(app.fineTuneLocParamsUIFigure);
            app.filterSizeEditFieldabel.Text = 'Filter size (pixel)';
            app.peakCandidateFindingLabel = uilabel(app.fineTuneLocParamsUIFigure);
            app.peakCandidateFindingLabel.Text = 'Peak candidate finding:';
            app.pixelSizeEditField = uieditfield(app.fineTuneLocParamsUIFigure, 'numeric');
            app.pixelSizeEditField.ValueDisplayFormat = '%.0f';
            app.pixelSizeEditField.ValueChangedFcn = createCallbackFcn(app, @conversionEditFieldValueChanged, true);
            app.pixelSizeEditField.Value = 100;
            app.pixelSizeEditFieldLabel = uilabel(app.fineTuneLocParamsUIFigure);
            app.pixelSizeEditFieldLabel.Text = 'pixel size (nm)';
            app.offsetEditField = uieditfield(app.fineTuneLocParamsUIFigure, 'numeric');
            app.offsetEditField.ValueDisplayFormat = '%.0f';
            app.offsetEditField.ValueChangedFcn = createCallbackFcn(app, @conversionEditFieldValueChanged, true);
            app.offsetEditField.Value = 100;
            app.offsetEditFieldLabel = uilabel(app.fineTuneLocParamsUIFigure);
            app.offsetEditFieldLabel.Text = 'offset (ADU)';
            app.conversionEditField = uieditfield(app.fineTuneLocParamsUIFigure, 'numeric');
            app.conversionEditField.ValueDisplayFormat = '%.2f';
            app.conversionEditField.ValueChangedFcn = createCallbackFcn(app, @conversionEditFieldValueChanged, true);
            app.conversionEditField.Value = 0.45;
            app.conversionEditFieldLabel = uilabel(app.fineTuneLocParamsUIFigure);
            app.conversionEditFieldLabel.Text = 'conversion (e-/ADU)';
            app.acquisitionParametersLabel = uilabel(app.fineTuneLocParamsUIFigure);
            app.acquisitionParametersLabel.Text = 'Acquisition parameters:';
            app.noiseFilename = uilabel(app.fineTuneLocParamsUIFigure);
            app.noiseFilename.Text = '';
            app.noiseFilenameLabel = uilabel(app.fineTuneLocParamsUIFigure);
            app.noiseFilenameLabel.Text = 'noise file';
            app.sptPALMfilename = uilabel(app.fineTuneLocParamsUIFigure);
            app.sptPALMfilename.Text = '';
            app.sptPALMfilenameLabel = uilabel(app.fineTuneLocParamsUIFigure);
            app.sptPALMfilenameLabel.Text = 'data file';
            app.dataAndCalibrationFilesLabel = uilabel(app.fineTuneLocParamsUIFigure);
            app.dataAndCalibrationFilesLabel.Text = 'Data and calibration files:';
            % Show the figure after all components are created
            app.fineTuneLocParamsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fineTuneLocParams(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.fineTuneLocParamsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.fineTuneLocParamsUIFigure)
        end
    end
end