classdef sptPALMmaskEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        sptPALMmaskeditorUIFigure  matlab.ui.Figure
        returnWithoutSavingButton  matlab.ui.control.Button
        brightnessSlider           matlab.ui.control.Slider
        drawROIbutton              matlab.ui.control.Button
        imagedisplayButtonGroup    matlab.ui.container.ButtonGroup
        maskedresultButton         matlab.ui.control.RadioButton
        originalButton             matlab.ui.control.RadioButton
        saveAndReturnButton        matlab.ui.control.Button
        resetButton                matlab.ui.control.Button
        imAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        c % colors
        hColorbar % colorbar
        cmap = gray;
        drawingInProgress = false; % flag for the ROI drawing being active or not
        hROI % currently set freehand ROI
        maskIm % binary mask
        hIm % image handle
        inputFilename % input filename
        outputFilename % output filename for the mask
        MIP % maximum intensity projection of the SMLM file
        pushButtons % handles to all pushbuttons
        CallingApp % app that calls this app
        InterruptDialog % Progress window that blocks user input to the calling app
    end
    
    methods (Access = private)
        
        function getMaskFromFile(app)
            % Delete the old mask in memory if it exists (set all values to 1).
            app.maskIm = ones(size(app.MIP(:,:,1)));

            % Collect all files with the "_mask" suffix (this results in a structure).
            [pathname, filename] = fileparts(app.outputFilename);
            TestFiles = dir(fullfile(pathname,strcat(filename,'.*')));

            if ~isempty(TestFiles)
                % Use >imfinfo< to mark all files that can be read by MATLAB as images.
                for iFile = 1:numel(TestFiles)
                    try ImageInfo = imfinfo(fullfile(TestFiles(iFile).folder, TestFiles(iFile).name));
                        if isequal([ImageInfo.Height, ImageInfo.Height], size(app.MIP(:,:,1)))
                            % The mask must have the same dimensions as the MIP.
                            TestFiles(iFile).isValidImage = true;
                            TestFiles(iFile).isTIF = strcmp(ImageInfo.Format,'tif');
                        else
                            TestFiles(iFile).isValidImage = false;
                        end

                    catch
                        TestFiles(iFile).isValidImage = false;
                    end
                end

                if sum([TestFiles.isValidImage]) > 0
                    % Delete all invalid files from the list.
                    TestFiles = TestFiles([TestFiles.isValidImage]);

                    % Prioritize the TIF file if it exists (the default output format of this app for the mask file),
                    % otherwise get the first valid image in the list.
                    if any([TestFiles.isTIF])
                        iFile = find([TestFiles.isTIF],1);
                    else
                        iFile = find([TestFiles.isValidImage],1);
                    end

                    % Convert it into a logical array by thresholding between the darkest and brightest pixels (use
                    % >im2gray< to cover RGB images).
                    app.maskIm = imread(fullfile(TestFiles(iFile).folder, TestFiles(iFile).name));
                    app.maskIm = imbinarize(mat2gray(im2gray(double(app.maskIm))));
                end
            end
        end
        
        function enableButtons(app, buttonNames, buttonState)
            % Switches a button between its enabled (buttonState=1) or disabled (buttonState=0) state, including color
            % changes. Here, |buttonNames| refers to the Tag property.

            % Intersect the push button tags with the selected tags to get the indices in the handle array.
            [~,ID] = intersect(arrayfun(@(x) x.Tag, app.pushButtons, 'uni', 0), buttonNames);
            buttonHandles = app.pushButtons(ID);

            switch buttonState
                case 0
                    set(buttonHandles,'Enable','off');
                    set(buttonHandles,'FontColor',app.c.pushButton.off(1,:));
                    set(buttonHandles,'BackgroundColor',app.c.pushButton.off(2,:));
                case 1
                    set(buttonHandles,'Enable','on');
                    set(buttonHandles,'FontColor',app.c.pushButton.on(1,:));
                    set(buttonHandles,'BackgroundColor',app.c.pushButton.on(2,:));
            end
        end
        
        function resetApp(app)
            % (Re)sets the app to the initial state.

            % Try to load the mask from a correspondingly named file.
            getMaskFromFile(app);
           
            if all(app.maskIm,"all")
                % Set the display choice to the original and disable the other radio button.
                app.originalButton.Value = true;
                app.maskedresultButton.Enable = "off";
                app.hIm.AlphaData = app.maskIm;
            else
                % Set the display choice to the masked result and enable the other radio button.
                app.maskedresultButton.Value = true;
                app.maskedresultButton.Enable = "on";
                app.hIm.AlphaData = (app.maskIm/2)+0.5;
            end

            % Set the brightness slider and colormap to their initial states.
            app.brightnessSlider.Value = 0;
            app.imAxes.Colormap = brighten(app.cmap, 0);

            % Set the initial state of the app's buttons.
            enableButtons(app,["reset","save & return"],0);
            enableButtons(app,["drawROI","return without saving"],1);
        end
        
        function closeApp(app)
            % Close the progress dialog box, enabling user interaction with the calling app again.
            close(app.InterruptDialog);
            delete(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, MIP, inputFilename)
            % Store calling app.
            app.CallingApp = caller;

            % Overlay the calling app with a progress dialog box that disables user input.
            app.InterruptDialog = uiprogressdlg(app.CallingApp.MainUIFigure);        
            
            % Store the MIP (change to grayscale image if necessary) and the input filename.
            app.MIP = mat2gray(im2gray(double(MIP)));
            app.inputFilename = inputFilename;
            
            % Get some colors.
            app.c = getColors;

            % Collect the handles to all push buttons.
            app.pushButtons = findobj(app.sptPALMmaskeditorUIFigure.Children,'-depth',1,'Type','uibutton','-not','Style','state');

            % Choose the colors for active / disabled push buttons, with rows: text color, background color.
            app.c.pushButton.on = [1,1,1; app.c.UT{'darkGreen',:}];
            app.c.pushButton.off = [0,0,0;0.96,0.96,0.96];

            % Display the image in the axes.
            app.hIm = imagesc(app.imAxes,'CData',double(app.MIP));
            app.imAxes.XLim = [0, size(MIP,2)] + 0.5;
            app.imAxes.YLim = [0, size(MIP,1)] + 0.5;
            app.imAxes.Color = app.c.UT{'carmine',:};
            app.imAxes.Colormap = app.cmap;

            % Show a gray colorbar under the image.
            app.hColorbar = colorbar(app.imAxes,'Ticks',[],'Location','south','Position',[0.282 0.08 0.669 0.025]);

            % Extract the file name and put it above the image.
            [pathname, shortFilename] = fileparts(app.inputFilename);
            app.imAxes.Title.Interpreter = 'none';
            app.imAxes.Title.String = shortFilename;

            % Create the output mask filename by adding '_mask' to the original filename, so the mask file for the SMLM
            % file "sample01.TIF" is "sample01_mask.TIF" and can be found in the same folder.
            app.outputFilename = fullfile(pathname,strcat(shortFilename,'_mask.TIF'));

            % Set the app to its initial state.
            resetApp(app);
        end

        % Button pushed function: drawROIbutton
        function drawROIbuttonPushed(app, event)
            if app.drawingInProgress == false
                % The ROI drawing was not active before, but now the user chose to draw a new ROI by clicking the
                % corresponding button.
                app.drawingInProgress = true;
                app.drawROIbutton.Text = 'accept ROI';
                app.imagedisplayButtonGroup.Enable = "off";
                enableButtons(app,["reset","save & return"],0);

                % Revert to the original image and hide the previous mask for now.
                app.originalButton.Value = true;
                app.hIm.AlphaData = ones(size(app.MIP(:,:,1)));
                
                app.hROI = drawfreehand(app.imAxes,'Color',app.c.UT{'darkGreen',:},'FaceAlpha',0.3);
                app.maskedresultButton.Enable = "off";
                app.imagedisplayButtonGroup.Enable = "off";

                if isempty(app.hROI.Position)
                    % The user canceled drawing the ROI with the Escape key.
                    app.drawingInProgress = false;
                    app.drawROIbutton.Text = 'start drawing new ROI';
                    delete(app.hROI);

                    if ~all(app.maskIm,'all')
                        % Revert to the previously stored mask.
                        app.hIm.AlphaData = (app.maskIm/2)+0.5;
                        app.maskedresultButton.Value = true;
                        app.maskedresultButton.Enable = "on";
                        app.imagedisplayButtonGroup.Enable = "on";
                        enableButtons(app,["reset","save & return"],1);
                    else
                        % Revert back to the display of the MIP.
                        app.hIm.AlphaData = app.maskIm;
                        app.originalButton.Value = true;
                        app.maskedresultButton.Enable = "off";
                        enableButtons(app,"reset",1);
                    end               
                end

            elseif app.drawingInProgress == true
                % The ROI drawing was active, the user chose to accept the current ROI by clicking the corresponding
                % button or canceling by pressing the escape button.
                app.drawingInProgress = false;
                app.drawROIbutton.Text = 'start drawing new ROI';

                % Convert the drawn ROI to a binary mask.
                app.maskIm = createMask(app.hROI);
                delete(app.hROI);
                app.hIm.AlphaData = (app.maskIm/2)+0.5;
                app.maskedresultButton.Value = true;
                app.maskedresultButton.Enable = "on";
                app.imagedisplayButtonGroup.Enable = "on";
                enableButtons(app,["reset","save & return"],1);
            end
        end

        % Value changed function: brightnessSlider
        function contrastSliderValueChanged(app, event)
           app.imAxes.Colormap = brighten(app.cmap, app.brightnessSlider.Value);
        end

        % Selection changed function: imagedisplayButtonGroup
        function imagedisplayButtonGroupSelectionChanged(app, event)
            selectedButton = app.imagedisplayButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'original')
                app.hIm.AlphaData = ones(size(app.MIP(:,:,1)));
            elseif strcmp(selectedButton.Text,'masked result')
                app.hIm.AlphaData = (app.maskIm/2)+0.5;
            end
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            % Resets the app to a state as if it was just started.
            resetApp(app);
        end

        % Close request function: sptPALMmaskeditorUIFigure
        function sptPALMmaskeditorUIFigureCloseRequest(app, event)
            closeApp(app);
        end

        % Button pushed function: saveAndReturnButton
        function saveAndReturnButtonPushed(app, event)
            % Store the currently set mask as a binary TIF file and return to the calling app.
            imwrite(app.maskIm, app.outputFilename);

            % Trigger updates following the creation of the new mask file.
            app.CallingApp.externalChangeOfMask(app.inputFilename);

            % Return to the calling app.            
            closeApp(app);
        end

        % Button pushed function: returnWithoutSavingButton
        function returnWithoutSavingButtonPushed(app, event)
            % Return to the calling app.            
            closeApp(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.sptPALMmaskeditorUIFigure = uifigure('Visible', 'off');
            app.sptPALMmaskeditorUIFigure.Name = 'sptPALM mask editor';
            app.sptPALMmaskeditorUIFigure.CloseRequestFcn = createCallbackFcn(app, @sptPALMmaskeditorUIFigureCloseRequest, true);
            app.imAxes = uiaxes(app.sptPALMmaskeditorUIFigure);
            title(app.imAxes, 'file name')
            app.imAxes.YDir = 'reverse';
            app.imAxes.XTick = [];
            app.imAxes.YTick = [];
            app.resetButton = uibutton(app.sptPALMmaskeditorUIFigure, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Tag = 'reset';
            app.resetButton.Text = 'reset';
            app.saveAndReturnButton = uibutton(app.sptPALMmaskeditorUIFigure, 'push');
            app.saveAndReturnButton.ButtonPushedFcn = createCallbackFcn(app, @saveAndReturnButtonPushed, true);
            app.saveAndReturnButton.Tag = 'save & return';
            app.saveAndReturnButton.Text = 'save & return';
            app.imagedisplayButtonGroup = uibuttongroup(app.sptPALMmaskeditorUIFigure);
            app.imagedisplayButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @imagedisplayButtonGroupSelectionChanged, true);
            app.imagedisplayButtonGroup.Title = 'image display';
            app.originalButton = uiradiobutton(app.imagedisplayButtonGroup);
            app.originalButton.Text = 'original';
            app.originalButton.Value = true;
            app.maskedresultButton = uiradiobutton(app.imagedisplayButtonGroup);
            app.maskedresultButton.Text = 'masked result';
            app.drawROIbutton = uibutton(app.sptPALMmaskeditorUIFigure, 'push');
            app.drawROIbutton.ButtonPushedFcn = createCallbackFcn(app, @drawROIbuttonPushed, true);
            app.drawROIbutton.Tag = 'drawROI';
            app.drawROIbutton.Text = 'start';
            app.brightnessSlider = uislider(app.sptPALMmaskeditorUIFigure);
            app.brightnessSlider.Limits = [-1 1];
            app.brightnessSlider.MajorTicks = [];
            app.brightnessSlider.ValueChangedFcn = createCallbackFcn(app, @contrastSliderValueChanged, true);
            app.brightnessSlider.MinorTicks = [];
            app.returnWithoutSavingButton = uibutton(app.sptPALMmaskeditorUIFigure, 'push');
            app.returnWithoutSavingButton.ButtonPushedFcn = createCallbackFcn(app, @returnWithoutSavingButtonPushed, true);
            app.returnWithoutSavingButton.Tag = 'return without saving';
            app.returnWithoutSavingButton.Text = 'return without saving';
            % Show the figure after all components are created
            app.sptPALMmaskeditorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sptPALMmaskEditor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.sptPALMmaskeditorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.sptPALMmaskeditorUIFigure)
        end
    end
end