classdef OneFlowTraX < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainUIFigure                    matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        LocalizationTab                 matlab.ui.container.Tab
        LocsOpenParameterAppButton      matlab.ui.control.Button
        FineTuneParametersButton        matlab.ui.control.Button
        LocsPSFsizePanel                matlab.ui.container.Panel
        LocsPSFsizeULEditFieldLabel     matlab.ui.control.Label
        LocsPSFsizeULEditField          matlab.ui.control.EditField
        LocsPSFsizeLLEditFieldLabel     matlab.ui.control.Label
        LocsPSFsizeLLEditField          matlab.ui.control.EditField
        LocsPSFsizeSlider               matlab.ui.control.RangeSlider
        LocsPSFsizeAxes                 matlab.ui.control.UIAxes
        LocsPhotonsPanel                matlab.ui.container.Panel
        LocsPhotonsULEditFieldLabel     matlab.ui.control.Label
        LocsPhotonsLLEditFieldLabel     matlab.ui.control.Label
        LocsPhotonsULEditField          matlab.ui.control.EditField
        LocsPhotonsLLEditField          matlab.ui.control.EditField
        LocsPhotonsSlider               matlab.ui.control.RangeSlider
        LocsPhotonsAxes                 matlab.ui.control.UIAxes
        LocsPrecisionPanel              matlab.ui.container.Panel
        LocsPrecisionULEditFieldLabel   matlab.ui.control.Label
        LocsPrecisionLLEditFieldLabel   matlab.ui.control.Label
        LocsPrecisionULEditField        matlab.ui.control.EditField
        LocsPrecisionLLEditField        matlab.ui.control.EditField
        LocsPrecisionSlider             matlab.ui.control.RangeSlider
        LocsPrecisionAxes               matlab.ui.control.UIAxes
        LocsStatusPanel                 matlab.ui.container.Panel
        LocsStatusLabel                 matlab.ui.control.Label
        LocsFilteredPanel               matlab.ui.container.Panel
        LocsFilteredPanelLabel          matlab.ui.control.Label
        TransferToTrackingButton        matlab.ui.control.Button
        LocalizeButton                  matlab.ui.control.Button
        LocsLocparamPanel               matlab.ui.container.Panel
        LocsLocparamPSFROIsizeEditFieldLabel  matlab.ui.control.Label
        LocsLocparamConversionEditField  matlab.ui.control.NumericEditField
        LocsLocparamOffsetEditField     matlab.ui.control.NumericEditField
        LocsLocparamPixelSizeEditField  matlab.ui.control.NumericEditField
        LocsLocparamFilterSizeEditField  matlab.ui.control.NumericEditField
        LocsLocparamCutOffEditField     matlab.ui.control.NumericEditField
        LocsLocparamPSFROIsizeEditField  matlab.ui.control.NumericEditField
        LocsLocparamCutOffEditFieldLabel  matlab.ui.control.Label
        LocsLocparamFilterSizeEditFieldLabel  matlab.ui.control.Label
        LocsLocparamPixelSizeEditFieldLabel  matlab.ui.control.Label
        LocsLocparamOffsetEditFieldLabel  matlab.ui.control.Label
        LocsLocparamConversionEditFieldLabel  matlab.ui.control.Label
        LocsFileInputPanel              matlab.ui.container.Panel
        LocsNoiseFileListBox            matlab.ui.control.ListBox
        LocsFileListBoxLabel            matlab.ui.control.Label
        LocsFileListBox                 matlab.ui.control.ListBox
        LocsSelectAllFilesCheckBox      matlab.ui.control.CheckBox
        LoadsCMOSnoiseFileButton        matlab.ui.control.Button
        LoadSMLMfilesButton             matlab.ui.control.Button
        TrackingTab                     matlab.ui.container.Tab
        MSDanalysisButton               matlab.ui.control.Button
        TracksFitparamPanel             matlab.ui.container.Panel
        TracksFrameRateEditFieldLabel   matlab.ui.control.Label
        TracksFrameRateEditField        matlab.ui.control.NumericEditField
        TracksMinTrackLengthEditField   matlab.ui.control.NumericEditField
        TracksAdjR2editField            matlab.ui.control.NumericEditField
        TracksLinFitPointsEditField     matlab.ui.control.EditField
        TracksLinFitPointsEditFieldLabel  matlab.ui.control.Label
        TracksAdjR2EditFieldLabel       matlab.ui.control.Label
        TracksMinTrackLengthEditFieldLabel  matlab.ui.control.Label
        TransferToClusterAnalyisButton  matlab.ui.control.Button
        TracksOpenParameterAppButton    matlab.ui.control.Button
        TracksVisPanel                  matlab.ui.container.Panel
        TracksVisPlottingOptionsPanel   matlab.ui.container.Panel
        TracksVisColorMapDropDownLabel  matlab.ui.control.Label
        TracksVisColorMapDropDown       matlab.ui.control.DropDown
        TracksVisColorSchemeDropDownLabel  matlab.ui.control.Label
        TracksVisColorSchemeDropDown    matlab.ui.control.DropDown
        TracksVisUnderlayTIFFcheckBox   matlab.ui.control.CheckBox
        TracksVisWhiteBackgroundCheckBox  matlab.ui.control.CheckBox
        TracksVisScaleBarDropDownLabel  matlab.ui.control.Label
        TracksVisScaleBarDropDown       matlab.ui.control.DropDown
        TracksVisLineWidthEditFieldLabel  matlab.ui.control.Label
        TracksVisLineWidthEditField     matlab.ui.control.NumericEditField
        TracksVisSaveTrackingImagesPanel  matlab.ui.container.Panel
        TracksVisSaveButton             matlab.ui.control.Button
        TracksVisMaskTracksPanel        matlab.ui.container.Panel
        TracksVisShowUnmaskedCheckBox   matlab.ui.control.CheckBox
        TracksVisEditMaskButton         matlab.ui.control.Button
        TracksVisLowerLimitEditFieldLabel  matlab.ui.control.Label
        TracksVisLowerLimitEditField    matlab.ui.control.NumericEditField
        TracksVisUpperLimitEditFieldLabel  matlab.ui.control.Label
        TracksVisUpperLimitEditField    matlab.ui.control.NumericEditField
        TracksVisNormalAxes             matlab.ui.control.UIAxes
        TracksVisZoomAxes               matlab.ui.control.UIAxes
        TracksMSDanalysisPanel          matlab.ui.container.Panel
        TracksDdistSplitEditFieldLabel  matlab.ui.control.Label
        TracksDdistSplitEditField       matlab.ui.control.NumericEditField
        Tracks2PopCheckBox              matlab.ui.control.CheckBox
        TracksMeanMSDautoScaleCheckBox  matlab.ui.control.CheckBox
        TracksMeanMSDresultLabel        matlab.ui.control.Label
        TracksDdistResultLabel          matlab.ui.control.Label
        TracksMeanMSDaxes               matlab.ui.control.UIAxes
        TracksDdistAxes                 matlab.ui.control.UIAxes
        TracksStatusPanel               matlab.ui.container.Panel
        TracksStatusLabel               matlab.ui.control.Label
        BuildTracksButton               matlab.ui.control.Button
        TracksBuildparamPanel           matlab.ui.container.Panel
        TracksMaxGapClosingEditFieldLabel  matlab.ui.control.Label
        TracksMaxLinkDistEditField      matlab.ui.control.NumericEditField
        TracksMaxGapClosingEditField    matlab.ui.control.NumericEditField
        TracksMaxLinkDistEditFieldLabel  matlab.ui.control.Label
        TracksFileInputPanel            matlab.ui.container.Panel
        TracksFileListBoxLabel          matlab.ui.control.Label
        TracksFileListBox               matlab.ui.control.ListBox
        TracksSelectAllFilesCheckBox    matlab.ui.control.CheckBox
        ClusterAnalysisTab              matlab.ui.container.Tab
        gPlotUpperLimitEditField        matlab.ui.control.NumericEditField
        gPlotUpperLimitEditFieldLabel   matlab.ui.control.Label
        gPlotLowerLimitEditField        matlab.ui.control.NumericEditField
        gPlotLowerLimitEditFieldLabel   matlab.ui.control.Label
        ClusterSavePanel                matlab.ui.container.Panel
        ClusterShowDataButton           matlab.ui.control.Button
        ClusterSaveImageButton          matlab.ui.control.Button
        gPlotPanel                      matlab.ui.container.Panel
        gPlotWhiteBGcheckBox            matlab.ui.control.CheckBox
        gPlotScaleBarDropDown           matlab.ui.control.DropDown
        gPlotScaleBarLabel              matlab.ui.control.Label
        gPlotLineWidthEditField         matlab.ui.control.NumericEditField
        gPlotLineWidthEditFieldLabel    matlab.ui.control.Label
        gPlotPointSizeEditField         matlab.ui.control.NumericEditField
        gPlotCentroidsSizeEditFieldLabel  matlab.ui.control.Label
        gPlotBaseDataDropDown           matlab.ui.control.DropDown
        gPlotBaseDataDropDownLabel      matlab.ui.control.Label
        gPlotColorSchemeDropDown        matlab.ui.control.DropDown
        gPlotColorSchemeDropDownLabel   matlab.ui.control.Label
        gPlotColorMapDropDown           matlab.ui.control.DropDown
        gPlotColorMapDropDownLabel      matlab.ui.control.Label
        cPlotPanel                      matlab.ui.container.Panel
        cPlotShowAlgCheckBox            matlab.ui.control.CheckBox
        cPlotBoundariesLineWidthEditField  matlab.ui.control.NumericEditField
        cPlotBoundariesLineWidthEditFieldLabel  matlab.ui.control.Label
        cPlotBoundariesColorDropDown    matlab.ui.control.DropDown
        cPlotBoundariesColorDropDownLabel  matlab.ui.control.Label
        cPlotAreasSemiTransparentCheckBox  matlab.ui.control.CheckBox
        cPlotAreasColorDropDown         matlab.ui.control.DropDown
        cPlotAreasColorDropDownLabel    matlab.ui.control.Label
        ClusterEstPanel                 matlab.ui.container.Panel
        ClusterEstNSimulationsEditField  matlab.ui.control.NumericEditField
        ClusterEstNSimulationsEditFieldLabel  matlab.ui.control.Label
        ClusterEstParameterEditField    matlab.ui.control.NumericEditField
        ClusterEstRunNsimulationsButton  matlab.ui.control.Button
        ClusterEstParameterEditFieldLabel  matlab.ui.control.Label
        ClusterEstTransferButton        matlab.ui.control.Button
        ClusterEstAxes                  matlab.ui.control.UIAxes
        ClusterStatusPanel              matlab.ui.container.Panel
        ClusterStatusLabel              matlab.ui.control.Label
        ClusterIDpanel                  matlab.ui.container.Panel
        ClusterIDdropDown               matlab.ui.control.DropDown
        segmentationMethodPanel         matlab.ui.container.Panel
        segmentationMethodLabel         matlab.ui.control.Label
        clusterIDminNeditField          matlab.ui.control.NumericEditField
        clusterIDminNeditFieldLabel     matlab.ui.control.Label
        clusterIDparam1editField        matlab.ui.control.NumericEditField
        clusterIDparam1EditFieldLabel   matlab.ui.control.Label
        clusterIDparam2editField        matlab.ui.control.NumericEditField
        clusterIDparam2EditFieldLabel   matlab.ui.control.Label
        clusterIDrunButton              matlab.ui.control.Button
        clusterIDresetButton            matlab.ui.control.Button
        ClusterFileInputPanel           matlab.ui.container.Panel
        ClusterFileListBox              matlab.ui.control.ListBox
        ClusterFileListBoxLabel         matlab.ui.control.Label
        ClusterOpenParameterAppButton   matlab.ui.control.Button
        ClusterVisAxes                  matlab.ui.control.UIAxes
        BatchAnalysisTab                matlab.ui.container.Tab
        BatchInputFileTable             matlab.ui.control.Table
        BatchNoiseFileTable             matlab.ui.control.Table
        BatchTextArea                   matlab.ui.control.TextArea
        AnalysisstepsLabel              matlab.ui.control.Label
        BatchOutputFileEditField        matlab.ui.control.EditField
        resultsoutputfileEditFieldLabel  matlab.ui.control.Label
        BatchStartButton                matlab.ui.control.Button
        BatchOpenParameterAppButton     matlab.ui.control.Button
    end

    
    properties (Access = public)
        S % structure holding the user-defined parameters for the data analysis
        ParameterApp = gobjects(0) % Parameter app
        MaskingApp = gobjects(0) % Masking app
        ShowClusterDataApp = gobjects(0) % app to show a table with the current cluster results
        FineTuneLocParamsApp = gobjects(0) % Fine-tune Localization Parameters app
    end
    
    properties (Access = private)
        c % structure holding some colors (see startup function), with fields:
        %   .CB: 12x1 table with 12 colorblind friendly colors
        %   .lambda: 800x3 list of colors according to wavelength (row number is the wavelength in nanometers)
        %   .UT: 15x1 table with a custom design color palette
        %   .cmap: 6x1 table with colormaps
        %   .histcols: 3x3 list of colors for the histogram plots in the Localization tab
        %   .pushButton: structure holding the text and background colors for the push buttons, according to states:
        %                .off (black text, gray background)
        %                .on (white text, green background)
        %                .primed (white text, gold background)
        
        h % structure collecting the handles grouped objects for easier processing, with fields:
        % .pushButtons: handles for all push buttons in the app
        % .locs: handles in the Localization tab, with subfields:
        %        .params: edit field handles in the localization parameters panel (6x1)
        %        .axes: axis handles for the histograms with rows: PSF size, photons, localization precision (3x1)
        %        .rangeSliders: range slider handles for the histograms (3x1)
        %        .rangeLL: lower limit edit fields for the histograms (3x1)
        %        .rangeUL: upper limit edit fields for the histograms (3x1)
        % .tracks: handles in the Tracking tab, with subfields:
        %          .paramsBuild: edit field handles in the track building parameters panel (2x1)
        %          .paramsFit: edit field handles in the MSD fitting parameters panel (4x1)
        %          .axesMSD: axis handles for the MSD analysis plots (2x1), with: mean MSD analysis, D distribution
        % .tracksVis: handles of the track visualization axes, with field:
        %             .axes: overview image (1), magnified image (2)
        % .cluster: handles in the Cluster Analysis tab, with subfields:
        %           .paramsEst: edit field handles in the parameter estimation panel (2x1)
        %           .paramsID: edit field handles in the cluster identification panel (3x1)
        
        tmp % Structure containing some miscellaneous temporary data needed across the app, with fields:
        % .blockTabChange: when true, the user is forced to stay on the currently selected tab
        % .useGPU: true if a GPU can be used to perform the localization procedure
        % .lastDirectory: path to the most recently loaded data file(s)
        % .buttonStates: list holding the stored button states for all buttons in |app.h.pushButtons|
        % .selectedPointList: Nx3 list with the concatenated data for all localizations for all files currently
        %                     selected in the Localization tab (PSF size, photons and localization precision)
        % .selectedPointListFiltered: as .selectedPointList, with all entries removed that are outside the user-defined
        %                             histogram limits
        % .selectedOutlierInfo: 1x6 vector with the outlier info of the localization procedure, summed up for all files
        %                       currently selected in the Localization tab 
        % .selectedTrackArray: tracks from all currently selected files in the Tracking tab, in one Nx1 cell array
        % .logDlist: Nx1 list of current logarithmized individual diffusion coefficients (to recalculate the fraction of
        %            the slower component when the split value is changed)
        % .clusterData: Nx5 list with the current cluster data (area (nm²), diameter (nm), N, cluster centroid
        %               x-coordinate (nm), cluster centroid y-coordinate (nm))
        
        GO % Structure containing handles to graphic objects that are used for visualization of data (axes contents like
        % image data etc.), with fields:
        % .locs: histograms in the localization tab, arranged as a 3x3 array with one column for each histogram:
        %         (1) original PSF histogram
        %         (2) PSF histogram affected by limits
        %         (3) vertical PSF limit lines
        %         (4) original photons histogram
        %         (5) photons histogram affected by limits
        %         (6) vertical photons limit lines
        %         (7) original precision histogram
        %         (8) precision histogram affected by limits
        %         (9) vertical precision limit lines
        % .tracks: plots of the MSD analysis results in the tracking tab (1-3 in the left plot, 4-9 in the right plot,
        %          10 as a text message in the left plot).
        %           (1) mean MSD plot
        %           (2) mean MSD linear fit line (just the fitted range)
        %           (3) extended dashed linear fit line
        %           (4) D histogram
        %           (5) single normal fit of the log10 data
        %           (6) bimodal fit of the log10 data
        %           (7) slower component (of the bimodal fit)
        %           (8) faster component (of the bimodal fit)
        %           (9) vertical split line
        %          (10) text message for the mean MSD plot
        % .tracksVis: graphic objects that depend on the tracks visualization options.
        %              (1) text message object for the overview image
        %              (2) maximum intensity projection (grayscale RGB) of the TIFF file for the overview image
        %              (3) maximum intensity projection (grayscale RGB) of the TIFF file for the magnified image
        %              (4) patch object (tracks) for the overview image
        %              (5) patch object (tracks) for the magnified image
        %              (6) masking image (the AlphaData property) for the overview image 
        %              (7) masking image (the AlphaData property) for the magnified image
        %              (8) rectangle object (scale bar) for the overview image
        %              (9) rectangle object (scale bar) for the magnified image
        %             (10) text object for the scale bar in the overview image
        %             (11) text object for the scale bar in the magnified image
        %             (12) ROI square for the overview image
        %             (13) colorbar
        %             (14) lower limit edit field
        %             (15) label of (14)
        %             (16) upper limit edit field
        %             (17) label of (16)
        % .cluster: graphic objects that depend on the cluster visualization options.
        %            (1) patch object (tracks)
        %            (2) patch object (Voronoi cells)
        %            (3) line object (Voronoi lines)
        %            (4) patch object (cluster areas)
        %            (5) line object (cluster boundaries)
        %            (6) scatter object (centroids/localizations/DBSCAN points)
        %            (7) rectangle object (scale bar)
        %            (8) text object for the scale bar
        %            (9) colorbar
        %           (10) lower limit edit field in the general plotting options panel
        %           (11) label of (10)
        %           (12) upper limit edit field in the general plotting options panel
        %           (13) label of (12)

        Data % Structure that holds all the individual analyzed data for every file in the Localization tab listbox.
        %      This structure can be addressed to concatenate data for the visualization of results (without
        %      recalculating each analysis step)., with fields:
        %      .fullFilenames: full filename
        %      .pointList: Nx7 list of point coordinates, with columns: frame, x-coordinate, y-coordinate, PSF size,
        %                  photons, background, CRLB localization precision (lengths in nm)
        %      .pointListFiltered: as .pointList, but filtered by the current histogram settings
        %      .outlierInfo: 1x6 vector with the outlier info of the localization procedure
        %      .MIP: maximum intensity projection of the raw data (NxMx3 RGB grayscale image)
        %      .stackDimensions: dimensions of the image stack [width, height, number of frames]
        %      .maskIm: mask image as a NxM logical array
        %      .maskPolygon: mask as a polyshape object
        %      .trackArray: Nx1 cell array containing the (filtered) tracks
        %      .trackArrayMasked: as .trackArray, but with all tracks removed that are outside the mask
        %      .MSD: Nx1 cell array containing the MSD data (lag time (s), MSD (µm²), sdv (µm²), N) for each track
        %      .indivMSDfits: Nx2 list with the individual fits (D (µm²/s), adj.R²) for each track
        %      .centroidList: Nx2 list of the track centroids, calculated from the (filtered) tracks

        VisT % 2x1 structure holding old (1) and updated (2) parameters that have an effect on the visualization in the
        %      Tracking tab, with fields:
        %      .colors: 12x1 table of colorblind friendly colors
        %      .iSingleFileSelected: true if only a single file is selected in the file listbox
        %      .trackArray: Nx1 cell array containing the (filtered) tracks
        %      .axesLimits: 1x4 vector for the full axes limits ([xmin,xmax,ymin,ymax])
        %      .colorScheme: color scheme for the track visualization ('random', 'duration', 'displacement', 
        %                    'range', 'mobility (D)', 'mobility (logD)', 'immobile/mobile (logD)')
        %      .colorMap: color map option (depending on the color scheme) for the track visualization 
        %                 ('cycle 4 colors', 'cycle 6 colors', 'cycle 8 colors', 'cycle 10 colors', 'inferno',
        %                  'inferno (reversed)', 'plasma', 'plasma (reversed)', 'hot', 'hot (reversed)', 
        %                  'black', 'white')
        %      .lineWidth: line width of the tracks in the magnified view (tracks in the overview are rendered with 1/3
        %                  of this line width)
        %      .dataLL: lower limit of the data (according to the color scheme option)
        %      .dataUL: upper limit of the data (according to the color scheme option)
        %      .changedZoom: true if the user just zoomed or dragged the view in the magnified track visualization plot
        %      .scaleBar: visualization of the scale bar ('none', 'bar (white)', 'full (white)', 'bar (black)',
        %                 'full (black)')
        %      .isWhiteBG: true if the user chose to paint the plots' background white
        %      .showTIFF: true if the user chose to underlay the tracks with a maximum intensity projection of the
        %                 raw data (overriding the black or white background)
        %      .showUnmasked: if true and if a mask is present, this will show how the tracks would look without the
        %                     applied mask
        %      .MSDParams: structure holding MSD analysis specific parameters, with fields:
        %                  .frameRate: frame rate in Hz
        %                  .minTrackLength: minimum track length to be considered for mobility analysis
        %                  .adjR2: minimum adjusted R² value to be considered for mobility analysis
        %                  .linFitPoints: points to be included in the linear fit (as a single number X for the range
        %                                 1-X or a 1x2 vector [2,X] for the range 2-X)
        %                  .splitValue: log10 value of the diffusion coefficient that separates the slower from the
        %                               faster fraction of diffusing molecules
        %      .MIP: maximum intensity projection of the raw data (NxMx3 RGB grayscale image)
        %      .maskIm: mask image for the selected file (NxM logical array, all true if no mask is present)

        VisC % 2x1 structure holding old (1) and updated (2) parameters that have an effect on the visualization in the
        %      Cluster Analysis tab, with fields:
        %      .colors: 12x1 table of colorblind friendly colors
        %      .pointList: Nx2 list of the (filtered) localization coordinates
        %      .trackArray: Nx1 cell array containing the (filtered) tracks
        %      .centroidList: Nx2 list of the track centroids, calculated from the (filtered) tracks
        %      .voronoiPoints: Nx2 list of Voronoi seed points
        %      .voronoiVertices: Nx2 list of Voronoi cell vertices
        %      .voronoiCells: Nx1 cell array containing the indices for the Voronoi cells
        %      .voronoiValueList: Nx1 list of values for the Voronoi cluster algorithm (either first-rank densities or
        %                         Voronoi cell areas)
        %      .DBSCANpoints: Nx2 list of the points for DBSCAN
        %      .DBSCANclusterIndexList: Nx1 list of indices that assign the points to specific clusters (-1 for noise
        %                               points)
        %      .DBSCANisCore: Nx1 logical list, true if a point is a core cluster point
        %      .overlapGroupsNASTIC: Nx1 cell array, holding the indices that provide which AABB overlap for the NASTIC
        %                            algorithm
        %      .clusterBoundaryArray: Nx1 cell array, holding the indices that provide the vertex boundaries for each
        %                             found cluster
        %      .showClusterAlg: if true, the visualization specific to the chosen cluster algorithm will be shown
        %      .clusterAlg: the currently chosen cluster algorithm ('Voronoi','DBSCAN','NASTIC')
        %      .axesLimits: 1x4 vector for the full axes limits ([xmin,xmax,ymin,ymax])
        %      .baseDataStyle: base data that the cluster algorithm is based on ('localizations','track centroids',
        %                      'tracks')
        %      .colorScheme: color scheme for the visualization ('random','duration','displacement','range',
        %                    'mobility (D)','mobility (logD)','immobile/mobile (logD)','Voronoi (density)',
        %                    'Voronoi (area)','DBSCAN (random)','DBSCAN (noise/cluster)','DBSCAN (noise/core/cluster)',
        %                    'NASTIC)
        %      .colorMap: color map option (depending on the color scheme) for the visualization ('unicolored',
        %                 'cycle 4 colors','cycle 6 colors','cycle 8 colors','cycle 10 colors','inferno',
        %                 'inferno (reversed)','plasma','plasma (reversed)','hot','hot (reversed)','black','white',
        %                 'red','green','yellow','blue','orange','cyan','magenta','teal','lavender','maroon',
        %                 'orange/blue','blue/orange','red/teal','teal/red','gray/orange/blue','gray/blue/orange',
        %                 'gray/red/teal','gray/teal/red')
        %      .pointSize: size of the points that are currently displayed (e.g. cluster centroids, localizations ...)
        %      .gLineWidth: line width of tracks or Voronoi cells
        %      .dataLL: lower limit of the data (according to the color scheme option)
        %      .dataUL: upper limit of the data (according to the color scheme option)
        %      .scaleBar: visualization of the scale bar ('none', 'bar (white)', 'full (white)', 'bar (black)',
        %                 'full (black)')
        %      .isWhiteBG: true if the user chose to paint the plots' background white
        %      .clusterBoundariesLineWidth: line width of the cluster boundaries
        %      .clusterBoudariesColor: 1x3 vector giving the color for the cluster boundaries
        %      .clusterAreaIsSemiTransparent: if true, the cluster areas are rendered semi-transparent
        %      .clusterAreaColor: 1x3 vector giving the color for the cluster areas
        %      .MSDParams: structure holding MSD analysis specific parameters, with fields:
        %                  .frameRate: frame rate in Hz
        %                  .minTrackLength: minimum track length to be considered for mobility analysis
        %                  .adjR2: minimum adjusted R² value to be considered for mobility analysis
        %                  .linFitPoints: points to be included in the linear fit (as a single number X for the range
        %                                 1-X or a 1x2 vector [2,X] for the range 2-X)
        %                  .splitValue: log10 value of the diffusion coefficient that separates the slower from the
        %                               faster fraction of diffusing molecules

    end
    
    methods (Access = public)
       
        function externalChangeOfMask(app, inputFilename)
            % Get the ID of the input filename.
            [~, ID] = intersect(app.LocsFileListBox.ItemsData, inputFilename, 'stable');

            % Read in the mask and create the mask polygon.
            [app.Data(ID).maskIm, app.Data(ID).maskPolygon] = getMaskForApp(inputFilename);

            % Prepare the parameter structure for the tracking procedure and extract the limits.
            trackParams = rmfield(app.S.tracking, {'limits','maskPolygons'});
            trackParams = reshape(namedargs2cell(trackParams ),2,[]);
            trackLimits = table2array(app.S.tracking.limits)';

            % Apply the new mask to the localization coordinates.
            pointList = app.Data(ID).pointListFiltered;
            maskPolygon = app.Data(ID).maskPolygon;
            maskPolygon.Vertices = maskPolygon.Vertices * app.S.localization.pixelSize;
            TFin = isinterior(maskPolygon, pointList(:,2:3));
            pointList = pointList(TFin,:);
            tmpTracks = assembleTracks(pointList, trackParams{:}, 'exportAllInfo', true);
            trackFeatures = [...,
                cellfun('size', tmpTracks, 1), ...
                (cellfun(@(x) range(x(:,1)),tmpTracks) + 1) / app.S.MSDanalysis.frameRate, ...
                cellfun(@(x) norm(diff(x([1,end],2:3))), tmpTracks), ...
                cellfun(@(x) hypot(range(x(:,2)), range(x(:,3))), tmpTracks)];
            isValid = all(trackFeatures >= trackLimits(1,:) & trackFeatures <= trackLimits(2,:), 2);
            app.Data(ID).trackArrayMasked = tmpTracks(isValid);

            % Update the visualization.
            processTracksFileSelection(app);
        end
    
        function externalChangeOfS(app, S)
            % This function is triggered by an external parameter app, transferring the updated parameter structure S
            % back to this app. Here, all changed parameter are determined to adjust the corresponding edit fields etc.
            % in this app.
            changedParameters = compareS(app.S, S);

            % Update S with the changed value(s) from the parameter app.
            app.S = S;

            % Use the first word of each parameter name to determine the first analysis step that is affected by the
            % updated values, but adjust some parameters that need to be handled separately (for example, they
            % should not trigger the reset of an entire analysis step).
            parameterList = changedParameters(:,1);
            parameterList = erase(parameterList, "MSDanalysis splitValue");
            parameterList = replace(parameterList,"localization limits","locLimits");

            firstWords = unique(extractBefore(parameterList,' '));
            analysisSteps = ["localization";"locLimits";"tracking";"MSDanalysis";"cluster"];
            affectedSteps = ismember(analysisSteps, firstWords);
            firstAffectedStep = analysisSteps(find(affectedSteps,1));

            % Updating any parameters must reset all depending analysis steps.
            resetAnalysisStep(app,firstAffectedStep);

            % If any input/output file for the batch analysis was altered, switch to that tab in the end.
            if any(strcmp(firstWords,"files"))
                app.TabGroup.SelectedTab = app.BatchAnalysisTab;
            end

            % Delete all parameters that handle the cluster algorithm. Their edit fields are filled dependent on the
            % current algorithm and are processed via >resetAnalysisStep< in >processClusterFileSelection<.
            isClusterChanged = any(cellfun(@(x) contains(x, 'cluster'), changedParameters(:,1)));
            if isClusterChanged
                isClusterParameter = cellfun(@(x) contains(x, 'cluster'), changedParameters(:,1));
                changedParameters(isClusterParameter,:) = [];
            end

            nParameters = size(changedParameters,1);
            for iParameter = 1:nParameters
                objectHandle = findobj(app.MainUIFigure, 'Tag', changedParameters{iParameter, 1});
                if ~isempty(objectHandle)
                    if strcmp(changedParameters{iParameter, 1}, 'MSDanalysis linFitPoints')
                        % The linFitPoints value must be converted to text for the text edit field.
                        linFitPoints = changedParameters{iParameter, 2};
                        if isscalar(linFitPoints)
                            objectHandle.Value = int2str(linFitPoints);
                        else
                            objectHandle.Value = sprintf('%u-%u', linFitPoints(1), linFitPoints(2));
                        end
                    elseif strcmp(changedParameters{iParameter, 1}, 'MSDanalysis splitValue')
                        objectHandle.Value = changedParameters{iParameter, 2};
                        changeMSDsplitValue(app, 'external');
                    elseif strcmp(changedParameters{iParameter, 1}, 'files inputFiles')
                        if isempty(app.S.files.inputFiles)
                            objectHandle.Data = table.empty;
                        else
                            [pathname, filename, extension] = ...
                                cellfun(@fileparts, app.S.files.inputFiles, 'UniformOutput', false);
                            filename = cellfun(@(x,y) strcat(x,y), filename, extension, 'UniformOutput', false);
                            initNoiseID = ones(size(filename));
                            initMaskTF = ~cellfun(@isempty, app.S.tracking.maskPolygons(:,1));
                            objectHandle.Data = table(filename,pathname,initNoiseID,initMaskTF);
                            objectHandle.ColumnWidth = 'fit';
                        end
                    elseif strcmp(changedParameters{iParameter, 1}, 'files noiseFiles')
                        if isempty(app.S.files.noiseFiles)
                            objectHandle.Data = table.empty;
                        else
                            [pathname,filename,extension] = ...
                                cellfun(@fileparts, app.S.files.noiseFiles, 'UniformOutput', false);
                            filename = cellfun(@(x,y) strcat(x,y), filename, extension, 'UniformOutput', false);
                            objectHandle.Data = table(filename,pathname,(1:numel(filename))');
                            objectHandle.ColumnWidth = 'fit';
                        end
                    else
                        objectHandle.Value = changedParameters{iParameter, 2};
                    end
                end
            end

            % As the input/output files are selected in the external parameter app, check if the batch analysis can be
            % unlocked.
            checkBatchStart(app);
        end

    end
    
    methods (Access = private)

        function resetAnalysisStep(app, stepString)
            % Some changes side-step the reset of an entire analysis step, so just return when |stepString| is empty.
            if isempty(stepString)
                return
            end

            % Based on |stepString|, create a list that also includes subsequent analysis steps that need to be reset.
            analysisSteps = ["localization";"locLimits";"tracking";"MSDanalysis";"cluster"];
            resetSteps = analysisSteps(find(strcmp(stepString,analysisSteps)):end);

            % Loop through all analysis steps that need to be reset.
            for iStep = 1:numel(resetSteps)
                switch resetSteps(iStep)
                    case {'localization','locLimits'}
                        % Clear and disable the file listbox in the Tracking tab.
                        app.TracksFileListBox.ItemsData = cell.empty;
                        app.TracksFileListBox.Items = cell.empty;
                        app.TracksFileListBox.Enable = "off";

                        if strcmp(resetSteps(iStep),'localization')
                            % Disable the file listbox in the Localization tab (this triggers clearing the plots when
                            % >processLocsFileSelection< is executed.
                            app.LocsFileListBox.Enable = "off";
                        end

                        processLocsFileSelection(app);

                    case {'tracking','MSDanalysis'}
                        % Clear the file listbox in the Cluster Analysis tab.
                        app.ClusterFileListBox.ItemsData = cell.empty;
                        app.ClusterFileListBox.Items = cell.empty;

                        if strcmp(resetSteps(iStep),'tracking')
                            % Disable the file listbox in the Tracking tab (this triggers clearing the plots when
                            % >processTracksFileSelection< is executed.
                            app.TracksFileListBox.Enable = "off";
                        elseif strcmp(resetSteps(iStep),'MSDanalysis')
                            % Delete all MSD data as it must be recalculated.
                            if isfield(app.Data,'MSD')
                                app.Data = rmfield(app.Data,'MSD');
                            end

                            % The following handles the situation when one file was selected in the track file listbox
                            % (track visualization is therefore present): Clear the trackArray in |app.VisT(1)|, which
                            % will update the track visualization via >processTracksFileSelection<, as certain color
                            % schemes depend on some of the (now altered) values in |app.S.MSDanalysis|.
                            app.VisT(1).trackArray = {};
                        end

                        processTracksFileSelection(app);

                    case "cluster"
                        % Close the cluster data window if it is still open.
                        if ~isempty(app.ShowClusterDataApp) && isvalid(app.ShowClusterDataApp)
                            close(app.ShowClusterDataApp.UIFigure)
                        end

                        % Process what is in the file listbox in the Cluster Analysis tab.
                        processClusterFileSelection(app);
                end
            end

            % Switch to the tab that contains the initially specified analysis step.
            switch stepString
                case {'localization','locLimits'}
                    app.TabGroup.SelectedTab = app.LocalizationTab;
                case {'tracking','MSDanalysis'}
                    app.TabGroup.SelectedTab = app.TrackingTab;
                case 'cluster'
                    app.TabGroup.SelectedTab = app.ClusterAnalysisTab;
            end
        end
       
        function errorMsg = checkInputErrors(app)
            % Checks for input errors before starting the localization routine.
            errorMsg = {};

            % Read the first frame of each file to get their dimensions.
            warning('off','imageio:tiffutils:libtiffWarning');
            nFiles = numel(app.LocsFileListBox.ItemsData);
            imDim = zeros(nFiles,2);
            for iFile = 1:nFiles
                t = Tiff(app.LocsFileListBox.ItemsData{iFile});
                tmpImage = read(t);
                close(t);
                imDim(iFile,:) = size(tmpImage,[1 2]);
            end

            % Do the same for the noise file.
            t = Tiff(app.LocsNoiseFileListBox.ItemsData{1});
            tmpNoise = read(t);
            close(t);
            noiseDim = size(tmpNoise,[1 2]);
            warning('on','imageio:tiffutils:libtiffWarning'); 

            % Generate error messages if something is wrong.
            if sum(var(imDim,1,1)) ~= 0
                errorMsg = vertcat(errorMsg, "Input files have different dimensions");
            else
                % If above is ok, check if input file dimensions are equal to the variance map dimensions.
                if numel(tmpNoise) == 1
                    % This is an EMCCD placeholder, don't do anything.
                elseif any(noiseDim ~= mean(imDim,1))
                    errorMsg = vertcat(errorMsg," Variance map has different dimensions than the input files.");
                end
            end
        end
       
        function processLocsFileSelection(app)

            % Loading new files is always enabled.
            enableButtons(app,["load SMLM files","load sCMOS noise file"],1);

            % The checkbox follows the enabled/disabled state of the listbox. 
            app.LocsSelectAllFilesCheckBox.Enable = app.LocsFileListBox.Enable;

            if ~app.LocsFileListBox.Enable || isempty(app.LocsFileListBox.Value)
                % In the first case, the displayed files are not localized yet, in the second case, none of the
                % localized files are currently selected.

                % Clear some data.
                app.tmp.selectedPointList = [];
                app.tmp.selectedPointListFiltered = [];

                % Hide all plot elements.
                set(app.GO.locs,'Visible','off');
                app.LocsFilteredPanelLabel.Text = '';

                % Disable the range sliders and some buttons.
                enableUserInput(app,"locsPlots",0);
                enableButtons(app,["Fine-tune parameters","Create localization image(s)","Transfer to Tracking"],0);

                if isempty(app.LocsFileListBox.ItemsData)
                    % When no files are listed yet, just disable the 'Localize' button.
                    enableButtons(app,"Localize",0);

                elseif ~app.LocsFileListBox.Enable
                    % When the listbox is disabled, select all files and check the checkbox.
                    app.LocsFileListBox.Value = app.LocsFileListBox.ItemsData;
                    app.LocsSelectAllFilesCheckBox.Value = true;
                else
                    % No file is selected, so uncheck the checkbox.
                    app.LocsSelectAllFilesCheckBox.Value = false;
                end

                % Return without further processing.
                return
            end

            % Put the checkbox into the correct state (this covers incomplete selections).
            isAllSelected = all(ismember(app.LocsFileListBox.ItemsData, app.LocsFileListBox.Value));
            app.LocsSelectAllFilesCheckBox.Value = isAllSelected;
            
            % Get the IDs of the currently selected files.
            [~, IDs] = intersect(app.LocsFileListBox.ItemsData, app.LocsFileListBox.Value, 'stable');

            % Concatenate the point lists, only keep PSF size, photons and localization precision.
            app.tmp.selectedPointList = vertcat(app.Data(IDs).pointList);
            app.tmp.selectedPointList = app.tmp.selectedPointList(:,[4 5 7]);

            % Concatenate the outlier info.
            app.tmp.selectedOutlierInfo = sum(vertcat(app.Data(IDs).outlierInfo), 1);

            % Apply the user-defined histogram limits to the concatenated point list.
            locLimits = table2array(app.S.localization.limits)';
            pointList = app.tmp.selectedPointList;
            app.tmp.selectedPointListFiltered = pointList(~any(pointList < locLimits(1,:) |...
                pointList > locLimits(2,:),2),:);

            % Update the outlier list the remaining localizations.
            app.tmp.selectedOutlierInfo(6) = size(app.tmp.selectedPointListFiltered,1);

            % Update the histograms.
            updateLocHistograms(app);

            % Enable all user input for the Localization tab and some buttons.
            enableUserInput(app,"locsAll",1);
            enableButtons(app,["Transfer to Tracking","Localize"],1);

            % If only one file is selected, activate the corresponding buttons.
            isOneOneFileSelected = numel(app.LocsFileListBox.Value) == 1;
            enableButtons(app,["Fine-tune parameters","Create localization image(s)"], isOneOneFileSelected);
        end

        function updateLocHistograms(app)
            % Update the status line.
            app.LocsStatusLabel.Text = 'Updating histograms ...'; drawnow;

            [histogramData, axesLimits] = calculateLocHistograms(app.tmp.selectedPointList, ...
                    app.tmp.selectedPointListFiltered);
            
            for iHist = 1:3

                % Update the original and filtered histogram with the new data.
                set(app.GO.locs(1,iHist),'XData',histogramData{iHist}(:,1),'YData',histogramData{iHist}(:,2));
                set(app.GO.locs(2,iHist),'XData',histogramData{iHist}(:,1),'YData',histogramData{iHist}(:,3));

                % Set the axes limits.
                app.h.locs.axes(iHist).XLim = axesLimits(1:2,iHist);
                app.h.locs.axes(iHist).YLim = [0, axesLimits(3,iHist)];

                % Clean up the y axis ticks.
                newYticks = calcYticks(app, app.h.locs.axes(iHist).YLim);
                app.h.locs.axes(iHist).YAxis.TickValues = newYticks;

                % Set the range sliders to the same limits as the x-axis.
                app.h.locs.rangeSliders(iHist).Limits = app.h.locs.axes(iHist).XLim;

                % Read the limits of the range sliders and set the values according to the parameter structure S.
                limits = app.h.locs.rangeSliders(iHist).Limits;
                values = app.S.localization.limits{iHist,:};

                % Set the lower/upper limit fields.
                app.h.locs.rangeLL(iHist).Value = num2str(values(1));
                app.h.locs.rangeUL(iHist).Value = num2str(values(2));

                % Slider knob positions, corrected for 0 or Inf.
                knobPositions = [max(limits(1), values(1)), min(limits(2), values(2))];
                app.h.locs.rangeSliders(iHist).Value = knobPositions;

                % The line height is determined by the extent of the y-axis.
                maxY = app.h.locs.axes(iHist).YLim(2)*0.8;

                % Update the histogram limit lines.
                lineX = [knobPositions(1), knobPositions(1), NaN, knobPositions(2), knobPositions(2)];
                lineY = [maxY, 0, NaN, 0, maxY];
                set(app.GO.locs(3,iHist), 'XData', lineX, 'YData', lineY);
            end

            % Make the axes contents visible (if not already visible).
            set(app.GO.locs,'Visible','on');
            
            % update the filter information
            updateOutlierInfo(app);
            
            % update status line
            app.LocsStatusLabel.Text = 'idle'; drawnow;
        end

        function updateOutlierInfo(app)
            % Construct the format.
            str = ['Original number of localizations:\n'...
                '%u, including results with:\n'...
                '%ux NaN / 0 / inf\n%ux obvious outliers\n%ux fitting errors\n\n'...
                'Remaining\n(after pre-filtering):\n%u (%u %%)\n\n'...
                'Remaining\n(after histogram filtering,\n'...
                '%% of orginal / pre-filtered):\n%u (%u %% / %u %%)'];
            
            % Unpack for more clarity.
            nOrig = app.tmp.selectedOutlierInfo(1);
            nNaNor0orInf = app.tmp.selectedOutlierInfo(2);
            nOutlier = app.tmp.selectedOutlierInfo(3);
            nFittingError = app.tmp.selectedOutlierInfo(4);
            nPreFilter = app.tmp.selectedOutlierInfo(5);
            nUserFilter = app.tmp.selectedOutlierInfo(6);
            pctPreFilter = round(nPreFilter / nOrig * 100);
            pctHistOrig = round(nUserFilter / nOrig * 100);
            pctHistPreFilter = round(nUserFilter / nPreFilter * 100);
            
            % Build the output string.
            finfo = [nOrig, nNaNor0orInf, nOutlier, nFittingError, nPreFilter, pctPreFilter, ...
                nUserFilter, pctHistOrig, pctHistPreFilter];
            app.LocsFilteredPanelLabel.Text = sprintf(str,finfo);
        end

        function processTracksFileSelection(app)

            % The checkbox follows the enabled/disabled state of the listbox. 
            app.TracksSelectAllFilesCheckBox.Enable = app.TracksFileListBox.Enable;

            if ~app.TracksFileListBox.Enable || isempty(app.TracksFileListBox.Value)
                % The displayed files are not tracked yet, none of the already tracked files are selected or there are
                % no files are in the listbox).

                % Clear some data.
                app.tmp.selectedTrackArray = [];

                % Hide all MSD plot elements and clear their results.
                set(app.GO.tracks(1:10),'Visible','off');
                app.TracksMeanMSDresultLabel.Text = '';
                app.TracksDdistResultLabel.Text = '';

                % Set the allowed user input and buttons.
                enableUserInput(app,["tracksPlots","tracksVis"],0);
                enableButtons(app,["Transfer to Cluster Analysis","edit mask","save"],0);
              
                if app.TracksFileListBox.Enable && isempty(app.TracksFileListBox.Value)
                    % None of the tracked files are currently selected, so uncheck the checkbox, but track building and
                    % MSD analysis (they autoselect all files) are still viable.
                    app.TracksSelectAllFilesCheckBox.Value = 0;
                    enableUserInput(app,["tracksParamsBuild","tracksParamsFit"],1);
                    enableButtons(app,["Build tracks","MSD analysis"],1);

                elseif isempty(app.TracksFileListBox.ItemsData)
                    % No files are present, so disable track building and MSD analysis.
                    app.TracksSelectAllFilesCheckBox.Value = 0;
                    enableUserInput(app,["tracksParamsBuild","tracksParamsFit"],0);
                    enableButtons(app,["Build tracks","MSD analysis"],0);

                else
                    % Files are present but without any calculated data yet. Just enable the track building.
                    enableUserInput(app,"tracksParamsBuild",1);
                    enableUserInput(app,"tracksParamsFit",0);
                    enableButtons(app,"Build tracks",1);
                    enableButtons(app,"MSD analysis",0);
                end
                
                % Trigger the necessary changes in the visualization GUI elements.
                app.VisT(1).isSingleFileSelected = 1;
                app.VisT(2).isSingleFileSelected = 0;
                getTracksVisOptions(app);
                app.VisT = updateAppGOsTracksVis(app.GO.tracksVis, app.h.tracksVis.axes, app.VisT);
               
                % Return without further processing.
                return
            end

            % Put the checkbox into the correct state (this covers incomplete selections).
            isAllSelected = all(ismember(app.TracksFileListBox.ItemsData, app.TracksFileListBox.Value));
            app.TracksSelectAllFilesCheckBox.Value = isAllSelected;

            % Get the IDs of the currently selected files.
            [~, IDs] = intersect(app.LocsFileListBox.ItemsData, app.TracksFileListBox.Value, 'stable');

            % Concatenate the track arrays.
            app.tmp.selectedTrackArray = vertcat(app.Data(IDs).trackArrayMasked);

            % Make the plot data invisible during any re-calculation and clear the displayed results.
            set(app.GO.tracks,'Visible','off');
            app.TracksMeanMSDresultLabel.Text = '';
            app.TracksDdistResultLabel.Text = '';

            % If MSD data is present, update the MSD plots.
            if isfield(app.Data,'MSD')
                app.GO.tracks(10).String = '';
                updateMSDplots(app);
            else
                app.GO.tracks(10).String = {'Start MSD analysis','to plot data'};
            end

            if numel(app.TracksFileListBox.Value) == 1
                % If only one file is selected, show the tracking image. Set |VisT| to trigger the corresponding plot
                % visualizations.
                app.VisT(1).isSingleFileSelected = 0;
                app.VisT(2).isSingleFileSelected = 1;

                % Copy the selected file's data to |VisT|.
                app.VisT(2).trackArray = app.Data(IDs).trackArray;
                app.VisT(2).MIP = app.Data(IDs).MIP;

                % Also copy the stored mask (if it does not exist, just pass along a dummy mask).
                if isempty(app.Data(IDs).maskIm)
                    app.VisT(2).maskIm = true(size(app.Data(IDs).MIP(:,:,1)));
                else
                    app.VisT(2).maskIm = app.Data(IDs).maskIm;
                end

                % Coordinate limits are extracted from the first supplied data file.
                maxYX = app.S.localization.pixelSize * app.Data(1).stackDimensions([2 1]);
                [app.VisT(2).axesLimits] = deal([0, maxYX(2), 0, maxYX(1)]);

                % Enable the visualization options and corresponding buttons.
                enableButtons(app,["save","edit mask"],1);
                enableUserInput(app,"tracksVis",1);

                % The 'show unmasked tracks' checkbox value and interaction depends on the existence of a mask.
                app.TracksVisShowUnmaskedCheckBox.Value = isempty(app.Data(IDs).maskIm);
                app.TracksVisShowUnmaskedCheckBox.Enable = ~isempty(app.Data(IDs).maskIm);
            else
                app.VisT(1).isSingleFileSelected = 1;
                app.VisT(2).isSingleFileSelected = 0;

                % Disable the visualization options and corresponding buttons.
                enableButtons(app,["save","edit mask"],0);
                enableUserInput(app,"tracksVis",0);
            end

            % Collect the GUI options and update the track visualization plots.
            getTracksVisOptions(app);
            app.VisT = updateAppGOsTracksVis(app.GO.tracksVis, app.h.tracksVis.axes, app.VisT);
   
            % Enable user input for track building and MSD analysis.
            enableUserInput(app,["tracksParamsBuild","tracksParamsFit"],1);
            enableButtons(app,["Build tracks","MSD analysis"],1);

            % User input for the MSD plots (checkboxes etc.) and the transfer button are only enabled if MSD data is
            % available.
            enableUserInput(app,"tracksPlots",isfield(app.Data,'MSD'))
            enableButtons(app,"Transfer to Cluster Analysis", isfield(app.Data,'MSD'));
        end

        function calculateMSDdata(app)

            % Loop through all files in the file listbox.
            [~, IDs] = intersect(app.LocsFileListBox.ItemsData, app.TracksFileListBox.ItemsData, 'stable');

            for iFile = IDs'
                % Update the status line.
                app.TracksStatusLabel.Text = ...
                    sprintf('Analyzing MSD of %s ...',app.LocsFileListBox.Items{iFile}); drawnow;

                % Calculate the MSD
                app.Data(iFile).MSD = calculateMSD(app.Data(iFile).trackArrayMasked, app.S.MSDanalysis.frameRate, ...
                    "minTrackLength", app.S.MSDanalysis.minTrackLength);

                % Fit all individual MSD curves.
                app.Data(iFile).indivMSDfits = fitMSD(app.Data(iFile).MSD, ...
                    app.S.MSDanalysis.frameRate, 'linFitPoints', app.S.MSDanalysis.linFitPoints);
            end

            % update status line
            app.TracksStatusLabel.Text = 'idle'; drawnow;
        end

        function selectedMeanMSD = calculateMeanMSDdata(app)
            % Calculates the weighted mean results over the MSD curves of all selected files. This is part of the
            % program >calculateMSD< (see the commented code there), here, this shortcuts the repeated calculation of
            % individual MSD curves and just processes the already stored data.
            [~, IDs] = intersect(app.LocsFileListBox.ItemsData, app.TracksFileListBox.Value, 'stable');
            allMSD = vertcat(app.Data(IDs).MSD);
            allMSD = vertcat(allMSD{:});
            [timeLag, ~, ID] = unique(allMSD(:,1));
            allMSDarray = accumarray(ID, 1:size(allMSD,1), [], @(r){allMSD(r,[2 4])});
            weightedMeanMSD = cellfun(@(x) sum(prod(x,2))/sum(x(:,2)), allMSDarray, 'uni', 0);
            weightedSdvMSD = cellfun(@(x,y) sqrt(sum(x(:,2).*(x(:,1)-y).^2)./...
                (sum(x(:,2))-sum(x(:,2).^2)/sum(x(:,2)))), allMSDarray, weightedMeanMSD, 'uni', 0);
            df = cellfun(@(x) sum(x(:,2)).^2./sum(x(:,2).^2), allMSDarray, 'uni', 0);
            meanMSDresults = [timeLag, cell2mat(horzcat(weightedMeanMSD, weightedSdvMSD, df))];
            selectedMeanMSD = meanMSDresults(~any(isnan(meanMSDresults),2),:);
        end

        function updateMSDplots(app)
            % Get the IDs of the currently selected files in the Tracking tab listbox.
            [~, IDs] = intersect(app.LocsFileListBox.ItemsData, app.TracksFileListBox.Value, 'stable');

            % Calculate the mean MSD curve and apply the fit.
            selectedMeanMSD = calculateMeanMSDdata(app);
            [meanMSDfit, meanMSDfitCoeffs] = ...
                fitMSD({selectedMeanMSD}, app.S.MSDanalysis.frameRate, 'linFitPoints', app.S.MSDanalysis.linFitPoints);

            % Concatenate the MSD fit data into one array.
            MSDfits = vertcat(app.Data(IDs).indivMSDfits);

            % Remove all data that do not conform with the chosen adj. R² value, have negative diffusion coefficients,
            % were tagged as invalid (row of zeroes) by >fitMSD< or have NaN or Inf values.
            isValid = MSDfits(:,1) > 0 & MSDfits(:,2) >= app.S.MSDanalysis.adjR2 & ...
                ~any(isnan(MSDfits), 2) & ~any(isinf(MSDfits), 2);
            Dlist = MSDfits(isValid, 1);
            logDlist = log10(Dlist);

            % Calculate the logD histogram. The probability normalization makes the fitting more robust. The histogram
            % range and bin size is set in >startupFcn<.  
            binWidth = mean(diff(app.GO.tracks(4).XData));
            leftEdge = app.GO.tracks(4).XData(1) - 0.5*binWidth;
            rightEdge = app.GO.tracks(4).XData(end) + 0.5*binWidth;
            N = histcounts(logDlist, leftEdge:binWidth:rightEdge, 'Normalization', 'probability');

            % Fit the logD data as it if they were normally distributed (strictly speaking, they should follow a
            % lognormal distribution), but this is how it is done across literature.
            statOptions = statset('normfit');
            statOptions.MaxFunEvals = 500;
            pd = fitdist(logDlist, 'Normal', 'Options', statOptions);
            D1pop = 10^pd.mu;

            % Calculate the fraction of the slower component using the split value. Store the current logDlist to
            % recalculate the slow component fraction when the split value is changed.
            slowFractionSplit = sum(logDlist <= app.S.MSDanalysis.splitValue) / numel(logDlist);
            app.tmp.logDlist = logDlist;

            % Try to fit the logD data with a Gaussian mixture model, assuming two populations.
            [pdComp1, pdComp2, fractionComp1, errorCode] = fit2pops(logDlist);
            
            % Update the mean MSD plot with the calculated data based on all selected files.
            set(app.GO.tracks(1),'XData',selectedMeanMSD(:,1),'YData',selectedMeanMSD(:,2));

            % Add the linear fit data on top, with the fit just covering the chosen number of datapoints as a thicker
            % red line, extended with a thinner, dashed line to visualize the fit at larger time lags.
            linFitPoints = app.S.MSDanalysis.linFitPoints;
            x1 = selectedMeanMSD(isvector(linFitPoints) + 1, 1); % Starts at the second point for vectors
            x2 = selectedMeanMSD(linFitPoints(end), 1);
            extEndID = min(linFitPoints(end)*5, size(selectedMeanMSD,1));
            x2ext = selectedMeanMSD(extEndID, 1);
            meanMSDfitY = meanMSDfitCoeffs*[1,1;x1,x2];
            meanMSDfitYext = meanMSDfitCoeffs*[1,1;x1,x2ext];
            set(app.GO.tracks(2),'XData', [x1,x2], 'YData', meanMSDfitY);
            set(app.GO.tracks(3),'XData', [x1,x2ext], 'YData', meanMSDfitYext);

            % Convert the probability values in the histograms to counts, so that the user can better assess the number
            % of tracks that went into the calculation.
            nCounts = numel(Dlist);
            app.GO.tracks(4).YData = round(N*nCounts);

            % Calculate and update the single population fit line.
            popFitX = app.GO.tracks(5).XData;
            popFitY1 = pdf(pd, popFitX) * nCounts * binWidth;
            app.GO.tracks(5).YData = popFitY1;


            % Check the error code and do not calculate fit lines if the two-component fit failed.
            if errorCode == 2
                app.GO.tracks(6).YData = zeros(numel(popFitX),1);
                app.GO.tracks(7).YData = zeros(numel(popFitX),1);
                app.GO.tracks(8).YData = zeros(numel(popFitX),1);
                
            else
                % Calculate the two-component fit lines.
                popFitY2comp1 = pdf(pdComp1, popFitX) * fractionComp1 * nCounts * binWidth;
                popFitY2comp2 = pdf(pdComp2, popFitX) * (1-fractionComp1) * nCounts * binWidth;
                popFitY2 = popFitY2comp1 + popFitY2comp2;

                % Update the corresponding plot objects.
                app.GO.tracks(6).YData = popFitY2;
                app.GO.tracks(7).YData = popFitY2comp1;
                app.GO.tracks(8).YData = popFitY2comp2;
            end

            % Rescale the plots.
            scaleMSDplots(app);

            % Update the results message for the mean MSD plot.
            app.TracksMeanMSDresultLabel.Text =...
                            sprintf('calculated D (µm²/s) (linear fit of mean):\n%7.2E', meanMSDfit(1));

            % Store the results of the one/two population fit as a cell array in the 'UserData' property of the text
            % label (not recommended by MATLAB but it makes sense here).
            app.TracksDdistResultLabel.UserData = cell(1,2);
            app.TracksDdistResultLabel.UserData{1} = ...
                sprintf('calculated D (µm²/s) (fitted peak center):\n%7.2E', D1pop);

            if errorCode == 2
                app.TracksDdistResultLabel.UserData{2} = "Two component fit failed.";
            else
                app.TracksDdistResultLabel.UserData{2} = ...
                    sprintf(['calculated D (µm²/s) (fitted peak centers):\n' ...
                    'slower fraction %7.2E, faster fraction %7.2E\n' ...
                    'slower fraction %.2f (split value), %.2f (fit)'], ...
                    10.^pdComp1.mu, 10.^pdComp2.mu, slowFractionSplit, fractionComp1);
            end

            % Make the mean MSD plots and the logD histogram visible, the checkbox determines if the one or two
            % population fit will be shown, together with the respective results message.
            set(app.GO.tracks(1:4), 'Visible', 'on');
            show1or2pops(app);
        end

        function enactTracksColorSchemeChange(app)
            % For some cases, it might be necessary to specify a default colormap (when the color scheme is changed and
            % the previously chosen colormap is no longer valid). Otherwise, the value will be automatically set to the
            % first item in the Items property (which is fine for most cases).
            defaultColorMap = '';
            oldColorMap = app.TracksVisColorMapDropDown.Value;

            % Set the visibility and text for the limit edit fields and their labels, so only their exceptions must be
            % addressed below.
            set(app.GO.tracksVis(14:17), 'Visible', "off");
            app.GO.tracksVis(17).Text = "to";
            app.TracksVisColorMapDropDownLabel.Text = 'color map';

            switch app.TracksVisColorSchemeDropDown.Value
                case 'random'
                    app.TracksVisColorMapDropDown.Items = ...
                        {'cycle 4 colors', 'cycle 6 colors', 'cycle 8 colors', 'cycle 10 colors'};
                    defaultColorMap = 'cycle 6 colors';

                case 'immobile/mobile (logD)'
                    app.TracksVisColorMapDropDown.Items = ...
                        {'orange/blue', 'blue/orange', 'red/teal', 'teal/red'};
                    set(app.GO.tracksVis(16:17), "Visible", "on")
                    app.GO.tracksVis(17).Text = "split value";

                case {'mobility (D)', 'mobility (logD)', 'duration', 'displacement', 'range'}
                    app.TracksVisColorMapDropDown.Items = {'inferno', 'inferno (reversed)', 'plasma', ...
                        'plasma (reversed)', 'hot', 'hot (reversed)', 'black', 'white'};
                    % Default: 'plasma' for black and 'plasma (reversed)' for white background.
                    defaultColorMap = ...
                        app.TracksVisColorMapDropDown.Items{app.TracksVisWhiteBackgroundCheckBox.Value + 3};
                    set(app.GO.tracksVis(14:17), 'Visible', "on");

                otherwise
                    % Empty.
            end

            % When the colormap was auto-corrected, the specified default one is chosen instead.
            if ~isempty(defaultColorMap) && ...
                    ~strcmp(app.TracksVisColorMapDropDown.Value, oldColorMap)
                app.TracksVisColorMapDropDown.Value = defaultColorMap;
            end
        end

        function getTracksVisOptions(app)
            % Collect and store all currently set visualization options. Their order in the |VisT| structure is set when
            % it is created in the startup function (some high priority parameters are already set there) and when this
            % function is first called in the startup function. Changed parameters will be processed one by one,
            % according to their order in |VisT|.
            app.VisT(2).colorScheme = app.TracksVisColorSchemeDropDown.Value;
            app.VisT(2).colorMap = app.TracksVisColorMapDropDown.Value;
            app.VisT(2).lineWidth = app.TracksVisLineWidthEditField.Value;
            app.VisT(2).dataLL = app.TracksVisLowerLimitEditField.Value;
            app.VisT(2).dataUL = app.TracksVisUpperLimitEditField.Value;
            app.VisT(2).changedZoom = false;
            app.VisT(2).scaleBar = app.TracksVisScaleBarDropDown.Value;
            app.VisT(2).isWhiteBG = app.TracksVisWhiteBackgroundCheckBox.Value;
            app.VisT(2).showTIFF = app.TracksVisUnderlayTIFFcheckBox.Value;
            app.VisT(2).showUnmasked = app.TracksVisShowUnmaskedCheckBox.Value;

            % In addition to the visualization options selected by the user, the MSD parameters are also needed for
            % coloring tracks according to their mobility etc.           
            app.VisT(2).MSDparams = app.S.MSDanalysis;
        end

        function processClusterFileSelection(app)
            % Disable the listbox when no files are present.
            app.ClusterFileListBox.Enable = ~isempty(app.ClusterFileListBox.Value);

            % Adapt the text in the cluster plotting options panel to the currently chosen algorithm.
            textChoices = ["show Voronoi cells","show DBSCAN assignment",...
                "show NASTIC assignment","show segNASTIC assignment"];
            choiceID = find(contains(textChoices, app.S.cluster.algorithm), 1);
            app.cPlotShowAlgCheckBox.Text = textChoices(choiceID);

            if ~app.ClusterFileListBox.Enable
                % Choose the cluster algorithm that is currently set in the parameter structure S and trigger subsequent
                % changes to the app GUI elements.
                app.ClusterIDdropDown.Value = getClusterAlgStringFromS(app);
                selectNewClusterAlgorithm(app,'external');

                % Hide everything in the visualization axes.
                set(app.GO.cluster,'Visible','off');

                % Disable all user input and return.
                enableUserInput(app,["clusterID","clusterParamsGen", ...
                    "clusterParamsCluster", "clusterPlotsEst"], 0);
                enableButtons(app,"clusterAll",0);
                enableButtons(app,"open parameter window (cluster)",1);
                disableDefaultInteractivity(app.ClusterVisAxes);
                return
            end

            % When the listbox is not disabled, one file will always be selected. Trigger changes of the app GUI
            % elements that are based on the currently set algorithm.
            selectNewClusterAlgorithm(app,'external');

            % Selecting a new file will remove the cluster visualization, reverting back to the visualization of the
            % original base data (tracks, track centroids or localizations).
            app.cPlotShowAlgCheckBox.Value = false;
            if contains(app.ClusterIDdropDown.Value, "tracks")
                app.gPlotBaseDataDropDown.Value = "tracks";
            elseif contains(app.ClusterIDdropDown.Value, "centroids")
                app.gPlotBaseDataDropDown.Value = "track centroids";
            else
                app.gPlotBaseDataDropDown.Value = "localizations";
            end
            enactClusterAlgorithmChange(app);
            enactClusterColorSchemeChange(app);

            % Store the currently set visualization options and duplicate |V| (this is now the reset state of the GUI,
            % from which changes can be tracked and processed).
            getClusterVisOptions(app);
            app.VisC(1) = app.VisC(2);

            % Get the ID of the currently selected file.
            [~, ID] = intersect(app.LocsFileListBox.ItemsData, app.ClusterFileListBox.Value);

            % Coordinate limits are extracted from the supplied data file.
            maxYX = app.S.localization.pixelSize * app.Data(ID).stackDimensions([2 1]);
            [app.VisC(2).axesLimits] = deal([0, maxYX(2), 0, maxYX(1)]);

            % Copy the point list, the tracks and their centroids. 
            app.VisC(2).pointList = double(app.Data(ID).pointList(:,2:3));
            app.VisC(2).centroidList = app.Data(ID).centroidList;
            app.VisC(2).trackArray = app.Data(ID).trackArrayMasked;

            % Remove the old point list data to make sure that the data will be plotted anew.
            app.VisC(1).pointList = [];

            % Update the visualization.
            app.ClusterStatusLabel.Text = 'Plotting ...'; drawnow
            getClusterVisOptions(app);
            app.VisC = updateAppGOsCA(app.GO.cluster, app.ClusterVisAxes, app.VisC);

            % Re-enable buttons that were not dealt with by >selectNewClusterAlgorithm<.
            enableButtons(app, "save image", 1);
            enableButtons(app, "show data", 0);

            % The cluster analysis must be started by hitting the 'run' button. Accordingly, only the general plot
            % options and the axes interactions are enabled.
            enableUserInput(app,"clusterParamsGen",1);
            enableUserInput(app,"clusterParamsCluster",0);
            enableDefaultInteractivity(app.ClusterVisAxes);

            % Enable the 'run' button for the cluster analysis, but disable 'reset' (the latter is only active when
            % cluster data is present and visualized).
            enableButtons(app,"run",1);
            enableButtons(app,"reset",0);
                       
            % Status update.
            app.ClusterStatusLabel.Text = 'idle'; drawnow      
        end

        function enactClusterBaseDataChange(app)
            % Changing the base data will always switch off the cluster algorithm-specific checkbox.
            app.cPlotShowAlgCheckBox.Value = false;

            switch app.gPlotBaseDataDropDown.Value
                case 'localizations'
                    app.gPlotColorSchemeDropDown.Items = {'unicolored'};
                    app.gPlotColorSchemeDropDown.Value = 'unicolored';
                    app.gPlotPointSizeEditField.Enable = "on";
                    app.gPlotLineWidthEditField.Enable = "off";

                case 'track centroids'
                    app.gPlotColorSchemeDropDown.Items = {'unicolored', 'duration', ...
                        'displacement', 'range', 'mobility (D)', 'mobility (logD)', ...
                        'immobile/mobile (logD)'};
                    app.gPlotColorSchemeDropDown.Value = 'unicolored';
                    app.gPlotPointSizeEditField.Enable = "on";
                    app.gPlotLineWidthEditField.Enable = "off";

                case 'tracks'
                    app.gPlotColorSchemeDropDown.Items = {'random', 'duration', ...
                        'displacement', 'range', 'mobility (D)', 'mobility (logD)', ...
                        'immobile/mobile (logD)'};
                    app.gPlotColorSchemeDropDown.Value = 'random';
                    app.gPlotPointSizeEditField.Enable = "off";
                    app.gPlotLineWidthEditField.Enable = "on";

                otherwise
            end
        end

        function enactClusterAlgorithmChange(app)
            
            if app.cPlotShowAlgCheckBox.Value == false
                % When the cluster algorithm-specific checkbox is switched off, revert to the base data options.
                enactClusterBaseDataChange(app);
                app.VisC(2).clusterAlg = 'none';
            else
                switch app.ClusterIDdropDown.Value
                    case 'Voronoi (density, track centroids)'
                        app.gPlotBaseDataDropDown.Value = 'track centroids';
                        app.gPlotColorSchemeDropDown.Items = {'Voronoi (density)'};
                        app.gPlotPointSizeEditField.Enable = "on";
                        app.gPlotLineWidthEditField.Enable = "on";
                        app.VisC(2).clusterAlg = 'Voronoi';

                    case 'Voronoi (area, track centroids)'
                        app.gPlotBaseDataDropDown.Value = 'track centroids';
                        app.gPlotColorSchemeDropDown.Items = {'Voronoi (area)'};
                        app.gPlotPointSizeEditField.Enable = "on";
                        app.gPlotLineWidthEditField.Enable = "on";
                        app.VisC(2).clusterAlg = 'Voronoi';

                    case 'Voronoi (density, localizations)'
                        app.gPlotBaseDataDropDown.Value = 'localizations';
                        app.gPlotColorSchemeDropDown.Items = {'Voronoi (density)'};
                        app.gPlotPointSizeEditField.Enable = "on";
                        app.gPlotLineWidthEditField.Enable = "on";
                        app.VisC(2).clusterAlg = 'Voronoi';

                    case 'Voronoi (area, localizations)'
                        app.gPlotBaseDataDropDown.Value = 'localizations';
                        app.gPlotColorSchemeDropDown.Items = {'Voronoi (area)'};
                        app.gPlotPointSizeEditField.Enable = "on";
                        app.gPlotLineWidthEditField.Enable = "on";
                        app.VisC(2).clusterAlg = 'Voronoi';

                    case 'DBSCAN (track centroids)'
                        app.gPlotBaseDataDropDown.Value = 'track centroids';
                        app.gPlotColorSchemeDropDown.Items = ...
                            {'DBSCAN (random)', 'DBSCAN (noise/cluster)', ...
                            'DBSCAN (noise/core/border)'};
                        app.gPlotPointSizeEditField.Enable = "on";
                        app.gPlotLineWidthEditField.Enable = "off";
                        app.VisC(2).clusterAlg = 'DBSCAN';

                    case 'DBSCAN (localizations)'
                        app.gPlotBaseDataDropDown.Value = 'localizations';
                        app.gPlotColorSchemeDropDown.Items = ...
                            {'DBSCAN (random)', 'DBSCAN (noise/cluster)', ...
                            'DBSCAN (noise/core/border)'};
                        app.gPlotPointSizeEditField.Enable = "on";
                        app.gPlotLineWidthEditField.Enable = "off";
                        app.VisC(2).clusterAlg = 'DBSCAN';

                    case 'NASTIC (tracks)'
                        app.gPlotBaseDataDropDown.Value = 'tracks';
                        app.gPlotColorSchemeDropDown.Items = {'NASTIC'};
                        app.gPlotPointSizeEditField.Enable = "off";
                        app.gPlotLineWidthEditField.Enable = "on";
                        app.VisC(2).clusterAlg = 'NASTIC';

                    case 'segNASTIC (tracks)'
                        app.gPlotBaseDataDropDown.Value = 'tracks';
                        app.gPlotColorSchemeDropDown.Items = {'segNASTIC'};
                        app.gPlotPointSizeEditField.Enable = "off";
                        app.gPlotLineWidthEditField.Enable = "on";
                        app.VisC(2).clusterAlg = 'segNASTIC';

                    otherwise
                        % Empty.
                end
            end
        end

        function enactClusterColorSchemeChange(app)
            % For some cases, it might be necessary to specify a default colormap (when the color scheme is changed and
            % the previously chosen colormap is no longer valid). Otherwise, the value will be automatically set to the
            % first item in the Items property (which is fine for most cases).
            defaultColorMap = '';
            oldColorMap = app.gPlotColorMapDropDown;

            % Set the visibility and text for the limit edit fields and their labels, so only their exceptions must be
            % addressed below.
            set(app.GO.cluster(10:13), 'Visible', "off");
            app.GO.cluster(13).Text = "to";
            app.gPlotColorMapDropDownLabel.Text = 'color map';

            switch app.gPlotColorSchemeDropDown.Value
                case 'unicolored'
                    app.gPlotColorMapDropDown.Items = app.c.CB.Properties.RowNames;
                    app.gPlotColorMapDropDownLabel.Text = 'color';
                    defaultColorMap = 'green';

                case {'random', 'DBSCAN (random)'}
                    app.gPlotColorMapDropDown.Items = ...
                        {'cycle 4 colors', 'cycle 6 colors', 'cycle 8 colors', 'cycle 10 colors'};
                    defaultColorMap = 'cycle 6 colors';

                case 'immobile/mobile (logD)' 
                    app.gPlotColorMapDropDown.Items = ...
                        {'orange/blue', 'blue/orange', 'red/teal', 'teal/red'};
                    set(app.GO.cluster(12:13), 'Visible', "on")
                    app.GO.cluster(13).Text = "split value";

                case {'mobility (D)', 'mobility (logD)', 'duration', 'displacement', 'range', ...
                        'Voronoi (density)', 'Voronoi (area)'}
                    app.gPlotColorMapDropDown.Items = {'inferno', 'inferno (reversed)', 'plasma', ...
                        'plasma (reversed)', 'hot', 'hot (reversed)', 'black', 'white'};
                    % Default: 'plasma' for black and 'plasma (reversed)' for white background.
                    defaultColorMap = ...
                        app.gPlotColorMapDropDown.Items{app.gPlotWhiteBGcheckBox.Value + 3};
                    set(app.GO.cluster(10:13), 'Visible', "on");     

                case 'DBSCAN (noise/cluster)'
                    app.gPlotColorMapDropDown.Items = ...
                        {'orange/blue', 'blue/orange', 'red/teal', 'teal/red'};

                case 'DBSCAN (noise/core/border)'
                    app.gPlotColorMapDropDown.Items = ...
                        {'gray/orange/blue', 'gray/blue/orange', 'gray/red/teal', 'gray/teal/red'};

                case 'NASTIC'
                    app.gPlotColorMapDropDown.Items = ...
                        {'orange/blue', 'blue/orange', 'red/teal', 'teal/red'};

                case 'segNASTIC'
                    app.gPlotColorMapDropDown.Items = ...
                        {'orange/blue', 'blue/orange', 'red/teal', 'teal/red'};

                otherwise
                    % Empty.
            end

            % When the colormap was auto-corrected, the specified default one is chosen instead.
            if ~isempty(defaultColorMap) && ...
                    ~strcmp(app.gPlotColorMapDropDown.Value, oldColorMap)
                app.gPlotColorMapDropDown.Value = defaultColorMap;
            end
        end

        function getClusterVisOptions(app)
            % Collect and store all currently set visualization options. Their order in the |VisC| structure is set when
            % it is created in the startup function (some high priority parameters are already set there) and when this
            % function is first called in the startup function. Changed parameters will be processed one by one,
            % according to their order in |VisC|.
            app.VisC(2).baseDataStyle = app.gPlotBaseDataDropDown.Value;
            app.VisC(2).colorScheme = app.gPlotColorSchemeDropDown.Value;
            app.VisC(2).colorMap = app.gPlotColorMapDropDown.Value;
            app.VisC(2).pointSize = app.gPlotPointSizeEditField.Value;
            app.VisC(2).gLineWidth = app.gPlotLineWidthEditField.Value;
            app.VisC(2).dataLL = app.gPlotLowerLimitEditField.Value;
            app.VisC(2).dataUL = app.gPlotUpperLimitEditField.Value;
            app.VisC(2).scaleBar = app.gPlotScaleBarDropDown.Value;
            app.VisC(2).isWhiteBG = app.gPlotWhiteBGcheckBox.Value;
            app.VisC(2).showClusterAlg = app.cPlotShowAlgCheckBox.Value;
            if app.cPlotShowAlgCheckBox.Value == false
                app.VisC(2).clusterAlg = 'none';
            end
            app.VisC(2).clusterBoundariesLineWidth = app.cPlotBoundariesLineWidthEditField.Value;
            app.VisC(2).clusterBoundariesColor = app.cPlotBoundariesColorDropDown.Value;
            app.VisC(2).clusterAreaIsSemiTransparent = app.cPlotAreasSemiTransparentCheckBox.Value;
            app.VisC(2).clusterAreaColor = app.cPlotAreasColorDropDown.Value;

            % In addition to the visualization options selected by the user, the MSD parameters are also needed for
            % coloring tracks according to their mobility etc.           
            app.VisC(2).MSDparams = app.S.MSDanalysis;
        end

        function ClusterAxesLimitsChangedCallback(app)
            % Abort if the scale bar is switched off or if there is no data to plot.
            if strcmp(app.gPlotScaleBarDropDown.Value, "none")
                return
            end

            % Otherwise, force a visualization update by setting the old value in |VisC| to "none".
            app.VisC(1).scaleBar = "none";
            app.VisC = updateAppGOsCA(app.GO.cluster, app.ClusterVisAxes, app.VisC);

        end

        function TracksVisAxesLimitsChangedCallback(app)
            % Trigger the visualization update.
            app.VisT(2).changedZoom = true;
            app.VisT = updateAppGOsTracksVis(app.GO.tracksVis, app.h.tracksVis.axes, app.VisT);
        end

        function selectNewClusterAlgorithm(app, sourceStr)
            % This function adapts all panel contents to the selected cluster ID algorithm. First, clear the axes in the
            % parameter estimation panel and the estimated parameter.
            cla(app.ClusterEstAxes);
            app.ClusterEstParameterEditField.Value = 0;

            % Set the default enabled/disabled state of the objects in the parameter estimation panel (and make them all
            % visible). Also make the second parameter input in the cluster ID panel visible.
            enableUserInput(app, ["clusterPlotsEst","clusterID"], 1);
            enableButtons(app,"run estimation",1);
            enableButtons(app,"transfer",0);
            app.ClusterEstParameterEditFieldLabel.Visible = "on";

            % Get the cluster algorithm either from the GUI or from the parameter structure.
            if strcmp(sourceStr,'internal')
                clusterAlg = app.ClusterIDdropDown.Value;
            else
                clusterAlg = getClusterAlgStringFromS(app);
                app.ClusterIDdropDown.Value = clusterAlg;
            end
                        
            app.clusterIDparam1EditFieldLabel.Interpreter = 'none';
            app.clusterIDparam2editField.Visible = "on";
            app.clusterIDparam2EditFieldLabel.Visible = "on";

            % Recall the value for 'minN' or store the edited one.
            if strcmp(sourceStr,'internal')
                app.S.cluster.minN = app.clusterIDminNeditField.Value;
            else
                app.clusterIDminNeditField.Value = app.S.cluster.minN;
            end

            % Change the cluster base data in the parameter structure S according to the drop down menu choice.
            if contains(clusterAlg, "localizations")
                app.S.cluster.input = 'localizations';
                app.clusterIDminNeditFieldLabel.Text = ["at least N";"locs per cluster"];
            elseif contains(clusterAlg, "centroids")
                app.S.cluster.input = 'track centroids';
                app.clusterIDminNeditFieldLabel.Text = ["at least N";"tracks per cluster"];
            else
                app.S.cluster.input = 'tracks';
                app.clusterIDminNeditFieldLabel.Text = ["at least N";"tracks per cluster"];
            end

            % The different cluster algorithms will have different options. They are handled by the same edit fields in
            % the app, so they must be named according to the algorithm and their values must be assigned to the correct
            % parameters in the parameter structure S.
            if contains(clusterAlg, "Voronoi")
                app.clusterIDparam2editField.Visible = "off";
                app.clusterIDparam2EditFieldLabel.Visible = "off";
                app.ClusterEstNSimulationsEditField.Visible = "on";
                app.ClusterEstNSimulationsEditFieldLabel.Visible = "on";
                app.ClusterEstNSimulationsEditField.Value = 5;
                app.ClusterEstRunNsimulationsButton.Text = "run N simulations";
                app.ClusterEstAxes.Title.String = "data vs. CSR (Monte Carlo simulations)";
                app.ClusterEstAxes.YLabel.String = "counts";

                if contains(clusterAlg, "density")
                    app.segmentationMethodLabel.Text = "$\delta^{1}_{i} > \alpha \cdot \delta$";
                    app.clusterIDparam1EditFieldLabel.Text = char(945);
                    app.ClusterEstParameterEditFieldLabel.Text = "estimation of " + char(945);
                    app.ClusterEstAxes.XLabel.String = ...
                        "Normalized Voronoi first-rank density (\delta^{1}_{i}/\delta)";   

                    % Recall the value or store the edited one.
                    if strcmp(sourceStr,'internal')
                        app.S.cluster.Voronoi.alpha = app.clusterIDparam1editField.Value;
                    else
                        app.clusterIDparam1editField.Value = app.S.cluster.Voronoi.alpha;
                    end

                    % Update the parameter structure S.
                    app.S.cluster.algorithm = 'Voronoi';
                    app.S.cluster.Voronoi.method = 'density';

                elseif contains(clusterAlg, "area")
                    app.segmentationMethodLabel.Text = "$\rm area_{i} < area_{thresh}$";
                    app.clusterIDparam1EditFieldLabel.Interpreter = 'tex';
                    app.clusterIDparam1EditFieldLabel.Text = "area_{thresh}";
                    app.ClusterEstParameterEditFieldLabel.Text = ["estimation of";"area_{thresh}"];   
                    app.ClusterEstAxes.XLabel.String = "Voronoi cell area (nm²)";

                    % Recall the value or store the edited one.
                    if strcmp(sourceStr,'internal')
                        app.S.cluster.Voronoi.areaThreshold = app.clusterIDparam1editField.Value;
                    else
                        app.clusterIDparam1editField.Value = app.S.cluster.Voronoi.areaThreshold;
                    end

                    % Update the parameter structure S.
                    app.S.cluster.algorithm = 'Voronoi';
                    app.S.cluster.Voronoi.method = 'area';
                end

            elseif contains(clusterAlg, "DBSCAN")
                app.segmentationMethodLabel.Text = "";
                app.clusterIDparam1EditFieldLabel.Text = "minimum points";
                app.clusterIDparam2EditFieldLabel.Text = char(949);
                app.ClusterEstNSimulationsEditField.Visible = "off";
                app.ClusterEstNSimulationsEditFieldLabel.Visible = "off";
                app.ClusterEstRunNsimulationsButton.Text = "run estimation";
                app.ClusterEstParameterEditFieldLabel.Text = "estimation of " + char(949);
                             
                % Recall the edit field values or write them to S.
                if strcmp(sourceStr,'internal')
                    app.S.cluster.DBSCAN.minPts = app.clusterIDparam1editField.Value;
                    app.S.cluster.DBSCAN.epsilon = app.clusterIDparam2editField.Value;
                else
                    app.clusterIDparam1editField.Value = app.S.cluster.DBSCAN.minPts;
                    app.clusterIDparam2editField.Value = app.S.cluster.DBSCAN.epsilon;
                end

                % Set the labels for the k-NN plot.
                k = app.S.cluster.DBSCAN.minPts;
                app.ClusterEstAxes.Title.String = sprintf('%u-NN distance plot',k);
                app.ClusterEstAxes.XLabel.String = ...
                    sprintf('Sorted %uth nearest distances of all points',k);
                app.ClusterEstAxes.YLabel.String = char(949);

                % Update the parameter structure S.
                app.S.cluster.algorithm = 'DBSCAN';

            elseif contains(clusterAlg, "NASTIC")
                app.segmentationMethodLabel.Text = "";
                app.clusterIDparam1EditFieldLabel.Text = "radius factor";

                app.ClusterEstAxes.Title.String = "";
                app.ClusterEstAxes.XLabel.String = "";
                app.ClusterEstAxes.YLabel.String = "";
                app.ClusterEstRunNsimulationsButton.Text = "";
                app.ClusterEstRunNsimulationsButton.Text = "run estimation";
                app.ClusterEstParameterEditFieldLabel.Visible = "off";
                app.ClusterEstNSimulationsEditField.Visible = "off";
                app.ClusterEstNSimulationsEditFieldLabel.Visible = "off";
                enableButtons(app,["run estimation","transfer"],0);
                enableUserInput(app,"clusterPlotsEst",0);

                if contains(clusterAlg, "seg")
                    app.clusterIDparam2EditFieldLabel.Text = {'N segment'; 'overlaps'};

                    % Recall the edit field values or write them to S.
                     if strcmp(sourceStr,'internal')
                        app.S.cluster.segNASTIC.r = app.clusterIDparam1editField.Value;
                        app.S.cluster.segNASTIC.nSegmentOverlaps = ...
                            app.clusterIDparam2editField.Value;
                    else
                        app.clusterIDparam1editField.Value = app.S.cluster.segNASTIC.r;
                        app.clusterIDparam2editField.Value = ...
                            app.S.cluster.segNASTIC.nSegmentOverlaps;
                    end

                    % Update the parameter structure S.
                    app.S.cluster.algorithm = 'segNASTIC';
                else
                   app.clusterIDparam2editField.Visible = "off";
                   app.clusterIDparam2EditFieldLabel.Visible = "off";

                   % Recall the edit field value or write it to S.
                   if strcmp(sourceStr,'internal')
                       app.S.cluster.NASTIC.r = app.clusterIDparam1editField.Value;
                   else
                       app.clusterIDparam1editField.Value = app.S.cluster.NASTIC.r;
                   end
                   
                   % Update the parameter structure S.
                   app.S.cluster.algorithm = 'NASTIC';
                end

            else
                % Empty.
            end

            % Update the external parameter app if the the cluster parameters were changed inside this app.
            if strcmp(sourceStr,'internal')
                updateParameterApp(app);
            end
        end

        function closeDialogBox(~,src,~)
            % Function that executes when the user presses a button in a uiconfirm or uialert dialog box. The only thing
            % it does is closing the figure (src) the dialog box was created in. This allows to put >uiwait< into the
            % calling function, which blocks execution until the figure is closed (now triggered by pressing a dialog
            % button)
            close(src);
        end
       
        function saveAxesAsPDF(~, axesHandle, fullFilename, isAppended)
            % Saves an app designer plot that is displayed in an axis object to a .pdf file. For more control over the
            % displayed elements, the data is first copied to a temporary figure. When |isAppended| is true, the plot
            % will be appended as a new page to the specified .pdf file.
            tmpFig = figure('Visible','off');
            copyobj(axesHandle, tmpFig);
            tmpAx = tmpFig.Children;

            % Delete all invisible objects (go backwards as deleted objects change the forward numerical assigment).
            for iChild = numel(tmpAx.Children):-1:1
                if strcmp(tmpAx.Children(iChild).Visible, "off")
                    delete(tmpAx.Children(iChild));
                end
            end

            % Adjust the dimensions of the axes.
            tmpAx.Units = 'normalized';
            tmpFig.Position = [100 100 axesHandle.Position(3:4)];
            tmpAx.Position = [0 0 1 1];

            % Export and close the temporary figure.
            exportgraphics(tmpAx, fullFilename, 'BackgroundColor', tmpAx.Color, 'ContentType', 'vector', ...
                'Append', isAppended);
            close(tmpFig);
        end

        function saveColorbarAsPDF(~, axesHandle, colorbarText, fullFilename, isAppended)
            % Saves the colorbar of a plot to a .pdf file (function input is the corresponding axes). When |isAppended|
            % is true, the colorbar will be appended as a new page to the specified .pdf file.
            tmpFig = figure('Visible','off');
            copyobj(axesHandle, tmpFig);
            tmpAx = tmpFig.Children;

            % Adjust the dimensions of the axes.
            tmpAx.Units = 'normalized';
            tmpFig.Position = [100 100 axesHandle.Position(3:4)];
            tmpAx.Position = [0 0 1 1];

            % Delete all invisible objects (go backwards as deleted objects change the forward numerical assigment).
            for iChild = numel(tmpAx.Children):-1:1
                if strcmp(tmpAx.Children(iChild).Visible, "off")
                    delete(tmpAx.Children(iChild));
                end
            end

            % Create a new colorbar.
            tmpColorbar = colorbar(tmpAx, 'Location', 'southoutside');

            % Hide the axes and all plots (deleting would also delete the colorbar).
            tmpAx.Visible = "off";
            set(tmpAx.Children,'Visible','off');

            % The "log10 " string in edit fields must be "translated" to the MATLAB TeX markup.
            normalFontSize = tmpColorbar.Label.FontSize;
            normalFontSizeStr = num2str(normalFontSize);
            smallFontSizeStr = num2str(normalFontSize-3);
            log10str = "log\fontsize{" + smallFontSizeStr + "}_{10 }\fontsize{" + normalFontSizeStr + "}";
            colorbarText = replace(colorbarText, "log" + char([0x2081, 0x2080]) + " ", log10str);       

            % Set the colorbar label.
            tmpColorbar.Label.String = colorbarText;

            % Many color scheme options require some adjustments.
            if contains(colorbarText,"random")
                tmpColorbar.Label.String = "random coloring";
                tmpColorbar.Ticks = [];
            elseif contains(colorbarText,"immobile/mobile")
                tmpColorbar.Label.String = insertAfter(colorbarText, "immobile/mobile (logD)", ...
                    sprintf(" with split value %.2f", tmpColorbar.Ticks(2)));
                tmpColorbar.Ticks = [];
            elseif contains(colorbarText,"density")
                tmpColorbar.Label.String = ...
                    "local density (\delta_{i}^{1}/\delta)";
            elseif contains(colorbarText,"area")
                tmpColorbar.Label.String = "Voronoi cell area (nm²)";
            elseif contains(colorbarText,"DBSCAN")
                tmpColorbar.Label.String = erase(tmpColorbar.Label.String, " ()");
                tmpColorbar.Ticks = [];
            elseif contains(colorbarText,"NASTIC")
                tmpColorbar.Label.String = "not part of/part of cluster";
                tmpColorbar.Ticks = [];
            end

            % Export and close the temporary figure.
            exportgraphics(tmpAx, fullFilename, 'BackgroundColor', 'none', 'ContentType', 'vector', ...
                'Append', isAppended);
            close(tmpFig);
        end
         

        function enableButtons(app, buttonNames, buttonState)
            % Switches a button between its enabled (|buttonState| = 1), disabled (|buttonState| = 0) or primed
            % (|buttonState| = 2) state, including color changes. Here, |buttonNames| can be "all" (for all buttons
            % acrosse the app), "locsAll"/"tracksAll"/"clusterAll" for all buttons in the respective tab or a string
            % (array) that refers to the button Tag property. In most cases, this will be the same as the button Text
            % property (except when the same button text is on multiple buttons across all tabs and must be
            % differentiated via the Tag). Additionally, |buttonNames| can be "storeRecall", then |buttonState| = 0
            % stores the state of all buttons, and |buttonState| = 1 sets them all to their previous state.

            if strcmp(buttonNames,"open parameter window")
                % Sets the open parameter window buttons in all tabs.
                ID = contains(get(app.h.pushButtons,'Tag'),"open parameter window");
                buttonHandles{1} = app.h.pushButtons(ID);

            elseif strcmp(buttonNames,"storeRecall")
                if buttonState == 0
                    % Discriminate between the button states by their background color.
                    buttonColors = cellfun(@(x) x(2,:),struct2cell(app.c.pushButton),'UniformOutput',false);
                    currentButtonColors = get(app.h.pushButtons,'BackgroundColor');
                    [~,app.tmp.buttonStates] = ismember(cell2mat(currentButtonColors),cell2mat(buttonColors),'rows');

                    % Store the button states with the numbers explained above and return.
                    app.tmp.buttonStates = app.tmp.buttonStates - 1;
                    return

                elseif buttonState == 1
                    buttonHandles{1} = app.h.pushButtons(app.tmp.buttonStates==0);
                    buttonHandles{2} = app.h.pushButtons(app.tmp.buttonStates==1);
                    buttonHandles{3} = app.h.pushButtons(app.tmp.buttonStates==2);
                    buttonState = 0:2;
                end
            elseif strcmp(buttonNames,"all")
                buttonHandles{1} = app.h.pushButtons;
            elseif strcmp(buttonNames,"locsAll")
                buttonHandles{1} = findobj(app.LocalizationTab,'-depth',3,'Type','uibutton','-not','Style','state');
            elseif strcmp(buttonNames,"tracksAll")
                buttonHandles{1} = findobj(app.TrackingTab,'-depth',3,'Type','uibutton','-not','Style','state');
            elseif strcmp(buttonNames,"clusterAll")
                buttonHandles{1} = findobj(app.ClusterAnalysisTab,'-depth',3,'Type','uibutton','-not','Style','state');
            else
                % Intersect the push button tags with the selected tags to get the indices in the handle array.
                [~,ID] = intersect(arrayfun(@(x) x.Tag, app.h.pushButtons, 'uni', 0), buttonNames);
                buttonHandles{1} = app.h.pushButtons(ID);
            end

            % Exclude the 'open parameter window' buttons from all grouped button lists, as they are always enabled.
            if any(ismember(buttonNames, ["all","locsAll","tracksAll","clusterAll"]))
                excludeTF = arrayfun(@(x) contains(x.Tag,"open parameter window"),buttonHandles{1});
                buttonHandles{1} = buttonHandles{1}(~excludeTF);
            end

            for iState = 1:numel(buttonHandles)
                switch buttonState(iState)
                    case 0
                        set(buttonHandles{iState},'Enable','off');
                        set(buttonHandles{iState},'FontColor',app.c.pushButton.off(1,:));
                        set(buttonHandles{iState},'BackgroundColor',app.c.pushButton.off(2,:));
                    case 1
                        set(buttonHandles{iState},'Enable','on');
                        set(buttonHandles{iState},'FontColor',app.c.pushButton.on(1,:));
                        set(buttonHandles{iState},'BackgroundColor',app.c.pushButton.on(2,:));
                    case 2
                        set(buttonHandles{iState},'Enable','on');
                        set(buttonHandles{iState},'FontColor',app.c.pushButton.primed(1,:));
                        set(buttonHandles{iState},'BackgroundColor',app.c.pushButton.primed(2,:));
                end
            end
        end
        
        function enableUserInput(app, IDarray, tf)
            % Enables (tf=1) or disables (tf=0) the user input for the chosen set of objects. IDarray must be a string
            % array. The following groups are defined:
            % "locsAll": all objects in the Localization tab
            % "locsPlots": Localization tab, all objects pertaining the histograms
            % "locsParams": Localization tab, localization parameters panel
            % "locsFiles": Localization tab, file list and check box (select all)
            % "tracksAll": all objects in the Tracking tab
            % "tracksPlots": Tracking tab, all objects pertaining the plots
            % "tracksParamsBuild": Tracking tab, track building parameters panel
            % "tracksParamsFit": Tracking tab, track fitting parameters panel
            % "tracksFiles": Tracking tab, file list and 'select all' check box
            % "tracksVis": all objects pertaining the track visualization
            % "clusterPlotsEst": Cluster Analysis tab, all objects pertaining the parameter estimation plot
            % "clusterPlotsVis": Cluster Analysis tab, all objects pertaining the cluster visualization plot
            % "clusterID": Cluster Analysis tab, cluster ID panel
            % "clusterParamsGen": Cluster Analysis tab, general plotting options
            % "clusterParamsCluster": Cluster Analysis tab, cluster plotting options
            % "clusterFiles": Cluster Analysis tab, file list
            
            % If 'All' is found in the IDarray, expand the connected cases.
            if any(contains(IDarray,'All'))
                containerAll = {...
                    ["locsPlots","locsParams","locsFiles"]; ...
                    ["tracksPlots","tracksParamsBuild","tracksParamsFit","tracksFiles","tracksVis"]; ...
                    ["clusterPlotsEst","clusterPlotsVis","clusterID","clusterParamsGen",...
                    "clusterParamsCluster","clusterFiles"] ...
                    };
                containerIDs = ["locsAll","tracksAll","clusterAll"];
                [~,~,addID] = intersect(IDarray,containerIDs);
                IDarray = [IDarray,containerAll{addID}];
                % The "All" strings can stay in as they are not addressed in the switch block. Remove duplicates.
                IDarray = unique(IDarray);
            end
            
            for iCase = 1:numel(IDarray)
                switch IDarray(iCase)
                    case "locsPlots"
                        set(app.h.locs.rangeSliders,'Enable',tf);
                        set(app.h.locs.rangeLL,'Enable',tf);
                        set(app.h.locs.rangeUL,'Enable',tf);
                    
                    case "locsParams"
                        set(app.h.locs.params,'Enable',tf);
                                    
                    case "locsFiles"
                        app.LocsFileListBox.Enable = tf;
                        app.LocsSelectAllFilesCheckBox.Enable = tf;
                        
                    case "tracksPlots"
                        set(findobj(app.TracksMSDanalysisPanel.Children,'-depth',0,...
                            'Type','uinumericeditfield','-or','Type','uicheckbox'),'Enable',tf);
                        
                    case "tracksVis"
                        set(findobj(app.TracksVisPanel.Children,'-depth',1,...
                            'Type','uinumericeditfield','-or','Type','uicheckbox','-or', ...
                            'Type','uidropdown'),'Enable',tf);
                        
                    case "tracksParamsBuild"
                        set(app.h.tracks.paramsBuild,'Enable',tf);
                        
                    case "tracksParamsFit"
                        set(app.h.tracks.paramsFit,'Enable',tf);
                        
                    case "tracksFiles"
                        app.TracksFileListBox.Enable = tf;
                        app.TracksSelectAllFilesCheckBox.Enable = tf;

                    case "clusterPlotsEst"
                        set(app.h.cluster.paramsEst,'Enable',tf);

                    case "clusterPlotsVis"
                        % For future use.

                    case "clusterID"
                        set(app.h.cluster.paramsID,'Enable',tf);
                        set(app.ClusterIDdropDown,'Enable',tf);

                    case "clusterParamsGen"
                        set(findobj(app.gPlotPanel.Children,'-depth',0),'Enable',tf);

                    case "clusterParamsCluster"
                        set(findobj(app.cPlotPanel.Children,'-depth',0),'Enable',tf);

                    case "clusterFiles"
                        app.ClusterFileListBox.Enable = tf;
                end
            end
        end 
            
        function newYticks = calcYticks(~,limitsY)
            % Quick method to calculate appropriate y ticks for given y-axis limits (1x2 vector), producing 5-6 ticks.
            
            % Round the tick distance to two significant digits.
            tickDist = round(range(limitsY)/4,2,'significant');
            
            % Calculate the lower tick position to start at a multiple of tickDist.
            startTick = floor(limitsY(1)/tickDist)*tickDist;
            
            % Construct ticks, but recalculate if this produces only 4 ticks.
            newYticks = startTick:tickDist:limitsY(2);
            if numel(newYticks) == 4
                tickDist = round(range(limitsY)/5,1,'significant');
                startTick = floor(limitsY(1)/tickDist)*tickDist;
                newYticks = startTick:tickDist:limitsY(2);
            end        
        end
     
        function updateParameterApp(app)
            % Return if the parameter app is not open.
            if isempty(app.ParameterApp) || ~isvalid(app.ParameterApp)
                return
            end

            % Transfer the updated parameter structure to the parameter app and trigger the reset of
            % the tree there.
            app.ParameterApp.resetTree(app.S);  
        end
        
        function show1or2pops(app)
            % Sets the visualization in the right MSD plot in the Tracking tab to show the fit based on one or two
            % populatons.
            app.TracksDdistSplitEditFieldLabel.Visible = app.Tracks2PopCheckBox.Value;
            app.TracksDdistSplitEditField.Visible = app.Tracks2PopCheckBox.Value;
            app.GO.tracks(5).Visible = ~app.Tracks2PopCheckBox.Value;
            set(app.GO.tracks(6:9), 'Visible', app.Tracks2PopCheckBox.Value);
            app.TracksDdistResultLabel.Text = app.TracksDdistResultLabel.UserData{app.Tracks2PopCheckBox.Value + 1};
        end
        
        function scaleMSDplots(app)
            % Scales the MSD and D distribution plots of the Tracking tab based on the currently calculated data.

            % If the autoscale checkbox is ticked, limit the displayed data to lag times of up to one second.
            if app.TracksMeanMSDautoScaleCheckBox.Value == true
                app.h.tracks.axesMSD(1).XLim = [0 1];
            else
                app.h.tracks.axesMSD(1).XLim = [0 max(app.GO.tracks(1).XData)];
            end

            % Base the y limit of the axes on the mean MSD data (not the fit lines).
            xID = find(app.GO.tracks(1).XData <= app.h.tracks.axesMSD(1).XLim(end));
            if isempty(xID)
                xID = 1:numel(app.GO.tracks(1).XData);
            end
            app.h.tracks.axesMSD(1).YLim = [0, 1.05 * max(app.GO.tracks(1).YData(xID))];

            % Change the y tick precision to conform with the data.
            tickPrecision = max(0, 1 - round(log10(app.h.tracks.axesMSD(1).YLim(2))));
            app.h.tracks.axesMSD(1).YRuler.TickLabelFormat = sprintf('%%.%uf', tickPrecision);

            % Find the maximum value of all y data points that were calculated for the D distribution plots and adjust
            % the y limit of the axes accordingly. The y axis is given in integer values (no need to change the
            % precision).
            allY = get(app.GO.tracks(4:8), 'YData');
            newYLimMax = max(horzcat(allY{:}));
            app.h.tracks.axesMSD(2).YLim = [0, newYLimMax];
            
            % Adjust the split line height to the new y limit.
            app.GO.tracks(9).YData = [0, 0.8*newYLimMax];
        end      
        
        function changeMSDsplitValue(app, sourceStr)
            % Recall the value for the split value or store the edited one.
            if strcmp(sourceStr, 'internal')
                app.S.MSDanalysis.splitValue = app.TracksDdistSplitEditField.Value;
                updateParameterApp(app);
            else
                app.TracksDdistSplitEditField.Value = app.S.MSDanalysis.splitValue;
            end

            % Return if no logDlist is present.
            if ~isfield(app.tmp,"logDlist")
                return
            end

            % Recalculate the slow component fraction.
            splitValue = app.TracksDdistSplitEditField.Value;
            app.GO.tracks(9).XData = [splitValue, splitValue];
            slowFractionSplit = sum(app.tmp.logDlist <= splitValue) / numel(app.tmp.logDlist);

            % Parse the old results message and replace the corresponding value.
            resultsString = app.TracksDdistResultLabel.UserData{2};
            startIndex = strfind(resultsString,'(split value)')-5;
            resultsString(startIndex:startIndex+3) = sprintf('%.2f', slowFractionSplit);
            app.TracksDdistResultLabel.UserData{2} = resultsString;

            % Only show the results string if the 'two Populations' checkbox is enabled and selected.
            if app.Tracks2PopCheckBox.Enable && app.Tracks2PopCheckBox.Value == true
                app.TracksDdistResultLabel.Text = resultsString;
            else
                app.TracksDdistResultLabel.Text = '';
            end
        end

        function updateShowClusterDataApp(app)
            if ~isempty(app.ShowClusterDataApp) && isvalid(app.ShowClusterDataApp)
                % Put the already opened app in front and update the table data.
                figure(app.ShowClusterDataApp.UIFigure);
                app.ShowClusterDataApp.updateTable(app.tmp.clusterData);
            end
        end
        
        function checkBatchStart(app)
            % Checks if all prerequisites are met to unlock batch analysis.
            if ~isempty(app.BatchInputFileTable.Data) && ...
                all(app.BatchInputFileTable.Data{:,3} > 0) && ...
                ~isempty(app.BatchOutputFileEditField.Value)
                enableButtons(app, "start batch analysis", 1)
            else
                enableButtons(app, "start batch analysis", 0)
            end
        end
        
        function clusterAlgString = getClusterAlgStringFromS(app)
            % Parses the information from the parameter structure S to produce a string that is the corresponding choice
            % in the cluster ID drop down menu.
            clusterAlgInput = app.S.cluster.input;
            switch app.S.cluster.algorithm
                case 'Voronoi'
                    VoronoiMethod = app.S.cluster.Voronoi.method;
                    clusterAlgString = ...
                        sprintf('Voronoi (%s, %s)', VoronoiMethod, clusterAlgInput);
                case 'DBSCAN'
                    clusterAlgString = ...
                        sprintf('DBSCAN (%s)', clusterAlgInput);
                case 'NASTIC'
                    clusterAlgString = 'NASTIC (tracks)';
                case 'segNASTIC'
                    clusterAlgString = 'segNASTIC (tracks)';
                otherwise
                    % Empty.
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% General initialization
            
            % Update the status line.
            app.LocsStatusLabel.Text = 'Initializing...'; drawnow;

            % Prevent the user from changing tabs during initialization.
            app.tmp.blockTabChange = true;

            % Define some colors.
            app.c = getColors;

            % Add black and white.
            namesBW = {'black','white'}; RGB = [0, 0, 0; 1, 1, 1];
            addBW = table(RGB, 'VariableNames', {'all'}, 'RowNames', namesBW);
            app.c.CB = [app.c.CB; addBW];

            % Choose the colors for the three localization histograms.
            app.c.histcols = [app.c.UT{'darkBlue',:}; app.c.UT{'blue',:}; app.c.UT{'lightBlue',:}];
            
            % Choose the colors for active / disabled / primed push buttons, with rows: text color, background color.
            app.c.pushButton.off = [0,0,0;0.96,0.96,0.96];
            app.c.pushButton.on = [1,1,1; app.c.UT{'darkGreen',:}];
            app.c.pushButton.primed = [1,1,1; app.c.UT{'gold',:}];

            % Collect the handles for all push buttons (excluding radio buttons).
            app.h.pushButtons = findobj(app.MainUIFigure.Children,'-depth',4,'Type','uibutton','-not','Style','state');

            % Check if a supported GPU available, a recent GPU driver is present, and the Parallel Computing Toolbox is
            % installed and licensed.
            if canUseGPU()
                app.tmp.useGPU = true;
            else
                message = "No supported GPU found (see manual for troubleshooting), so localization will be " + ...
                    "done on the CPU. Please note that this step can therefore take a long time.";
                uialert(app.MainUIFigure, message, 'Warning', 'Icon', 'warning');
                app.tmp.useGPU = false;
            end

            % Create the parameter structure with default values.
            app.S = getDefaultS;
        
            % Set the path for data files (initially the MATLAB current folder).
            app.tmp.lastDirectory = pwd;

            %% Localization tab

            % Get the axes handles.
            app.h.locs.axes = findobj(app.LocalizationTab.Children,'-depth',1,'Type','axes');

            % Get the handles to the edit fields in the localization parameters panel.
            app.h.locs.params = findobj(app.LocsLocparamPanel.Children,'-depth',0, 'Type','uinumericeditfield');

            % Put in initial fitting values from the parameter structure.
            app.h.locs.params(1).Value = app.S.localization.conversion;
            app.h.locs.params(2).Value = app.S.localization.offset;
            app.h.locs.params(3).Value = app.S.localization.pixelSize;
            app.h.locs.params(4).Value = app.S.localization.filterSize;
            app.h.locs.params(5).Value = app.S.localization.cutoff;
            app.h.locs.params(6).Value = app.S.localization.PSFROI;

            % Get the range slider handles in the localization tab.
            app.h.locs.rangeSliders = findobj(app.LocalizationTab.Children,'-depth',1,'Type','uirangeslider');

            % Get the handles to the slider limit edit fields in the localization parameters panel.
            tmpObjects = findobj(app.LocalizationTab.Children,'-depth',1, 'Type','uieditfield');
            app.h.locs.rangeLL = tmpObjects(2:2:end);
            app.h.locs.rangeUL = tmpObjects(1:2:end);

            % Get initial limits from the parameter structure to adjust the range sliders. Use default values for limits
            % that are set to infinity.
            defaultLimits = [0 300; 0 1000; 0 60];
            histLimits = table2array(app.S.localization.limits);
            histLimits(isinf(histLimits)) = defaultLimits(isinf(histLimits));
            for iSlider = 1:3
                app.h.locs.rangeSliders(iSlider).Limits = histLimits(iSlider,:);
                app.h.locs.rangeSliders(iSlider).Value = histLimits(iSlider,:);
                app.h.locs.rangeLL(iSlider).Value = num2str(histLimits(iSlider,1));
                app.h.locs.rangeUL(iSlider).Value = num2str(histLimits(iSlider,2));
            end

            % Initialize the graphic objects for the localization histograms.
            app.GO.locs = gobjects(3,3);

            % Set all localization axes to accept multiple elements.
            set(app.h.locs.axes,'NextPlot','add');

            % Create placeholder objects.
            for iHist = 1:3
                app.GO.locs(1,iHist) = bar(app.h.locs.axes(iHist),1,1,'Visible','off','BarWidth',1);
                app.GO.locs(2,iHist) = bar(app.h.locs.axes(iHist),1,1,'Visible','off','BarWidth',1);
                app.GO.locs(3,iHist) = line(app.h.locs.axes(iHist),[0 0 NaN 1 1], [0 1 NaN 0 1],'Visible','off');
            end

            % Set their appearance.
            set(app.GO.locs(1,:),{'FaceColor','EdgeColor'},{[0.9 0.9 0.9],[0.5 0.5 0.5]});
            set(app.GO.locs(2,:),{'FaceColor'},num2cell(app.c.histcols,2));
            set(app.GO.locs(3,:),{'Color','LineWidth','LineStyle'},{'k',1.5,'--'});

            % Disable plot interactivity and hide toolbars.
            arrayfun(@disableDefaultInteractivity,[app.h.locs.axes]);
            arrayfun(@(x) set(x,'Visible','off'),[app.h.locs.axes.Toolbar]);

            % Constrain the axes to their inner position to fix the position of the y-axis.
            set(app.h.locs.axes,'PositionConstraint','innerposition');

            %% Tracking tab (MSD plots)

            % Get the axes handles.
            app.h.tracks.axesMSD = findobj(app.TracksMSDanalysisPanel.Children,'-depth',0,'Type','axes');

            % Get the handles to the edit fields in the track building parameters panel and the track fitting parameters
            % panel.
            app.h.tracks.paramsBuild = findobj(app.TracksBuildparamPanel.Children,'-depth',0, ...
                'Type','uinumericeditfield');
            app.h.tracks.paramsFit = findobj(app.TracksFitparamPanel.Children,'-depth',0, ...
                {'Type','uinumericeditfield'}, '-or', {'Type','uieditfield'});

            % Put in initial values from the parameter structure.
            app.h.tracks.paramsBuild(1).Value = app.S.tracking.maxLinkDistance;
            app.h.tracks.paramsBuild(2).Value = app.S.tracking.maxGapClose;
            app.h.tracks.paramsFit(1).Value = app.S.MSDanalysis.frameRate;
            app.h.tracks.paramsFit(2).Value = app.S.MSDanalysis.minTrackLength;
            app.h.tracks.paramsFit(3).Value = app.S.MSDanalysis.adjR2;
            linFitPoints = app.S.MSDanalysis.linFitPoints;
            if isscalar(linFitPoints)
                app.h.tracks.paramsFit(4).Value = int2str(linFitPoints);
            else
                app.h.tracks.paramsFit(4).Value = sprintf('%u-%u', linFitPoints(1), linFitPoints(2));
            end

            % Initialize the graphic objects array for the MSD analysis plots.
            app.GO.tracks = gobjects(10,1);

            % Set all MSD axes to accept multiple elements.
            set(app.h.tracks.axesMSD,'NextPlot','add');

            % Set the axes to initial ranges.
            app.h.tracks.axesMSD(1).XLim = [0, 1];
            app.h.tracks.axesMSD(1).YLim = [0, 0.1];
            app.h.tracks.axesMSD(1).YLimMode = 'auto';
            app.h.tracks.axesMSD(2).XLim = [-5, 1];
            app.h.tracks.axesMSD(2).YLim = [0, 100];
            app.h.tracks.axesMSD(2).YLimMode = 'auto';

            % Set the y axes format.
            app.h.tracks.axesMSD(1).YAxis.TickLabelFormat = '%3.1f';
            app.h.tracks.axesMSD(2).YAxis.TickLabelFormat = '%.0f';

            % Create placeholder objects.
            for iPlot = 1:3
                app.GO.tracks(iPlot) = line(app.TracksMeanMSDaxes,[0 1],[0 1],'Visible','off');
            end
            app.GO.tracks(4) = bar(app.TracksDdistAxes,1,1,'Visible','off','BarWidth',1);
            for iPlot = 5:9
                app.GO.tracks(iPlot) = line(app.TracksDdistAxes,[0 1],[0 1],'Visible','off');
            end
            app.GO.tracks(10) = text(app.TracksMeanMSDaxes,1,1,'');

            % Set their appearance.
            set(app.GO.tracks([1 2 3 5 6 7 8 9]),{'Color','LineWidth','LineStyle'},...
                {app.c.UT{'darkBlue',:},1.5,'-'; app.c.UT{'carmine',:},2,'-';...
                app.c.UT{'carmine',:},1,'--'; app.c.UT{'carmine',:},1.5,'-';...
                app.c.UT{'charcoal',:},1,'-'; app.c.UT{'carmine',:},1.5,'-';...
                app.c.UT{'carmine',:},1.5,'-';'k',1.5,'--'});
            app.GO.tracks(4).FaceColor = app.c.UT{'blue',:};
            set(app.GO.tracks(10),{'Units','FontSize','Color','String','Position'},...
                {'normalized',14,[0.5 0.5 0.5],{'Start MSD analysis','to plot data'}, [0.1 0.8]});

            % The x data for the MSD histogram and its fits are fixed. Change them here if needed.
            leftEdge = -5; rightEdge = 1; binWidth = 0.2;
            app.h.tracks.axesMSD(2).XLim = [leftEdge, rightEdge];
            xDataCourse = leftEdge+0.5*binWidth:binWidth:rightEdge-0.5*binWidth;
            xDataFine = linspace(leftEdge, rightEdge, 5000);
            set(app.GO.tracks(4), 'XData', xDataCourse, 'YData', ones(1,numel(xDataCourse)));
            for iPlot = 5:8
                set(app.GO.tracks(iPlot), 'XData', xDataFine, 'YData', ones(1,numel(xDataFine)));
            end

            % Set the immobile/mobile split value and move the line to the value stored in the parameter structure S.
            app.TracksDdistSplitEditField.Value = app.S.MSDanalysis.splitValue;
            app.GO.tracks(9).XData = [app.S.MSDanalysis.splitValue, app.S.MSDanalysis.splitValue];

            % Hide the 'split value' edit field initially.
            app.TracksDdistSplitEditFieldLabel.Visible = "off";
            app.TracksDdistSplitEditField.Visible = "off";

            % Disable plot interactivity and hide toolbars.
            arrayfun(@disableDefaultInteractivity,[app.h.tracks.axesMSD]);
            arrayfun(@(x) set(x,'Visible','off'),[app.h.tracks.axesMSD.Toolbar]);

            %% Tracking tab (visualization)

            % Get the axes handles.
            app.h.tracksVis.axes = findobj(app.TracksVisPanel.Children,'-depth',0,'Type','axes');

            % Inverse the y-axes to comply with the normal image format (first pixel is top left), hide the axes and set
            % a black colormap.
            set(app.h.tracksVis.axes,'YDir','reverse','XColor','none','YColor','none','Colormap',[0,0,0]);

            % Initialize graphic objects for the tracks visualization.
            app.GO.tracksVis = gobjects(15,1);
            app.GO.tracksVis(1) = text(app.TracksVisNormalAxes,1,1,'');
            app.GO.tracksVis(2) = image(app.TracksVisNormalAxes,'CData',zeros(100,100,3),"Visible","off");
            app.GO.tracksVis(3) = image(app.TracksVisZoomAxes,'CData',zeros(100,100,3),"Visible","off");
            app.GO.tracksVis(4) = patch(app.TracksVisNormalAxes,0,0,0,'Visible','off');
            app.GO.tracksVis(5) = patch(app.TracksVisZoomAxes,0,0,0,'Visible','off');
            app.GO.tracksVis(6) = image(app.TracksVisNormalAxes,'CData',zeros(100,100,3),"Visible","off");
            app.GO.tracksVis(7) = image(app.TracksVisZoomAxes,'CData',zeros(100,100,3),"Visible","off");
            app.GO.tracksVis(8) = rectangle(app.TracksVisNormalAxes,'Position',[0 0 1 1],'Visible','off');
            app.GO.tracksVis(9) = rectangle(app.TracksVisZoomAxes,'Position',[0 0 1 1],'Visible','off');
            app.GO.tracksVis(10) = text(app.TracksVisNormalAxes,1,1,'','Visible','off');
            app.GO.tracksVis(11) = text(app.TracksVisZoomAxes,1,1,'','Visible','off');
            app.GO.tracksVis(12) = rectangle(app.TracksVisNormalAxes,'Position',[0 0 1 1],'Visible','off');
            app.GO.tracksVis(13) = colorbar(app.TracksVisNormalAxes,'Ticks',[],'Location','south','Visible','off',...
                'Units','normalized','Position',[0.218, 0.228, 0.325, 0.03]);
            app.GO.tracksVis(14) = app.TracksVisLowerLimitEditField;
            app.GO.tracksVis(15) = app.TracksVisLowerLimitEditFieldLabel;
            app.GO.tracksVis(16) = app.TracksVisUpperLimitEditField;
            app.GO.tracksVis(17) = app.TracksVisUpperLimitEditFieldLabel;

            % Create functions that will trigger when the axes limits are changed (important for the size, units and
            % correct positioning of the scale bar).
            app.TracksVisZoomAxes.XAxis.LimitsChangedFcn = @(~,~)TracksVisAxesLimitsChangedCallback(app);
            app.TracksVisZoomAxes.YAxis.LimitsChangedFcn = @(~,~)TracksVisAxesLimitsChangedCallback(app);

            % Format the graphic objects according to the currently set default values.
            set(app.GO.tracksVis(1),{'Units','FontSize','Color','String','Position'},...
                {'normalized',14,[0.5 0.5 0.5],{'Choose single file','for tracks visualization'},[0.1 0.9]});
            set(app.GO.tracksVis(4),'FaceColor','none','EdgeColor','flat',...
                'LineWidth',app.TracksVisLineWidthEditField.Value/3);
            set(app.GO.tracksVis(5),'FaceColor','none','EdgeColor','flat',...
                'LineWidth',app.TracksVisLineWidthEditField.Value);
            set(app.GO.tracksVis(8:9),'FaceColor','w','EdgeColor','none');
            set(app.GO.tracksVis(10:11),'Color','w','FontUnits','normalized','FontSize',0.06,...
                'HorizontalAlignment','center','VerticalAlignment','baseline');
            set(app.GO.tracksVis(12),'EdgeColor','w','LineWidth',1);

            % Hide the colorbar limit fields (the initial 'random' color scheme does not need them).
            set(app.GO.tracksVis(14:17), 'Visible', "off");

            % Set the starting values for the tracks visualization parameters. In this startup function, they will all
            % be set for VisT(2) and then copied to VisT(1). Later, the old parameters can be accessed with VisT(1) and
            % the changed ones with VisT(2).
            app.VisT(2).colors = app.c.CB;
            app.VisT(2).isSingleFileSelected = false;
            app.VisT(2).trackArray = cell.empty;
            app.VisT(2).axesLimits = [0, 1, 0, 1];

            % Hide toolbars for both plots, disable plot interactivity for the left plot, but allow some plot
            % interactions for the right (magnified) track visualization plot.
            disableDefaultInteractivity(app.h.tracksVis.axes(1));
            app.h.tracksVis.axes(1).Toolbar.Visible = "off";
            app.h.tracksVis.axes(2).Toolbar.Visible = "off";
            app.h.tracksVis.axes(2).Interactions = [zoomInteraction, panInteraction];
            enableDefaultInteractivity(app.h.tracksVis.axes(2));

            %% Cluster Analysis tab

            % Get handles for all edit fields in the parameter estimation panel.
            app.h.cluster.paramsEst = findobj(app.ClusterEstPanel.Children,'-depth',0,'Type','uinumericeditfield');

            % Get handles for the edit fields in the cluster ID panel.
            app.h.cluster.paramsID = findobj(app.ClusterIDpanel.Children,'-depth',0, 'Type','uinumericeditfield');

            % Populate the color drop down menus for the clusters and define default values.
            [app.cPlotBoundariesColorDropDown.ItemsData, app.cPlotAreasColorDropDown.ItemsData] = ...
                deal([num2cell(app.c.CB.all,2);'none']);
            [app.cPlotBoundariesColorDropDown.Items, app.cPlotAreasColorDropDown.Items] = ...
                deal([app.c.CB.Properties.RowNames;'hidden']);
            % Blue boundaries.
            app.cPlotBoundariesColorDropDown.Value = app.cPlotBoundariesColorDropDown.ItemsData{4};
            % Green areas.
            app.cPlotAreasColorDropDown.Value = app.cPlotAreasColorDropDown.ItemsData{2};

            % Hide the axes toolbar and limit the interactivity to zoom and drag, and disable the interactivity (for
            % now).
            app.ClusterVisAxes.Toolbar.Visible = "off";
            app.ClusterVisAxes.Interactions = [zoomInteraction, panInteraction];
            disableDefaultInteractivity(app.ClusterVisAxes);

            % Create functions that will trigger when the axes limits are changed (important for the size, units and
            % correct positioning of the scale bar).
            app.ClusterVisAxes.XAxis.LimitsChangedFcn = @(~,~)ClusterAxesLimitsChangedCallback(app);
            app.ClusterVisAxes.YAxis.LimitsChangedFcn = @(~,~)ClusterAxesLimitsChangedCallback(app);

            % Set the default cluster option labels.
            app.segmentationMethodLabel.Text = "$\delta^{1}_{i} > \alpha \cdot \delta$";
            app.clusterIDparam1EditFieldLabel.Text = char(945);

            % Initialize graphic objects for the cluster visualization image.
            app.GO.cluster = gobjects(13,1);
            app.GO.cluster(1) = patch(app.ClusterVisAxes,0,0,0,'Visible','off');
            app.GO.cluster(2) = patch(app.ClusterVisAxes,0,0,0,'Visible','off');
            app.GO.cluster(3) = line(app.ClusterVisAxes,0,0,'Visible','off');
            app.GO.cluster(4) = patch(app.ClusterVisAxes,0,0,0,'Visible','off');
            app.GO.cluster(5) = line(app.ClusterVisAxes,0,0,'Visible','off');
            app.GO.cluster(6) = scatter(app.ClusterVisAxes,0,0,'Visible','off');
            app.GO.cluster(7) = rectangle(app.ClusterVisAxes,'Position',[0 0 1 1],'Visible','off');
            app.GO.cluster(8) = text(app.ClusterVisAxes,1,1,'','Visible','off');
            app.GO.cluster(9) = colorbar(app.ClusterVisAxes,'Ticks',[],'Location','south','Visible','off',...
                'Units','normalized','Position',[0.3985, 0.163, 0.5951, 0.0188]);
            app.GO.cluster(10) = app.gPlotLowerLimitEditField;
            app.GO.cluster(11) = app.gPlotLowerLimitEditFieldLabel;
            app.GO.cluster(12) = app.gPlotUpperLimitEditField;
            app.GO.cluster(13) = app.gPlotUpperLimitEditFieldLabel;

            % Format the graphic objects according to the currently set default values.
            app.ClusterVisAxes.YDir = 'reverse';
            set(app.GO.cluster(1),'FaceColor','none','EdgeColor','flat');
            set(app.GO.cluster(2),'FaceColor','flat','EdgeColor','none', 'FaceVertexCData',[1 1 1]);
            set(app.GO.cluster(4),'FaceColor','w','EdgeColor','none');
            set(app.GO.cluster(6),'MarkerEdgeColor','none', 'MarkerFaceColor',app.c.CB{'green',:}, ...
                'SizeData', app.gPlotPointSizeEditField.Value);
            set(app.GO.cluster(7),'FaceColor','w','EdgeColor','none');
            set(app.GO.cluster(8),'Color','w','FontUnits','normalized','FontSize',0.08, ...
                'HorizontalAlignment','center','VerticalAlignment','baseline');

            % Disable the toolbar and user interaction of the parameter estimation plot.
            app.ClusterEstAxes.Toolbar.Visible = "off";
            disableDefaultInteractivity(app.ClusterEstAxes);
            
            % Set a black colormap.
            app.ClusterVisAxes.Colormap = [0 0 0];

            % Set the starting values for the cluster visualization parameters. In this startup function, they will all
            % be set for VisC(2) and then copied to VisC(1). Later, the old parameters can be accessed with VisC(1) and
            % the changed ones with VisC(2).
            app.VisC(2).colors = app.c.CB;
            app.VisC(2).pointList = zeros(2);
            app.VisC(2).centroidList = zeros(2);
            app.VisC(2).trackArray = cell.empty;
            app.VisC(2).voronoiPoints = [];
            app.VisC(2).DBSCANpoints = [];
            app.VisC(2).overlapGroupsNASTIC = [];
            app.VisC(2).clusterBoundaryArray = [];
            app.VisC(2).showClusterAlg = false;
            app.VisC(2).clusterAlg = 'none';
            app.VisC(2).axesLimits = [0, 1, 0, 1];

            %% Batch Analysis tab

            % Nothing to be set here.

            %% End of initialization

            % Disable all interactive panel contents to prevent premature user input without loaded data.
            enableUserInput(app,["locsAll","tracksAll","clusterAll"],0);
            enableButtons(app,"all",0);

            % Only enable the loading buttons for the SMLM files and the sCMOS file and the "open parameter window"
            % buttons in all tabs. They will always be enabled.
            enableButtons(app,["load SMLM files","load sCMOS noise file"], 1);
            enableButtons(app,"open parameter window",1);

            % Store the current button states.
            enableButtons(app,"storeRecall",0);

            % Allow the user to change tabs.
            app.tmp.blockTabChange = false;
            
            % Update status.
            app.LocsStatusLabel.Text = 'idle'; drawnow;
        end

        % Button pushed function: BatchOpenParameterAppButton, 
        % ...and 3 other components
        function LocsOpenParameterAppButtonPushed(app, event)
            % Handles button presses to open the parameter app in all tabs, i.e., the components:
            % app.LocsOpenParameterAppButton, app.TracksOpenParameterAppButton, app.ClusterOpenParameterAppButton and
            % app.BatchOpenParameterAppButton.

            if ~strcmp([app.LocsStatusLabel.Text,app.TracksStatusLabel.Text,app.ClusterStatusLabel.Text], ...
                    "idleidleidle")
                % Ignore parameter app opening requests during calculations (status must be 'idle' in all three tabs).
                return
            elseif isempty(app.ParameterApp) || ~isvalid(app.ParameterApp)
                % The parameter app has not been opened yet or was closed. (Re-)start the parameter app and transfer the
                % currently set parameter structure S.
                app.ParameterApp = chooseBatchParams(app, app.S, app.tmp.lastDirectory);
            else
                % If the parameter app is already open, just bring it back to the front.
                figure(app.ParameterApp.UIFigure)
            end
        end

        % Button pushed function: LoadSMLMfilesButton
        function LoadSMLMfilesButtonPushed(app, event)
            % Request file (list) from user.
            [filename, pathname] = uigetfile({'*.tif';'*.TIF'}, 'Select sptPALM file', ...
                app.tmp.lastDirectory, 'MultiSelect','on');

            % MATLAB app glitch: use this to return focus to the main window.
            figure(app.MainUIFigure);

            % Special handling of the input dialog results:
            if isequal(filename,0) % user selected Cancel or closed the window
                return
            elseif ~iscell(filename) % user selected only one file
                filename = {filename};
            end

            % Make a basic check if the files are readable.
            app.LocsStatusLabel.Text = 'Checking files ...'; drawnow;
            isReadable = false(size(filename));
            for iFile = 1:numel(isReadable)
                warning('off','imageio:tiffutils:libtiffWarning'); 
                try
                    t = Tiff(fullfile(pathname,filename{iFile}));
                    close(t);
                    isReadable(iFile) = true;
                    warning('on','imageio:tiffutils:libtiffWarning');
                catch
                    isReadable(iFile) = false;
                    warning('on','imageio:tiffutils:libtiffWarning');
                end
            end

            if ~all(isReadable)
                 % Display an error message if any file could not be read, specifying the corresponding files.
                 message = vertcat("The following files could not be read:", filename(~isReadable)');
                 uialert(app.MainUIFigure, message, 'Warning', 'Icon', 'warning');

                 % Remove the unreadable files from the original filelist.
                 filename = filename(isReadable);

                 % Exit the load function if no readable files are left.
                 if isempty(filename)
                     return
                 end
            end

            % Store the current path and build the full filename(s). They are saved as the ItemsData property in the
            % file list box.
            app.tmp.lastDirectory = string(pathname);
            fullFilenames = cellfun(@(x) strcat(pathname,x), filename, "UniformOutput", false);
            app.LocsFileListBox.ItemsData = fullFilenames;

            % Display only the filenames in the list box.
            [~,app.LocsFileListBox.Items] = cellfun(@fileparts, fullFilenames, "UniformOutput", false);

            % Set the pathname as a the tooltip for the listbox.
            app.LocsFileListBox.Tooltip = pathname;

            % Clear the Data structure and reinitialize it with the newly loaded files.
            app.Data = cell2struct(fullFilenames,'fullFilenames',1);

            % Automatically select all SMLM files and deselect the noise file.
            app.LocsFileListBox.Value = app.LocsFileListBox.ItemsData;
            app.LocsSelectAllFilesCheckBox.Value = 1;
            app.LocsNoiseFileListBox.Value = cell.empty;

            % Reset all tabs.
            resetAnalysisStep(app,"localization");

            % When the sCMOS varmap is present, also enable the localization parameters and the 'Localize' button.
            if ~isempty(app.LocsNoiseFileListBox.Items)
                enableUserInput(app,"locsParams",1);
                enableButtons(app,"Localize",1);
            end

            % Update the status line.
            app.LocsStatusLabel.Text='idle'; drawnow;
        end

        % Button pushed function: LoadsCMOSnoiseFileButton
        function LoadsCMOSnoiseFileButtonPushed(app, event)
            % Request noise file from user.
            [filename, pathname] = uigetfile({'*.tif';'*.TIF'}, 'Select SMLM noise file', app.tmp.lastDirectory);

            % MATLAB app glitch: use this to return focus to the main window.
            figure(app.MainUIFigure);

            % Special handling of the input dialog results:
            if isequal(filename,0) % user selected Cancel or closed the window
                return
            end

            % Make a basic check if the file is readable.
            warning('off','imageio:tiffutils:libtiffWarning');
            app.LocsStatusLabel.Text = 'Checking file ...'; drawnow;
            try
                t = Tiff(fullfile(pathname,filename));
                firstFrame = read(t);
                close(t);
                warning('on','imageio:tiffutils:libtiffWarning'); 
            catch
                % Display an error message and exit the load function.
                message = "The noise file could not be read.";
                uialert(app.MainUIFigure, message, 'Warning', 'Icon', 'warning');
                warning('on','imageio:tiffutils:libtiffWarning'); 
                return
            end

            % Disable all user input and buttons for all panels in all tabs.
            enableUserInput(app,["locsAll","tracksAll","clusterAll"],0);
            enableButtons(app,"storeRecall",0);
            enableButtons(app,"all",0);
            
            % Store the current path and build the full filename.
            app.tmp.lastDirectory = string(pathname);
            fullFilename = strcat(pathname, filename);

            % Automatically deselect all files in the list box.
            app.LocsFileListBox.Value = cell.empty;
            app.LocsNoiseFileListBox.Value = cell.empty;
            app.LocsSelectAllFilesCheckBox.Value = 0;

            % For EMCCD files, the first frame will only hold one single pixel.
            if numel(firstFrame) == 1
                varianceMap = 1;
                offset = 100;
            else
                % Calculate the variance map.
                app.LocsStatusLabel.Text = 'Calculating variance map ...'; drawnow
                [varianceMap, offset] = calculateVarianceMap(fullFilename,'useGPU',app.tmp.useGPU);
            end

            % Display only the filename in the list box (without the extension) and deselect it.
            app.LocsNoiseFileListBox.ItemsData = cellstr(fullFilename);
            [~, filename] = fileparts(fullFilename);
            app.LocsNoiseFileListBox.Items = cellstr(filename);
            app.LocsNoiseFileListBox.Value = cell.empty;

            % Set the pathname as a the tooltip for the listbox.
            app.LocsNoiseFileListBox.Tooltip = pathname;

            % Update the offset value in the GUI with the one from the calculated variance map (except for an empty
            % value that indicates that a precalculated variance map was loaded).
            if ~isempty(offset)
                app.LocsLocparamOffsetEditField.Value = offset;
                app.S.localization.offset = offset;
            end
            app.S.localization.varianceMap = varianceMap;

            % Update the parameter app.
            updateParameterApp(app);

            % Enable the file loading buttons.
            enableButtons(app,"storeRecall",1);
            enableButtons(app,["load SMLM files","load sCMOS noise file"],1);
            enableButtons(app,"open parameter window",1);

            % Reset all tabs.
            resetAnalysisStep(app,"localization");

            % If the file list is populated, also enable the localization parameters and the 'Localize' button.
            if ~isempty(app.LocsNoiseFileListBox.Items)
                enableUserInput(app,"locsParams",1);
                enableButtons(app,"Localize",1);
            end

            % Update the status line.
            app.LocsStatusLabel.Text='idle'; drawnow;
        end

        % Button pushed function: LocalizeButton
        function LocalizeButtonPushed(app, event)
            % First check if the SMLM files and the noise file all have the same dimension in x and y.
            errorMsg = checkInputErrors(app);

            % If an error occurs, display the error in a dialog box and return.
            if ~isempty(errorMsg)
                f = uifigure; uialert(f,errorMsg,'Validity check','Icon','error',...
                    'CloseFcn',@(src,event)closeDialogBox(app,src,event));
                uiwait(f)
                return
            end

            % Block all user input during the localization procedure (disabling tab changes).
            app.tmp.blockTabChange = true;
            enableUserInput(app,"locsAll",0);
            enableButtons(app,"locsAll",0);

            % Localization is always done for all files in the list box.
            fullFilenames = app.LocsFileListBox.ItemsData;
            app.LocsFileListBox.Value = fullFilenames;
            app.LocsSelectAllFilesCheckBox.Value = 1;
            app.LocsNoiseFileListBox.Value = cell.empty;

            % Completely clear the Data structure and reinitialize it with the files that are to be localized.
            app.Data = cell2struct(fullFilenames,'fullFilenames',1);

            % Prepare the parameter structures for the localization procedure.
            locParams = rmfield(app.S.localization,'limits');
            locParams.useGPU = app.tmp.useGPU;
            locParams = reshape(namedargs2cell(locParams),2,[]);

            % Loop through all files in the listbox.
            for iFile = 1:numel(fullFilenames)

                % Update the status line.
                [~,fname] = fileparts(fullFilenames{iFile});
                app.LocsStatusLabel.Text = sprintf('Processing file: %s ...',fname); drawnow;

                % Localization procedure of SMLM data.
                imageStack = readTIFFstack(fullFilenames{iFile});

                % Adapt the variance map for EMCCD files.
                if numel(app.S.localization.varianceMap) == 1
                    locParams{2} = zeros(size(imageStack,[1 2]),'single');
                end

                [app.Data(iFile).pointList, app.Data(iFile).outlierInfo] = localizeSMLMdata(imageStack, locParams{:});

                % Also calculate a maximum intensity projection of the image stack and store it as an RGB image.
                app.Data(iFile).MIP = repmat(mat2gray(max(imageStack,[],3)),[1 1 3]);

                % Store the dimensions as [width, height, number of frames].
                app.Data(iFile).stackDimensions = size(permute(imageStack,[2 1 3]));

                % Look for valid mask files and store the corresponding pixel masks and mask polygons.
                [app.Data(iFile).maskIm, app.Data(iFile).maskPolygon] = getMaskForApp(fullFilenames{iFile});       
            end

            % Enable the file listbox and reset to clear other tabs.
            app.LocsFileListBox.Enable = "on";
            resetAnalysisStep(app,"locLimits");

            % Enable tab changes and update the status line.
            app.tmp.blockTabChange = false;
            app.LocsStatusLabel.Text = 'idle';
        end

        % Button pushed function: FineTuneParametersButton
        function FineTuneParametersButtonPushed(app, event)
            % Get the ID of the currently selected file to get the number of frames.
            [~, ID] = intersect(app.LocsFileListBox.ItemsData, app.LocsFileListBox.Value, 'stable');
            stackDimensions = app.Data(ID).stackDimensions;

            % Close the parameter window app in case it is open.
            if ~isempty(app.ParameterApp) && isvalid(app.ParameterApp)
                close(app.ParameterApp.UIFigure)
            end
            
            % Start the fine-tuning app.
            app.FineTuneLocParamsApp = fineTuneLocParams(app, app.LocsFileListBox.Value{:}, ...
                app.LocsNoiseFileListBox.ItemsData{:}, stackDimensions);
        end

        % Button pushed function: TransferToTrackingButton
        function TransferToTrackingButtonPushed(app, event)
            % Get the IDs of the currently selected files.
            [~, IDs] = intersect(app.LocsFileListBox.ItemsData, app.LocsFileListBox.Value, 'stable');

            % Filter and store the data of the selected files for the tracking tab.
            locLimits = table2array(app.S.localization.limits)';
           
            for iFile = IDs'
                pointList = app.Data(iFile).pointList;
                app.Data(iFile).pointListFiltered = pointList(~any(pointList(:,[4 5 7]) < locLimits(1,:) |...
                pointList(:,[4 5 7]) > locLimits(2,:),2),:);
                app.Data(iFile).outlierInfo(6) = size(app.Data(iFile).pointListFiltered, 1);
            end
            
            % Transfer the selected file names to the tracking tab filelist box.
            app.TracksFileListBox.ItemsData = app.LocsFileListBox.ItemsData(IDs);
            app.TracksFileListBox.Items = app.LocsFileListBox.Items(IDs);
            app.TracksFileListBox.Value = app.TracksFileListBox.ItemsData;
            app.TracksSelectAllFilesCheckBox.Value = true;
            
            % Reset all subsequent data. 
            resetAnalysisStep(app,"tracking");

            % Switch to the Tracking Tab.
            app.TabGroup.SelectedTab = app.TrackingTab;
            enableButtons(app,"open parameter window",1);
        end

        % Value changed function: LocsSelectAllFilesCheckBox
        function LocsSelectAllFilesCheckBoxValueChanged(app, event)
            % Depending on the state of the checkbox, select all or none of the files.
            if app.LocsSelectAllFilesCheckBox.Value == 1
                app.LocsFileListBox.Value = app.LocsFileListBox.ItemsData;
            else
                app.LocsFileListBox.Value = cell.empty;
            end

            % Process the current file selection.
            processLocsFileSelection(app);   
        end

        % Value changed function: LocsFileListBox
        function LocsFileListBoxValueChanged(app, event)
            % Process the current file selection.
            processLocsFileSelection(app);
        end

        % Value changed function: LocsLocparamConversionEditField, 
        % ...and 5 other components
        function LocsParametersChanged(app, event)
            % Get the name and value of the changed edit field in the localization parameter panel via its tag.
            paramName = erase(event.Source.Tag, 'localization ');
            paramValue = event.Value;

            if strcmp(paramName, 'PSFROI') && ~mod(paramValue,2)
                % For the PSF ROI, only odd values are allowed.
                paramValue = paramValue + 1;
                event.Source.Value = paramValue;
            end

            % Store the changed value in the parameter structure and update the parameter app.          
            app.S.localization.(paramName) = paramValue;
            updateParameterApp(app);

            % The localization procedure has to be repeated using the new parameter.
            resetAnalysisStep(app, "localization"); 
        end

        % Button pushed function: BuildTracksButton
        function BuildTracksButtonPushed(app, event)
            % This starts the tracking procedure. Block all user input during the tracking procedure (disabling tab
            % changes).
            app.tmp.blockTabChange = true;
            enableUserInput(app,"tracksAll",0);
            enableButtons(app,"tracksAll",0);
            
            % Clear the MSD data.
            if isfield(app.Data,"MSD")
                app.Data = rmfield(app.Data,"MSD");
            end

            % Tracking is always applied to all files in the list.
            app.TracksFileListBox.Value = app.TracksFileListBox.ItemsData;
            app.TracksSelectAllFilesCheckBox.Value = 1;

            % As an incomplete set may have been transferred from the Localization tab, the IDs of the visible files
            % must be extracted by comparing them with those in the Localization tab.
            [~, IDs] = intersect(app.LocsFileListBox.ItemsData, app.TracksFileListBox.ItemsData, 'stable');

            % Prepare the parameter structure for the tracking procedure and extract the limits.
            trackParams = rmfield(app.S.tracking, {'limits','maskPolygons'});
            trackParams = reshape(namedargs2cell(trackParams ),2,[]);
            trackLimits = table2array(app.S.tracking.limits)';
           
            % Loop through all files in the listbox.
            for iFile = IDs'
                % Update the status line.
                [~,fname] = fileparts(app.Data(iFile).fullFilenames);
                app.TracksStatusLabel.Text = sprintf('Processing file: %s ...',fname); drawnow;

                % Start the tracking procedure.
                pointList = app.Data(iFile).pointListFiltered;
                tmpTracks = assembleTracks(pointList, trackParams{:}, 'exportAllInfo', true);
               
                % Apply the chosen limits and store the filtered track array.
                % Calculate the number of localizations, the duration, the displacement and range for each track.
                trackFeatures = [...,
                    cellfun('size', tmpTracks, 1), ...
                    (cellfun(@(x) range(x(:,1)),tmpTracks) + 1) / app.S.MSDanalysis.frameRate, ...
                    cellfun(@(x) norm(diff(x([1,end],2:3))), tmpTracks), ...
                    cellfun(@(x) hypot(range(x(:,2)), range(x(:,3))), tmpTracks)];

                % Remove invalid tracks.
                isValid = all(trackFeatures >= trackLimits(1,:) & trackFeatures <= trackLimits(2,:), 2);
                app.Data(iFile).trackArray = tmpTracks(isValid);
                app.Data(iFile).trackArrayMasked = tmpTracks(isValid);

                % When a mask file is available, this process must be repeated with the masked set of localizations to
                % get the actual dataset that will be passed along to the mobility and cluster analysis (the full set of
                % tracks is then only needed for the visualization option 'show unmasked tracks').
                if ~isempty(app.Data(iFile).maskPolygon)
                    maskPolygon = app.Data(iFile).maskPolygon;
                    maskPolygon.Vertices = maskPolygon.Vertices * app.S.localization.pixelSize;
                    TFin = isinterior(maskPolygon, pointList(:,2:3));
                    pointList = pointList(TFin,:);
                    tmpTracks = assembleTracks(pointList, trackParams{:}, 'exportAllInfo', true);
                    trackFeatures = [...,
                        cellfun('size', tmpTracks, 1), ...
                        (cellfun(@(x) range(x(:,1)),tmpTracks) + 1) / app.S.MSDanalysis.frameRate, ...
                        cellfun(@(x) norm(diff(x([1,end],2:3))), tmpTracks), ...
                        cellfun(@(x) hypot(range(x(:,2)), range(x(:,3))), tmpTracks)];
                    isValid = all(trackFeatures >= trackLimits(1,:) & trackFeatures <= trackLimits(2,:), 2);
                    app.Data(iFile).trackArrayMasked = tmpTracks(isValid);
                end
            end

            % Enable the file list box.
            enableUserInput(app,"tracksFiles",1);

            % Reset the data for subsequent analysis steps.
            resetAnalysisStep(app,"MSDanalysis");

            % Update the status line.
            app.TracksStatusLabel.Text = 'idle'; drawnow
           
            % Unblock tab changing.
            app.tmp.blockTabChange = false;
        end

        % Button pushed function: MSDanalysisButton
        function MSDanalysisButtonPushed(app, event)
        % MSD analysis must be performed once after building the tracks and everytime a parameter is changed in the
        % track fitting parameters panel. Some cluster analysis visualization options depend on MSD data, so clear all
        % previous data and disable all user input in the Cluster Analysis tab. For the Tracking tab, user input is only
        % temporarily blocked.
        enableUserInput(app,["tracksAll","clusterAll"],0);
        enableButtons(app,"storeRecall",0);
        enableButtons(app,"all",0);
        app.tmp.blockTabChange = true;

        % Select all files, check the checkbox (MSD analysis will always be performed on all files in the file list box,
        % regardless of the previous selection).
        app.TracksSelectAllFilesCheckBox.Value = 1;
        app.TracksFileListBox.Value = app.TracksFileListBox.ItemsData;

        % Clear all MSD and dependent data %%% cluster etc. {'MSD','cluster'}
        if isfield(app.Data,'MSD')
            app.Data = rmfield(app.Data,'MSD');
        end

        % Calculate the MSD for all files in the listbox.
        calculateMSDdata(app);        
        
        % From here on, the temporary plot data in the Tracking tab will be generated depending on the selected files
        % and user-specified fitting parameters. Individual analysis data will only be calculated and stored for each
        % file if the user transfers the data to the Cluster Analysis tab.
        
        % trigger the clearance of plots etc.
        app.GO.tracksVis(1).String = {'Choose single file','for tracks visualization'};
        app.GO.tracksVis(2).Visible = 'off';
        app.GO.tracksVis(3).Visible = 'off';
        app.GO.tracksVis(10).Visible = 'off';
        
        % Enable the file listbox and use >processTracksFileSelection< to set which user input will be allowed for the
        % Tracking tab (in addition to calculating the MSD plots etc.).
        enableButtons(app,"storeRecall",1);
        enableUserInput(app,"tracksFiles",1);
        processTracksFileSelection(app);

        % Unblock tab changes.
        app.tmp.blockTabChange = false;
        enableButtons(app,"open parameter window",1);
        end

        % Button pushed function: TransferToClusterAnalyisButton
        function TransferToClusterAnalyisButtonPushed(app, event)
            % Get the IDs of the currently selected files.
            [~, IDs] = intersect(app.LocsFileListBox.ItemsData, app.TracksFileListBox.Value, 'stable');

            for iFile = IDs'
                % Calculate and store the centroid of each track in the filtered array.
                app.Data(iFile).centroidList = convertTracksToCentroids(app.Data(iFile).trackArrayMasked);
            end
  
            % Transfer the selected file names to the Cluster Analysis tab file listbox.
            app.ClusterFileListBox.ItemsData = app.LocsFileListBox.ItemsData(IDs);
            app.ClusterFileListBox.Items = app.LocsFileListBox.Items(IDs);
          
            % Enable the file listbox and select the first file.
            enableUserInput(app,"clusterFiles",1);
            app.ClusterFileListBox.Value = app.ClusterFileListBox.ItemsData(1);

            % Reset the Cluster Analysis tab, then switch to it.
            resetAnalysisStep(app,"cluster");
            app.TabGroup.SelectedTab = app.ClusterAnalysisTab;
        end

        % Value changed function: TracksSelectAllFilesCheckBox
        function TracksSelectAllFilesCheckBoxValueChanged(app, event)
            % Depending on the state of the checkbox, select all or none of the files.
            if app.TracksSelectAllFilesCheckBox.Value == 1
                app.TracksFileListBox.Value = app.TracksFileListBox.ItemsData;
            else
                app.TracksFileListBox.Value = cell.empty;
            end

            % Process the current file selection.
            processTracksFileSelection(app);   
        end

        % Value changed function: TracksFileListBox
        function TracksFileListBoxValueChanged(app, event)
             % Process the current file selection.
             processTracksFileSelection(app);
        end

        % Value changed function: TracksMaxGapClosingEditField, 
        % ...and 1 other component
        function TracksBuildingParametersChanged(app, event)
            % Handles the components app.TracksMaxLinkDistEditField and app.TracksMaxGapClosingEditField. Get the name
            % and value of the changed edit field in the track building parameters panel via its tag.
            paramName = erase(event.Source.Tag, 'tracking ');
            paramValue = event.Value;

            % Store the changed value in the parameter structure and update the parameter app.          
            app.S.tracking.(paramName) = paramValue;
            updateParameterApp(app);

            % The tracking procedure has to be repeated using the new parameter.
            app.TracksFileListBox.Enable = "off";
            app.TracksSelectAllFilesCheckBox.Value = 1;
            app.TracksFileListBox.Value = app.TracksFileListBox.ItemsData;
            resetAnalysisStep(app,"tracking");
        end

        % Value changed function: TracksAdjR2editField, 
        % ...and 3 other components
        function TracksFittingParametersChanged(app, event)
            % Handles the components: app.TracksFrameRateEditField, app.TracksLinFitPointsEditField,
            % app.TracksAdjR2editField, app.TracksMinTrackLengthEditField.
            % Get the name and value of the changed edit field in the track fitting parameters panel via its tag.
            paramName = erase(event.Source.Tag, 'MSDanalysis ');
            paramValue = event.Value;

            if strcmp(paramName, 'linFitPoints')
                % This value is either a single number or a range of numbers that are formatted like "2 4". This text
                % edit field value must be parsed into a number or vector to be further processed. Use >unique< to sort
                % the values and remove duplicates.
                paramValue = unique(str2double(extract(paramValue, digitsPattern))');

                if any(paramValue ~= round(paramValue)) || ...
                        (isscalar(paramValue) && (paramValue < 3 || paramValue > 10)) || length(paramValue) > 2 || ...
                        (length(paramValue) == 2 && (paramValue(1) > 2 || paramValue(2) > 10 || diff(paramValue) < 2))
                    % This sorts out all invalid inputs. Single values must be between 3 and 10, no more than two values
                    % are allowed, and for two values, the first one must be 1 or 2, the second not more than 10, and
                    % must contain at least 3 points. Ignore the user input and replace it with the previous value.
                    % This does not change anything, so return.
                    app.TracksLinFitPointsEditField.Value = event.PreviousValue;
                    return

                elseif paramValue(1) == 1
                    % Expressions like "1-4" are abbreviated to "4".
                    paramValue = paramValue(2);
                    app.TracksLinFitPointsEditField.Value = int2str(paramValue);

                elseif length(paramValue) == 2
                    % Re-enter the string into the edit field (in case the order was switched).
                    app.TracksLinFitPointsEditField.Value = sprintf('%u-%u', paramValue(1), paramValue(2));
                end

                if strcmp(app.TracksLinFitPointsEditField.Value, event.PreviousValue)
                    return
                end
            end

            % Store the changed value in the parameter structure and update the parameter app.
            app.S.MSDanalysis.(paramName) = paramValue;
            updateParameterApp(app);

            % The MSD analysis has to be repeated using the new parameter.
            resetAnalysisStep(app,"MSDanalysis");
        end

        % Value changed function: TracksMeanMSDautoScaleCheckBox
        function TracksMeanMSDautoScaleCheckBoxValueChanged(app, event)
            scaleMSDplots(app);            
        end

        % Value changed function: Tracks2PopCheckBox
        function Tracks2PopCheckBoxValueChanged(app, event)
            show1or2pops(app);
        end

        % Value changed function: TracksDdistSplitEditField
        function TracksDdistSplitEditFieldValueChanged(app, event)
            changeMSDsplitValue(app, 'internal');
        end

        % Close request function: MainUIFigure
        function MainUIFigureCloseRequest(app, event)
            % if this app is closed, also close the called apps.
            delete(app.ParameterApp)
            delete(app.MaskingApp)
            delete(app.ShowClusterDataApp)
            delete(app)
        end

        % Button pushed function: TracksVisEditMaskButton
        function TracksVisEditMaskButtonPushed(app, event)
            % Get the ID of the currently selected file.
            [~, ID] = intersect(app.LocsFileListBox.ItemsData, app.TracksFileListBox.Value, 'stable');

            % Start the external app.
            app.MaskingApp = sptPALMmaskEditor(app, app.Data(ID).MIP, app.LocsFileListBox.ItemsData{ID});
        end

        % Button pushed function: TracksVisSaveButton
        function TracksVisSaveButtonPushed(app, event)
            % Saves the currently displayed tracking images into the same folder as the file they were calculated from.
                       
            % Get the file name.
            [~, ID] = intersect(app.LocsFileListBox.ItemsData, app.TracksFileListBox.Value, 'stable');
            fullFilename = app.Data(ID).fullFilenames;
            [pathname, filename] = fileparts(fullFilename);

            % Update the status.
            app.TracksStatusLabel.Text = sprintf('Saving images for file: %s ...',filename); drawnow;
            
            % Prepare the save filename.
            fullSaveFilename = fullfile(pathname, [filename,'_tracksVis.pdf']);
            
            % Save the plots as .pdf pages.
            enableButtons(app,"save image",0);
            saveAxesAsPDF(app, app.TracksVisNormalAxes, fullSaveFilename, false);
            saveAxesAsPDF(app, app.TracksVisZoomAxes, fullSaveFilename, true);

            % Create the colorbar label from the currently set color scheme and, for the unit, the ValueDisplayFormat
            % property of the upper limit edit field (everything after the first space character).
            unitStringArray = split(app.TracksVisUpperLimitEditField.ValueDisplayFormat);
            colorbarText = app.TracksVisColorSchemeDropDown.Value + " (" + join(unitStringArray(2:end)) + ")";
            
            % Save the colorbar as an additional page in the .pdf file.
            saveColorbarAsPDF(app, app.TracksVisNormalAxes, colorbarText, fullSaveFilename, true);
            
            % Update the status.
            app.TracksStatusLabel.Text = 'idle'; drawnow;
            enableButtons(app,"save image",1);
        end

        % Value changed function: TracksVisColorMapDropDown, 
        % ...and 8 other components
        function TracksVisUnderlayTIFFCheckBoxValueChanged(app, event)
            % Handles track visualization options chosen by the user of the following components:
            % app.TracksVisUnderlayTIFFcheckBox, app.TracksVisLineWidthEditField, app.TracksVisShowUnmaskedCheckBox,
            % app.TracksVisScaleBarDropDown, app.TracksVisWhiteBackgroundCheckBox, app.TracksVisLowerLimitEditField,
            % app.TracksVisUpperLimitEditField, app.TracksVisColorMapDropDown, app.TracksVisColorSchemeDropDown
            
            if strcmp(event.Source.Tag,'color scheme (tracks)')
                enactTracksColorSchemeChange(app);
            end
            getTracksVisOptions(app);
            app.VisT = updateAppGOsTracksVis(app.GO.tracksVis, app.h.tracksVis.axes, app.VisT);
        end

        % Value changed function: ClusterFileListBox
        function ClusterFileListBoxValueChanged(app, event)
           processClusterFileSelection(app);
        end

        % Value changed function: ClusterIDdropDown
        function ClusterIDdropDownValueChanged(app, event)
            % Currently, the segNASTIC cluster algorithm cannot be selected, but is otherwise fully implemented. To add
            % it back as an additional algorithm, just add 'segNASTIC (tracks)' at the end of this drop down menu's
            % Items list (and delete this comment).
            
            % The user chose a different cluster algorithm in this app. Store this in the parameter structure S.
            clusterAlg = app.ClusterIDdropDown.Value;
            if contains(clusterAlg,"tracks")
                app.S.cluster.input = "tracks";
            elseif contains(clusterAlg,"centroids")
                app.S.cluster.input = "track centroids";
            else
                app.S.cluster.input = "localizations";
            end

            if contains(clusterAlg,"Voronoi")
                if contains(clusterAlg,"density")
                    app.S.cluster.Voronoi.method = "density";
                else
                    app.S.cluster.Voronoi.method = "area";
                end
            end

            app.S.cluster.algorithm = extractBefore(clusterAlg,' ');

            % Reset the Cluster Analysis tab, which will also trigger algorithm dependent changes in the GUI elements.
            resetAnalysisStep(app,"cluster");

            % Update the parameter app.
            updateParameterApp(app);
        end

        % Value changed function: clusterIDminNeditField, 
        % ...and 2 other components
        function clusterIDparametersChanged(app, event)
            % Handles edit field changes in the cluster ID panel: app.clusterIDminNeditField,
            % app.clusterIDparam1editField, app.clusterIDparam2editField.
            % The user changed values for the currently active cluster algorithm. These values are written to the
            % parameter structure S.
            selectNewClusterAlgorithm(app,'internal');

            % Update the parameter app.
            updateParameterApp(app);
            
            % Prime the 'run' button to signal that the cluster data must now be updated.
            enableButtons(app,"run",2);
        end

        % Button pushed function: clusterIDrunButton
        function clusterIDrunButtonPushed(app, event)
            % This starts the cluster analysis. Clear (%%% to do) all present data and disable all user input for
            % the Cluster Analysis tab. Also disable all buttons.
            enableUserInput(app,"clusterAll",0);
            enableButtons(app,"storeRecall",0);
            enableButtons(app,"all",0);
            app.tmp.blockTabChange = true;
                 
            % Copy the data that is the input for the chosen algorithm.
            clusterAlg = app.ClusterIDdropDown.Value;
            if contains(clusterAlg, "NASTIC")
                inputData = app.VisC(2).trackArray;
            elseif contains(clusterAlg, "localizations")
                inputData = app.VisC(2).pointList;
            elseif contains(clusterAlg, "centroids")
                inputData = app.VisC(2).centroidList;
            else
                % Empty.
            end

            % Delete the old data, forcing an update of the visualization (handles the case that the "run" button was
            % pushed again using the same data and parameters).
            app.VisC(1).clusterBoundaryArray = [];

            % Update the status.
            app.ClusterStatusLabel.Text = 'Identifying clusters ...'; drawnow

            % Process the data with the currently set cluster algorithm.
            if contains(clusterAlg, 'Voronoi')

                % Delete old data (to correct behavior when "run" is pushed with same parameters).
                app.VisC(1).voronoiPoints = [];

                % Create the Voronoi diagram and optionally trim the Voronoi cells according to the parameter app S.
                if app.S.cluster.Voronoi.trimEdges
                    [DT, voronoiVertices, voronoiCells] = getTrimmedVoronoi(inputData, ...
                    'type', app.S.cluster.Voronoi.trimMethod, 'boundary', app.S.cluster.Voronoi.trimBoundary);
                else
                    [DT, voronoiVertices, voronoiCells] = getTrimmedVoronoi(inputData);
                end

                % Store the Voronoi seeds (trimming might have altered the original point list), the vertices and cells.
                app.VisC(2).voronoiPoints = DT.Points;
                app.VisC(2).voronoiVertices = voronoiVertices;
                app.VisC(2).voronoiCells = voronoiCells;

                % Calculate the area for all voronoi cells.
                voronoiAreaList = calculateVoronoiArea(voronoiVertices, voronoiCells);

                if contains(clusterAlg, 'density')
                    VoronoiMethod = "density";
                    % Calculate the normalized first rank density for the value list.
                    valueList = calculateNormFirstRankDensity(DT, voronoiAreaList);

                elseif contains(clusterAlg, 'area')
                    VoronoiMethod = "area";
                    % The area list will be used as the value list.
                    valueList = voronoiAreaList;
                end              

                % Group the Voronoi cells to clusters and store the value list.
                splitValue = app.clusterIDparam1editField.Value;
                [clusterData, boundaryVertexArray] = buildVoronoiClusters(DT, voronoiVertices, ...
                    voronoiCells, VoronoiMethod, valueList, splitValue);
                app.VisC(2).voronoiValueList = valueList;

            elseif contains(clusterAlg, 'DBSCAN')
                % Delete old data (to correct behavior when "run" is pushed with same parameters).
                app.VisC(1).DBSCANpoints = [];

                if app.clusterIDparam2editField.Editable == true
                    epsilon = app.clusterIDparam2editField.Value;
                else
                    epsilon = estimateDBSCANepsilon(inputData, app.clusterIDparam1editField.Value);
                end

                % Store the chosen input data, apply the DBSCAN algorithm, store the identified
                % clusters and the point classification lists.
                app.VisC(2).DBSCANpoints = inputData; 
                [clusterData, boundaryVertexArray, app.VisC(2).DBSCANclusterIndexList, app.VisC(2).DBSCANisCore] = ...
                buildDBSCANClusters(inputData, epsilon, app.clusterIDparam1editField.Value);  

            elseif contains(clusterAlg, 'NASTIC')                
                % Delete old data (to correct behavior when "run" is pushed with same parameters).
                app.VisC(1).overlapGroupsNASTIC = [];

                % Extract the relevant parameters for the NASTIC algorithms.
                radiusFactor = app.clusterIDparam1editField.Value;

                % Convert the tracks to axis-aligned bounding boxes and assign them to groups.
                AABB = convertTracksToAABB(inputData, radiusFactor);
                overlapGroups = groupAABBoverlaps(AABB, inf);

                % Remove groups with too few tracks and store the remaining overlap groups.
                overlapGroups(cellfun('size',overlapGroups, 2) < app.clusterIDminNeditField.Value) = [];
                app.VisC(2).overlapGroupsNASTIC = overlapGroups;

                % Discriminate between the two NASTIC types.
                if contains(clusterAlg, 'seg')
                    % The overlap threshold determines often one track segment has to overlap with
                    % segments of other tracks to be considered as part of a cluster.
                    overlapThreshold = app.clusterIDparam2editField.Value;
                    [clusterData, boundaryVertexArray] = ...
                        buildSegNASTICclusters(inputData, overlapGroups, overlapThreshold);
                else
                    [clusterData, boundaryVertexArray] = ...
                        buildNASTICclusters(inputData, overlapGroups);
                end

            else
                % empty.
            end

            % Remove all clusters with less points than specified.
            isLacking = clusterData(:,3) < app.clusterIDminNeditField.Value;
            clusterData(isLacking,:) = [];
            boundaryVertexArray(isLacking) = [];
         
            % Store the boundary array.
            app.VisC(2).clusterBoundaryArray = boundaryVertexArray;

            % Store the current cluster data and trigger the update of the table (if open).
            app.tmp.clusterData = clusterData;
            updateShowClusterDataApp(app);

            % As new cluster data has now been calculated, enable the cluster plot options, tick the checkbox and
            % update the plots accordingly.
            app.cPlotShowAlgCheckBox.Value = true;
            enableUserInput(app,"clusterParamsCluster",1)
            enactClusterAlgorithmChange(app);
            enactClusterColorSchemeChange(app);
            getClusterVisOptions(app);
            app.VisC = updateAppGOsCA(app.GO.cluster, app.ClusterVisAxes, app.VisC);

            % The displayed data is now actual, enable the 'run' button (in case it was 'primed' before) and also enable
            % the 'reset' and 'show data' button.
            enableButtons(app,"storeRecall",1);
            enableButtons(app, ["run","reset","show data","save image"], 1);
            enableButtons(app,"open parameter window",1);
            app.tmp.blockTabChange = false;

            % Enable all cluster user input, but retain the state of the estimation panel objects (disabled for NASTIC).
            enableUserInput(app,"clusterAll",1);
            enableUserInput(app,"clusterPlotsEst", ~contains(clusterAlg,"NASTIC"));
  
            % Update the status.
            app.ClusterStatusLabel.Text = 'idle'; drawnow
        end

        % Button pushed function: clusterIDresetButton
        function clusterIDresetButtonPushed(app, event)
             % Revert back to the default visualization of the base data of the currently selected file.
             resetAnalysisStep(app,"cluster");
        end

        % Button pushed function: ClusterShowDataButton
        function ClusterShowDataButtonPushed(app, event)
            if isempty(app.ShowClusterDataApp) || ~isvalid(app.ShowClusterDataApp)
                % When the table is not yet open or was closed, start the app, passing the current table data.
                app.ShowClusterDataApp = showClusterData(app, app.tmp.clusterData);
            else
                % Otherwise, update the data.
                updateShowClusterDataApp(app);
            end
        end

        % Button pushed function: ClusterSaveImageButton
        function ClusterSaveImageButtonPushed(app, event)
            % Saves the currently displayed cluster image into the same folder as the file it was calculated from.
            
            % Get the file name.
            [~, ID] = intersect(app.LocsFileListBox.ItemsData, app.ClusterFileListBox.Value, 'stable');
            fullFilename = app.Data(ID).fullFilenames;
            [pathname, filename] = fileparts(fullFilename);

            % Update the status.
            app.ClusterStatusLabel.Text = sprintf('Saving image for file: %s ...',filename); drawnow;
            
            % Prepare the save filename.
            fullSaveFilename = fullfile(pathname, [filename,'_clusterVis.pdf']);
            
            % Save the plot as pdf.
            enableButtons(app,"save image",0);
            saveAxesAsPDF(app, app.ClusterVisAxes, fullSaveFilename, false);

            % Create the colorbar label from the currently set color scheme and, for the unit, the ValueDisplayFormat
            % property of the upper limit edit field (everything after the first space character).
            unitStringArray = split(app.gPlotUpperLimitEditField.ValueDisplayFormat); 
            colorbarText = app.gPlotColorSchemeDropDown.Value + ...
                " (" + join(unitStringArray(2:end)) + ")";

            % Replace "log10" with a pdf-printable Version (if necessary).
            colorbarText = replace(colorbarText, char([0x2081, 0x2080]), "_{10}");
            saveColorbarAsPDF(app, app.ClusterVisAxes, colorbarText, fullSaveFilename, true);
            
            % Update the status.
            app.ClusterStatusLabel.Text = 'idle'; drawnow;
            enableButtons(app,"save image",1);
        end

        % Button pushed function: ClusterEstRunNsimulationsButton
        function ClusterEstRunNsimulationsButtonPushed(app, event)
            % Disable user input.
            enableButtons(app,"storeRecall",0);
            enableButtons(app,"all",0);
            
            % Clear the axes.
            cla(app.ClusterEstAxes);
            app.ClusterEstAxes.XLimSpec = 'tight';
            app.ClusterEstAxes.YLimSpec = 'tight';

            % Reset the estimated value to 0.
            app.ClusterEstParameterEditField.Value = 0;
            
            % Copy the data that is the input for the chosen algorithm.
            clusterAlg = app.ClusterIDdropDown.Value;
            if contains(clusterAlg, "NASTIC")
                inputData = app.VisC(2).trackArray;            
            elseif contains(clusterAlg, "localizations")
                inputData = app.VisC(2).pointList;
            elseif contains(clusterAlg, "centroids")
                inputData = app.VisC(2).centroidList;
            else
                % Empty.
            end

            if contains(clusterAlg, "Voronoi")
                % Update the status.
                app.ClusterStatusLabel.Text = sprintf('%u simulations left. Please wait ...', ...
                    app.ClusterEstNSimulationsEditField.Value); drawnow
                method = extractBetween(string(clusterAlg),"(",",");
                [splitValue, histDataSample, histDataMC] = estimateVoronoiThreshold(...
                    inputData, method, app.ClusterEstNSimulationsEditField.Value, ...
                    'updateHandle', app.ClusterStatusLabel);
                patch(app.ClusterEstAxes,'XData',[histDataMC(:,1); flipud(histDataMC(:,1))], ...
                    'YData',[histDataMC(:,4); flipud(histDataMC(:,3))], ...
                    'EdgeColor', 'none', 'FaceColor', [0.8 0.8 0.8]);
                line(app.ClusterEstAxes, histDataMC(:,1), histDataMC(:,2), ...
                    'Color', 'k', 'LineWidth', 0.5);
                line(app.ClusterEstAxes, histDataSample(:,1), histDataSample(:,2), ...
                    'Color', app.c.UT{'darkBlue',:}, 'LineWidth', 2);
                if ~isnan(splitValue)
                    app.ClusterEstParameterEditField.Value = splitValue;
                end

            elseif contains(clusterAlg, "DBSCAN")
                % Update the status.
                app.ClusterStatusLabel.Text = 'Estimating. Please wait ...'; drawnow

                [epsilon, PlotData] = estimateDBSCANepsilon(inputData, ...
                    round(app.clusterIDparam1editField.Value));
                patch(app.ClusterEstAxes, 'XData', PlotData.noiseRegion.XData, ...
                    'YData', PlotData.noiseRegion.YData, ...
                    'EdgeColor', 'none', 'FaceColor', app.c.CB{'orange',:}, 'FaceAlpha', 1);
                patch(app.ClusterEstAxes, 'XData', PlotData.clusterRegion.XData, 'YData', ...
                    PlotData.clusterRegion.YData, ...
                    'EdgeColor', 'none', 'FaceColor', app.c.CB{'blue',:}, 'FaceAlpha', 1);
                line(app.ClusterEstAxes, PlotData.splitLine.XData, PlotData.splitLine.YData, ...
                    'Color', 'k', 'LineStyle', '--', 'LineWidth', 0.5);
                line(app.ClusterEstAxes, PlotData.kDistance.XData, PlotData.kDistance.YData, ...
                    'Color', 'k', 'LineWidth', 2);
                app.ClusterEstParameterEditField.Value = epsilon;

            else
                % Empty.
            end

            % Only enable the 'transfer' button if a value could be estimated.
            enableButtons(app,"storeRecall",1);
            enableButtons(app,"open parameter window",1);
            if app.ClusterEstParameterEditField.Value ~= 0
                enableButtons(app,"transfer",1);
            end

            % Update the status.
            app.ClusterStatusLabel.Text = 'idle'; drawnow
        end

        % Button pushed function: ClusterEstTransferButton
        function ClusterEstTransferButtonPushed(app, event)
            if contains(app.ClusterIDdropDown.Value, "Voronoi")
                app.clusterIDparam1editField.Value = app.ClusterEstParameterEditField.Value;
            elseif contains(app.ClusterIDdropDown.Value, "DBSCAN")
                app.clusterIDparam2editField.Value = app.ClusterEstParameterEditField.Value;
            else
                % NASTIC.
            end

            selectNewClusterAlgorithm(app,'internal');
        end

        % Value changed function: gPlotBaseDataDropDown
        function gPlotBaseDataDropDownValueChanged(app, event)
            enactClusterBaseDataChange(app);
            enactClusterColorSchemeChange(app);
            getClusterVisOptions(app);
            app.VisC = updateAppGOsCA(app.GO.cluster, app.ClusterVisAxes, app.VisC);
        end

        % Value changed function: gPlotColorSchemeDropDown
        function gPlotColorSchemeDropDownValueChanged(app, event)
            enactClusterColorSchemeChange(app);
            getClusterVisOptions(app);
            app.VisC = updateAppGOsCA(app.GO.cluster, app.ClusterVisAxes, app.VisC);
        end

        % Value changed function: gPlotColorMapDropDown
        function gPlotColorMapDropDownValueChanged(app, event)
            getClusterVisOptions(app);
            app.VisC = updateAppGOsCA(app.GO.cluster, app.ClusterVisAxes, app.VisC);            
        end

        % Value changed function: cPlotAreasColorDropDown, 
        % ...and 9 other components
        function gPlotParametersChanged(app, event)
            % Handles value changes of the components: gPlotLowerLimitEditField, gPlotUpperLimitEditField,
            % app.gPlotPointSizeEditField, app.gPlotLineWidthEditField, app.gPlotScaleBarDropDown,
            % app.gPlotWhiteBGcheckBox, app.cPlotBoundariesColorDropDown, app.cPlotBoundariesLineWidthEditField,
            % app.cPlotAreasColorDropDown, app.cPlotAreasSemiTransparentCheckBox
            getClusterVisOptions(app);
            app.VisC = updateAppGOsCA(app.GO.cluster, app.ClusterVisAxes, app.VisC);
        end

        % Value changed function: cPlotShowAlgCheckBox
        function cPlotShowAlgCheckBoxValueChanged(app, event)
            enactClusterAlgorithmChange(app);
            enactClusterColorSchemeChange(app);
            getClusterVisOptions(app);
            app.VisC = updateAppGOsCA(app.GO.cluster, app.ClusterVisAxes, app.VisC);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            % Reverts the tab selection back to the previously selected tab when |app.tmp.blockTabChange| == true.
            if app.tmp.blockTabChange
                app.TabGroup.SelectedTab = event.OldValue;
            end            
        end

        % Button pushed function: BatchStartButton
        function BatchStartButtonPushed(app, event)
            app.tmp.blockTabChange = true;

            % Make sure that the results template is present.
            fullTemplatePath = which('resultsTemplate.xlsx');
            if isempty(fullTemplatePath)
                errorMsg = ["Excel template to save the results","is missing."];
                f = uifigure; uialert(f,errorMsg,'Validity check','Icon','error',...
                    'CloseFcn',@(src,event)closeDialogBox(app,src,event));
                uiwait(f)
                return
            end

            % Copy the Excel template to the specified output file. This either creates a new file or overwrites an
            % existing one.
            copyfile(fullTemplatePath, app.S.files.outputFile);

            % Close the parameter window app in case it is open.
            if ~isempty(app.ParameterApp) && isvalid(app.ParameterApp)
                close(app.ParameterApp.UIFigure)
            end
           
            % Create a progress dialog box.
            progressDialog = uiprogressdlg(app.MainUIFigure, 'Title', 'Batch Processing', 'Indeterminate','on');

            % Update the masks in |S|. This is necessary if the user has already loaded files into batch processing but
            % then re-adjusts the masks for these files before starting the batch procedure.
            app.S = updateMasksForS(app.S);

            % Run the batch analysis procedure in an external function (the progress dialog will be closed there).
            noiseFileIDs = app.BatchInputFileTable.Data{:,3};
            maskSelections = app.BatchInputFileTable.Data{:,4};
            batchAnalysis(app.S, noiseFileIDs, maskSelections, 'updateProgress', progressDialog);

            % Unblock tab change.
            app.tmp.blockTabChange = false;
        end

        % Cell edit callback: BatchInputFileTable
        function BatchInputFileTableCellEdit(app, event)
            if event.Indices(2) == 4
                if isempty(app.S.tracking.maskPolygons{event.Indices(1),1})
                    % No mask is present, so ignore user input and revert to false.
                    app.BatchInputFileTable.Data{event.Indices(1), event.Indices(2)} = false;
                else
                    % Accept the user input, but reset everything after the tracking step. 
                    app.BatchInputFileTable.Data{event.Indices(1), event.Indices(2)} = event.NewData;
                    resetAnalysisStep(app,"tracking");

                    % Afterwards, return to the Batch Analysis tab.
                    app.TabGroup.SelectedTab = app.BatchAnalysisTab;
                end
            elseif event.Indices(2) == 3
                % Check if the chosen noise file is suitable (same dimensions). %%%

            else
                % Do nothing.
            end

            % This callback is triggered when the user assigns noise file(s) to the input file(s). Check if the latest
            % entry will unlock the batch analysis.
            checkBatchStart(app);
        end

        % Value changed function: LocsPSFsizeLLEditField, 
        % ...and 8 other components
        function LocsRangeSliderValueChanged(app, event)
            % Handles the components: app.LocsPSFsizeSlider, app.LocsPSFsizeLLEditField, app.LocsPSFsizeULEditField, 
            % app.LocsPhotonsSlider, app.LocsPhotonsLLEditField, app.LocsPhotonsULEditField,
            % app.LocsPrecisionSlider, app.LocsPrecisionLLEditField, app.LocsPrecisionULEditField

            % Get the histogram ID.
            histID = str2double(event.Source.Tag(end));

            % Get the slider limits.
            LL = app.h.locs.rangeSliders(histID).Limits(1);
            UL = app.h.locs.rangeSliders(histID).Limits(2);
          
            % Check which component was changed.
            if contains(event.Source.Tag, "SL")
                % Slider changed. The slider limits will prevent any invalid actions.
                newValues = event.Source.Value;
                newLL = num2str(newValues(1));
                newUL = num2str(newValues(2));

                app.h.locs.rangeLL(histID).Value = newLL;
                app.h.locs.rangeUL(histID).Value = newUL;
                app.S.localization.limits{histID,:} = newValues;

                % Overwrite these values if they hit a slider limit.
                if newValues(1) == LL
                    app.h.locs.rangeLL(histID).Value = "0";
                    app.S.localization.limits{histID,1} = 0;
                end

                if newValues(2) == UL
                    app.h.locs.rangeUL(histID).Value = "Inf";
                    app.S.localization.limits{histID,2} = Inf;
                end

            elseif contains(event.Source.Tag, "LL")
                % The lower limit field changed.
                newValue = round(str2double(event.Value));

                if newValue == 0
                    % The user assigned an open lower limit.
                    app.h.locs.rangeSliders(histID).Value(1) = LL;

                elseif isbetween(newValue, LL, UL-1)
                    % Valid limit.
                    % Change the respective range slider value.
                    app.h.locs.rangeSliders(histID).Value(1) = newValue;

               else
                    % Invalid entry (either outside the limits or NaN).
                    event.Source.Value = event.PreviousValue;
                    return
                end

                app.S.localization.limits{histID,1} = newValue;

            elseif contains(event.Source.Tag, "UL")
                % The upper limit field changed.
                newValue = round(str2double(event.Value));

                if isinf(newValue)
                    % The user assigned an open lower limit.
                    app.h.locs.rangeSliders(histID).Value(2) = UL;

                elseif isbetween(newValue, LL+1, UL)
                    % Valid limit.
                    % Change the respective range slider value.
                    app.h.locs.rangeSliders(histID).Value(1) = newValue;

                else
                    % Invalid entry (either outside the limits or NaN).
                    event.Source.Value = event.PreviousValue;
                    return
                end

                app.S.localization.limits{histID,2} = newValue;

            end

            % Update the parameter app.
            updateParameterApp(app);

            % Any changed limit has an effect on all histograms. Generate a new filtered point list from the original
            % point list.
            locLimits = table2array(app.S.localization.limits)';
            pointList = app.tmp.selectedPointList;
            app.tmp.selectedPointListFiltered = pointList(~any(pointList < locLimits(1,:) |...
                pointList > locLimits(2,:),2),:);

            % Update the outlier list with the remaining localizations and update all histogram plots.
            app.tmp.selectedOutlierInfo(6) = size(app.tmp.selectedPointListFiltered,1);
            updateLocHistograms(app);

            % Reset the data for all subsequent analysis steps.
            resetAnalysisStep(app,"locLimits");     
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainUIFigure = uifigure('Visible', 'off');
            app.MainUIFigure.Name = 'OneFlowTraX v1.3';
            app.MainUIFigure.CloseRequestFcn = createCallbackFcn(app, @MainUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.MainUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.LocalizationTab = uitab(app.TabGroup);
            app.LocalizationTab.Title = 'Localization';
            app.LocalizationTab.Tag = 'localization tab';
            app.LocsFileInputPanel = uipanel(app.LocalizationTab);
            app.LocsFileInputPanel.Title = 'file input';
            app.LoadSMLMfilesButton = uibutton(app.LocsFileInputPanel, 'push');
            app.LoadSMLMfilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSMLMfilesButtonPushed, true);
            app.LoadSMLMfilesButton.Tag = 'load SMLM files';
            app.LoadSMLMfilesButton.Enable = 'off';
            app.LoadSMLMfilesButton.Text = 'load SMLM files';
            app.LoadsCMOSnoiseFileButton = uibutton(app.LocsFileInputPanel, 'push');
            app.LoadsCMOSnoiseFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadsCMOSnoiseFileButtonPushed, true);
            app.LoadsCMOSnoiseFileButton.Tag = 'load sCMOS noise file';
            app.LoadsCMOSnoiseFileButton.Enable = 'off';
            app.LoadsCMOSnoiseFileButton.Tooltip = {'Supply one noise file that will be used for all input files above. This can either be a TIFF stack containing a set of dark images, or a single-image TIFF files where each pixel carries the value for the corresponding (previously calculated) variance. For EMCCD cameras, use the provided ''EMCCDnoise.TIF'' file.'};
            app.LoadsCMOSnoiseFileButton.Text = 'load sCMOS noise file';
            app.LocsSelectAllFilesCheckBox = uicheckbox(app.LocsFileInputPanel);
            app.LocsSelectAllFilesCheckBox.ValueChangedFcn = createCallbackFcn(app, @LocsSelectAllFilesCheckBoxValueChanged, true);
            app.LocsSelectAllFilesCheckBox.Enable = 'off';
            app.LocsSelectAllFilesCheckBox.Text = 'select all';
            app.LocsFileListBox = uilistbox(app.LocsFileInputPanel);
            app.LocsFileListBox.Items = {};
            app.LocsFileListBox.Multiselect = 'on';
            app.LocsFileListBox.ValueChangedFcn = createCallbackFcn(app, @LocsFileListBoxValueChanged, true);
            app.LocsFileListBox.Enable = 'off';
            app.LocsFileListBox.Value = {};
            app.LocsFileListBoxLabel = uilabel(app.LocsFileInputPanel);
            app.LocsFileListBoxLabel.Enable = 'off';
            app.LocsFileListBoxLabel.Text = 'file list';
            app.LocsNoiseFileListBox = uilistbox(app.LocsFileInputPanel);
            app.LocsNoiseFileListBox.Items = {};
            app.LocsNoiseFileListBox.Enable = 'off';
            app.LocsNoiseFileListBox.Value = {};
            app.LocsLocparamPanel = uipanel(app.LocalizationTab);
            app.LocsLocparamPanel.Title = 'localization parameters';
            app.LocsLocparamConversionEditFieldLabel = uilabel(app.LocsLocparamPanel);
            app.LocsLocparamConversionEditFieldLabel.Tooltip = {'Conversion factor of electrons per analog-to-digital unit (ADU).'; 'This value is read from the TIFF file metadata.'};
            app.LocsLocparamConversionEditFieldLabel.Text = 'conversion (e-/ADU)';
            app.LocsLocparamOffsetEditFieldLabel = uilabel(app.LocsLocparamPanel);
            app.LocsLocparamOffsetEditFieldLabel.Tooltip = {'Intensity offset of the camera.'; 'This value is added by the camera'; 'to the displayed pixel values (to prevent negative values)'; 'and is automatically calculated with the noise file.'; 'Alternatively'; ' this value is read from'; 'the camera TIFF file metadata.'};
            app.LocsLocparamOffsetEditFieldLabel.Text = 'offset (ADU)';
            app.LocsLocparamPixelSizeEditFieldLabel = uilabel(app.LocsLocparamPanel);
            app.LocsLocparamPixelSizeEditFieldLabel.Tooltip = {'Size of each pixel in terms of the sample plane.'; 'This value has to be provided by the user'; 'and depends on the microscope objective'; ''; 'camera lens and chip.'; 'Usual values range between 100 and 150'; '(according to the Nyquist criterion).'};
            app.LocsLocparamPixelSizeEditFieldLabel.Text = 'pixel size (nm)';
            app.LocsLocparamFilterSizeEditFieldLabel = uilabel(app.LocsLocparamPanel);
            app.LocsLocparamFilterSizeEditFieldLabel.Tooltip = {'Gaussian filter size to detect peaks in the images'; '(i.e. fluorophores).'; 'Default value: 1.2'};
            app.LocsLocparamFilterSizeEditFieldLabel.Text = 'filter size (pixel)';
            app.LocsLocparamCutOffEditFieldLabel = uilabel(app.LocsLocparamPanel);
            app.LocsLocparamCutOffEditFieldLabel.Tooltip = {'Number of photons above the background'; 'for a localization to be included in the analysis.'; 'Default value: 3.5'};
            app.LocsLocparamCutOffEditFieldLabel.Text = 'cut-off (photons)';
            app.LocsLocparamPSFROIsizeEditField = uieditfield(app.LocsLocparamPanel, 'numeric');
            app.LocsLocparamPSFROIsizeEditField.Limits = [5 45];
            app.LocsLocparamPSFROIsizeEditField.ValueChangedFcn = createCallbackFcn(app, @LocsParametersChanged, true);
            app.LocsLocparamPSFROIsizeEditField.Tag = 'localization PSFROI';
            app.LocsLocparamPSFROIsizeEditField.Enable = 'off';
            app.LocsLocparamPSFROIsizeEditField.Tooltip = {'Width of the ROI square with which each eligible spot is cut out and processed further. Too small values may not capture the entire PSF, too large values may capture more than one. Must be an odd number.'};
            app.LocsLocparamPSFROIsizeEditField.Value = 13;
            app.LocsLocparamCutOffEditField = uieditfield(app.LocsLocparamPanel, 'numeric');
            app.LocsLocparamCutOffEditField.Limits = [0 3000];
            app.LocsLocparamCutOffEditField.ValueChangedFcn = createCallbackFcn(app, @LocsParametersChanged, true);
            app.LocsLocparamCutOffEditField.Tag = 'localization cutoff';
            app.LocsLocparamCutOffEditField.Enable = 'off';
            app.LocsLocparamCutOffEditField.Tooltip = {'Minimal value for the brightest pixel in a spot to be included in the localization procedure.'};
            app.LocsLocparamCutOffEditField.Value = 3.5;
            app.LocsLocparamFilterSizeEditField = uieditfield(app.LocsLocparamPanel, 'numeric');
            app.LocsLocparamFilterSizeEditField.Limits = [0.3 2.5];
            app.LocsLocparamFilterSizeEditField.ValueChangedFcn = createCallbackFcn(app, @LocsParametersChanged, true);
            app.LocsLocparamFilterSizeEditField.Tag = 'localization filterSize';
            app.LocsLocparamFilterSizeEditField.Enable = 'off';
            app.LocsLocparamFilterSizeEditField.Tooltip = {'Sigma of the Gaussian in the DoG filter to enhance the contrast for the basic detection of bright spots in each image.'};
            app.LocsLocparamFilterSizeEditField.Value = 1.2;
            app.LocsLocparamPixelSizeEditField = uieditfield(app.LocsLocparamPanel, 'numeric');
            app.LocsLocparamPixelSizeEditField.Limits = [10 2000];
            app.LocsLocparamPixelSizeEditField.ValueChangedFcn = createCallbackFcn(app, @LocsParametersChanged, true);
            app.LocsLocparamPixelSizeEditField.Tag = 'localization pixelSize';
            app.LocsLocparamPixelSizeEditField.Enable = 'off';
            app.LocsLocparamPixelSizeEditField.Tooltip = {'Length in the sample plane that is projected onto one camera pixel in the detection plane.'};
            app.LocsLocparamPixelSizeEditField.Value = 100;
            app.LocsLocparamOffsetEditField = uieditfield(app.LocsLocparamPanel, 'numeric');
            app.LocsLocparamOffsetEditField.Limits = [0 Inf];
            app.LocsLocparamOffsetEditField.ValueChangedFcn = createCallbackFcn(app, @LocsParametersChanged, true);
            app.LocsLocparamOffsetEditField.Tag = 'localization offset';
            app.LocsLocparamOffsetEditField.Enable = 'off';
            app.LocsLocparamOffsetEditField.Tooltip = {'Offset of the camera (without any light) that prevents negative values due to natural read noise fluctuations.'};
            app.LocsLocparamOffsetEditField.Value = 100;
            app.LocsLocparamConversionEditField = uieditfield(app.LocsLocparamPanel, 'numeric');
            app.LocsLocparamConversionEditField.Limits = [0.01 Inf];
            app.LocsLocparamConversionEditField.ValueChangedFcn = createCallbackFcn(app, @LocsParametersChanged, true);
            app.LocsLocparamConversionEditField.Tag = 'localization conversion';
            app.LocsLocparamConversionEditField.Enable = 'off';
            app.LocsLocparamConversionEditField.Tooltip = {'Factor to convert ADU (Analog-to-Digital Unit) into photoelectrons (reciprocal of the average pixel gain).'};
            app.LocsLocparamConversionEditField.Value = 0.45;
            app.LocsLocparamPSFROIsizeEditFieldLabel = uilabel(app.LocsLocparamPanel);
            app.LocsLocparamPSFROIsizeEditFieldLabel.Tooltip = {'Square cut-out section for each putative localization PSF. '; 'Default value: 13'};
            app.LocsLocparamPSFROIsizeEditFieldLabel.Text = 'PSF ROI size (pixel)';
            app.LocalizeButton = uibutton(app.LocalizationTab, 'push');
            app.LocalizeButton.ButtonPushedFcn = createCallbackFcn(app, @LocalizeButtonPushed, true);
            app.LocalizeButton.Tag = 'Localize';
            app.LocalizeButton.Enable = 'off';
            app.LocalizeButton.Text = 'Localize';
            app.TransferToTrackingButton = uibutton(app.LocalizationTab, 'push');
            app.TransferToTrackingButton.ButtonPushedFcn = createCallbackFcn(app, @TransferToTrackingButtonPushed, true);
            app.TransferToTrackingButton.Tag = 'Transfer to Tracking';
            app.TransferToTrackingButton.Enable = 'off';
            app.TransferToTrackingButton.Text = 'Transfer to Tracking';
            app.LocsFilteredPanel = uipanel(app.LocalizationTab);
            app.LocsFilteredPanel.Title = 'filtered localizations';
            app.LocsFilteredPanelLabel = uilabel(app.LocsFilteredPanel);
            app.LocsFilteredPanelLabel.Text = '';
            app.LocsStatusPanel = uipanel(app.LocalizationTab);
            app.LocsStatusPanel.Title = 'status';
            app.LocsStatusLabel = uilabel(app.LocsStatusPanel);
            app.LocsStatusLabel.Text = 'idle';
            app.LocsPrecisionPanel = uipanel(app.LocalizationTab);
            app.LocsPrecisionPanel.Title = 'CRLB localization precision (nm)';
            app.LocsPrecisionAxes = uiaxes(app.LocsPrecisionPanel);
            ylabel(app.LocsPrecisionAxes, 'occurence')
            app.LocsPrecisionAxes.XLim = [0 60];
            app.LocsPrecisionAxes.YLim = [0 100000];
            app.LocsPrecisionAxes.XTick = [];
            app.LocsPrecisionSlider = uislider(app.LocsPrecisionPanel, 'range');
            app.LocsPrecisionSlider.ValueChangedFcn = createCallbackFcn(app, @LocsRangeSliderValueChanged, true);
            app.LocsPrecisionSlider.Step = 1;
            app.LocsPrecisionSlider.Tag = 'localization SL3';
            app.LocsPrecisionLLEditField = uieditfield(app.LocsPrecisionPanel, 'text');
            app.LocsPrecisionLLEditField.ValueChangedFcn = createCallbackFcn(app, @LocsRangeSliderValueChanged, true);
            app.LocsPrecisionLLEditField.Tag = 'localization LL3';
            app.LocsPrecisionLLEditField.Value = '0';
            app.LocsPrecisionULEditField = uieditfield(app.LocsPrecisionPanel, 'text');
            app.LocsPrecisionULEditField.ValueChangedFcn = createCallbackFcn(app, @LocsRangeSliderValueChanged, true);
            app.LocsPrecisionULEditField.Tag = 'localization UL3';
            app.LocsPrecisionULEditField.Value = '100';
            app.LocsPrecisionLLEditFieldLabel = uilabel(app.LocsPrecisionPanel);
            app.LocsPrecisionLLEditFieldLabel.Text = 'lower limit';
            app.LocsPrecisionULEditFieldLabel = uilabel(app.LocsPrecisionPanel);
            app.LocsPrecisionULEditFieldLabel.Text = 'upper limit';
            app.LocsPhotonsPanel = uipanel(app.LocalizationTab);
            app.LocsPhotonsPanel.Title = 'photons';
            app.LocsPhotonsAxes = uiaxes(app.LocsPhotonsPanel);
            ylabel(app.LocsPhotonsAxes, 'occurence')
            app.LocsPhotonsAxes.XLim = [0 1000];
            app.LocsPhotonsAxes.YLim = [0 100000];
            app.LocsPhotonsAxes.XTick = [];
            app.LocsPhotonsSlider = uislider(app.LocsPhotonsPanel, 'range');
            app.LocsPhotonsSlider.ValueChangedFcn = createCallbackFcn(app, @LocsRangeSliderValueChanged, true);
            app.LocsPhotonsSlider.Step = 1;
            app.LocsPhotonsSlider.Tag = 'localization SL2';
            app.LocsPhotonsLLEditField = uieditfield(app.LocsPhotonsPanel, 'text');
            app.LocsPhotonsLLEditField.ValueChangedFcn = createCallbackFcn(app, @LocsRangeSliderValueChanged, true);
            app.LocsPhotonsLLEditField.Tag = 'localization LL2';
            app.LocsPhotonsLLEditField.Value = '0';
            app.LocsPhotonsULEditField = uieditfield(app.LocsPhotonsPanel, 'text');
            app.LocsPhotonsULEditField.ValueChangedFcn = createCallbackFcn(app, @LocsRangeSliderValueChanged, true);
            app.LocsPhotonsULEditField.Tag = 'localization UL2';
            app.LocsPhotonsULEditField.Value = '100';
            app.LocsPhotonsLLEditFieldLabel = uilabel(app.LocsPhotonsPanel);
            app.LocsPhotonsLLEditFieldLabel.Text = 'lower limit';
            app.LocsPhotonsULEditFieldLabel = uilabel(app.LocsPhotonsPanel);
            app.LocsPhotonsULEditFieldLabel.Text = 'upper limit';
            app.LocsPSFsizePanel = uipanel(app.LocalizationTab);
            app.LocsPSFsizePanel.Title = 'PSF size (nm)';
            app.LocsPSFsizeAxes = uiaxes(app.LocsPSFsizePanel);
            ylabel(app.LocsPSFsizeAxes, 'occurence')
            app.LocsPSFsizeAxes.XLim = [0 300];
            app.LocsPSFsizeAxes.YLim = [0 100000];
            app.LocsPSFsizeAxes.XTick = [];
            app.LocsPSFsizeAxes.XTickLabel = '';
            app.LocsPSFsizeSlider = uislider(app.LocsPSFsizePanel, 'range');
            app.LocsPSFsizeSlider.ValueChangedFcn = createCallbackFcn(app, @LocsRangeSliderValueChanged, true);
            app.LocsPSFsizeSlider.Step = 1;
            app.LocsPSFsizeSlider.Tag = 'localization SL1';
            app.LocsPSFsizeLLEditField = uieditfield(app.LocsPSFsizePanel, 'text');
            app.LocsPSFsizeLLEditField.ValueChangedFcn = createCallbackFcn(app, @LocsRangeSliderValueChanged, true);
            app.LocsPSFsizeLLEditField.Tag = 'localization LL1';
            app.LocsPSFsizeLLEditField.Value = '0';
            app.LocsPSFsizeLLEditFieldLabel = uilabel(app.LocsPSFsizePanel);
            app.LocsPSFsizeLLEditFieldLabel.Text = 'lower limit';
            app.LocsPSFsizeULEditField = uieditfield(app.LocsPSFsizePanel, 'text');
            app.LocsPSFsizeULEditField.ValueChangedFcn = createCallbackFcn(app, @LocsRangeSliderValueChanged, true);
            app.LocsPSFsizeULEditField.Tag = 'localization UL1';
            app.LocsPSFsizeULEditField.Value = '100';
            app.LocsPSFsizeULEditFieldLabel = uilabel(app.LocsPSFsizePanel);
            app.LocsPSFsizeULEditFieldLabel.Text = 'upper limit';
            app.FineTuneParametersButton = uibutton(app.LocalizationTab, 'push');
            app.FineTuneParametersButton.ButtonPushedFcn = createCallbackFcn(app, @FineTuneParametersButtonPushed, true);
            app.FineTuneParametersButton.Tag = 'Fine-tune parameters';
            app.FineTuneParametersButton.Enable = 'off';
            app.FineTuneParametersButton.Text = 'Fine-tune parameters';
            app.LocsOpenParameterAppButton = uibutton(app.LocalizationTab, 'push');
            app.LocsOpenParameterAppButton.ButtonPushedFcn = createCallbackFcn(app, @LocsOpenParameterAppButtonPushed, true);
            app.LocsOpenParameterAppButton.Tag = 'open parameter window (locs)';
            app.LocsOpenParameterAppButton.Enable = 'off';
            app.LocsOpenParameterAppButton.Text = 'open parameter window';
            app.TrackingTab = uitab(app.TabGroup);
            app.TrackingTab.Title = 'Tracking';
            app.TrackingTab.Tag = 'tracking tab';
            app.TracksFileInputPanel = uipanel(app.TrackingTab);
            app.TracksFileInputPanel.Title = 'file input';
            app.TracksSelectAllFilesCheckBox = uicheckbox(app.TracksFileInputPanel);
            app.TracksSelectAllFilesCheckBox.ValueChangedFcn = createCallbackFcn(app, @TracksSelectAllFilesCheckBoxValueChanged, true);
            app.TracksSelectAllFilesCheckBox.Enable = 'off';
            app.TracksSelectAllFilesCheckBox.Text = 'select all';
            app.TracksFileListBox = uilistbox(app.TracksFileInputPanel);
            app.TracksFileListBox.Items = {};
            app.TracksFileListBox.Multiselect = 'on';
            app.TracksFileListBox.ValueChangedFcn = createCallbackFcn(app, @TracksFileListBoxValueChanged, true);
            app.TracksFileListBox.Enable = 'off';
            app.TracksFileListBox.Value = {};
            app.TracksFileListBoxLabel = uilabel(app.TracksFileInputPanel);
            app.TracksFileListBoxLabel.Enable = 'off';
            app.TracksFileListBoxLabel.Text = 'file list';
            app.TracksBuildparamPanel = uipanel(app.TrackingTab);
            app.TracksBuildparamPanel.Title = 'track building parameters';
            app.TracksMaxLinkDistEditFieldLabel = uilabel(app.TracksBuildparamPanel);
            app.TracksMaxLinkDistEditFieldLabel.Text = 'max. linking distance (nm)';
            app.TracksMaxGapClosingEditField = uieditfield(app.TracksBuildparamPanel, 'numeric');
            app.TracksMaxGapClosingEditField.Limits = [1 10];
            app.TracksMaxGapClosingEditField.ValueDisplayFormat = '%u';
            app.TracksMaxGapClosingEditField.ValueChangedFcn = createCallbackFcn(app, @TracksBuildingParametersChanged, true);
            app.TracksMaxGapClosingEditField.Tag = 'tracking maxGapClose';
            app.TracksMaxGapClosingEditField.Tooltip = {'Maximum number of frames to look ahead for a fluorophore position to connect it to a previous one found in the same vicinity.'};
            app.TracksMaxGapClosingEditField.Value = 4;
            app.TracksMaxLinkDistEditField = uieditfield(app.TracksBuildparamPanel, 'numeric');
            app.TracksMaxLinkDistEditField.Limits = [0 3000];
            app.TracksMaxLinkDistEditField.ValueDisplayFormat = '%u';
            app.TracksMaxLinkDistEditField.ValueChangedFcn = createCallbackFcn(app, @TracksBuildingParametersChanged, true);
            app.TracksMaxLinkDistEditField.Tag = 'tracking maxLinkDistance';
            app.TracksMaxLinkDistEditField.Tooltip = {'Maximum distance between two fluorophore positions in two consecutive frames to be assigned to the same track in nanometers.'};
            app.TracksMaxLinkDistEditField.Value = 300;
            app.TracksMaxGapClosingEditFieldLabel = uilabel(app.TracksBuildparamPanel);
            app.TracksMaxGapClosingEditFieldLabel.Text = 'max. gap closing (frames)';
            app.BuildTracksButton = uibutton(app.TrackingTab, 'push');
            app.BuildTracksButton.ButtonPushedFcn = createCallbackFcn(app, @BuildTracksButtonPushed, true);
            app.BuildTracksButton.Tag = 'Build tracks';
            app.BuildTracksButton.Text = 'Build tracks';
            app.TracksStatusPanel = uipanel(app.TrackingTab);
            app.TracksStatusPanel.Title = 'status';
            app.TracksStatusLabel = uilabel(app.TracksStatusPanel);
            app.TracksStatusLabel.Text = 'idle';
            app.TracksMSDanalysisPanel = uipanel(app.TrackingTab);
            app.TracksMSDanalysisPanel.Title = 'mobility analysis';
            app.TracksDdistAxes = uiaxes(app.TracksMSDanalysisPanel);
            title(app.TracksDdistAxes, 'D distribution')
            xlabel(app.TracksDdistAxes, 'log D')
            ylabel(app.TracksDdistAxes, 'occurence')
            app.TracksDdistAxes.XLim = [-5 0];
            app.TracksDdistAxes.YLim = [0 0.001];
            app.TracksMeanMSDaxes = uiaxes(app.TracksMSDanalysisPanel);
            title(app.TracksMeanMSDaxes, 'mean MSD')
            xlabel(app.TracksMeanMSDaxes, 'lag time (s)')
            ylabel(app.TracksMeanMSDaxes, 'MSD (µm²)')
            app.TracksMeanMSDaxes.YLim = [0 0.1];
            app.TracksDdistResultLabel = uilabel(app.TracksMSDanalysisPanel);
            app.TracksDdistResultLabel.Text = {'calculated D (fitted peak center):'; ''; ''};
            app.TracksMeanMSDresultLabel = uilabel(app.TracksMSDanalysisPanel);
            app.TracksMeanMSDresultLabel.Text = {'calculated D (linear fit of mean):'; ''; ''};
            app.TracksMeanMSDautoScaleCheckBox = uicheckbox(app.TracksMSDanalysisPanel);
            app.TracksMeanMSDautoScaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @TracksMeanMSDautoScaleCheckBoxValueChanged, true);
            app.TracksMeanMSDautoScaleCheckBox.Tooltip = {'When checked, this limits the x-axis to one second, otherwise the entire data range is used.'};
            app.TracksMeanMSDautoScaleCheckBox.Text = 'auto scale';
            app.TracksMeanMSDautoScaleCheckBox.Value = true;
            app.Tracks2PopCheckBox = uicheckbox(app.TracksMSDanalysisPanel);
            app.Tracks2PopCheckBox.ValueChangedFcn = createCallbackFcn(app, @Tracks2PopCheckBoxValueChanged, true);
            app.Tracks2PopCheckBox.Tooltip = {'Check this box to preview a fit that is based on two populations of diffusing molecules.'};
            app.Tracks2PopCheckBox.Text = 'two Populations';
            app.TracksDdistSplitEditField = uieditfield(app.TracksMSDanalysisPanel, 'numeric');
            app.TracksDdistSplitEditField.ValueDisplayFormat = '%.2f';
            app.TracksDdistSplitEditField.ValueChangedFcn = createCallbackFcn(app, @TracksDdistSplitEditFieldValueChanged, true);
            app.TracksDdistSplitEditField.Tag = 'MSDanalysis splitValue';
            app.TracksDdistSplitEditField.Tooltip = {'log10 value of the diffusion coefficient that separates the slower from the faster fraction of diffusing molecules.'};
            app.TracksDdistSplitEditField.Value = -1.75;
            app.TracksDdistSplitEditFieldLabel = uilabel(app.TracksMSDanalysisPanel);
            app.TracksDdistSplitEditFieldLabel.Text = 'split value';
            app.TracksVisPanel = uipanel(app.TrackingTab);
            app.TracksVisPanel.Title = 'tracks visualization';
            app.TracksVisZoomAxes = uiaxes(app.TracksVisPanel);
            app.TracksVisZoomAxes.XTick = [];
            app.TracksVisZoomAxes.XTickLabel = '';
            app.TracksVisZoomAxes.YTick = [];
            app.TracksVisZoomAxes.YTickLabel = '';
            app.TracksVisNormalAxes = uiaxes(app.TracksVisPanel);
            app.TracksVisNormalAxes.XTick = [];
            app.TracksVisNormalAxes.XTickLabel = '';
            app.TracksVisNormalAxes.YTick = [];
            app.TracksVisNormalAxes.YTickLabel = '';
            app.TracksVisUpperLimitEditField = uieditfield(app.TracksVisPanel, 'numeric');
            app.TracksVisUpperLimitEditField.Limits = [0 Inf];
            app.TracksVisUpperLimitEditField.ValueDisplayFormat = '%u';
            app.TracksVisUpperLimitEditField.ValueChangedFcn = createCallbackFcn(app, @TracksVisUnderlayTIFFCheckBoxValueChanged, true);
            app.TracksVisUpperLimitEditField.Tooltip = {'max: 200'};
            app.TracksVisUpperLimitEditField.Value = 200;
            app.TracksVisUpperLimitEditFieldLabel = uilabel(app.TracksVisPanel);
            app.TracksVisUpperLimitEditFieldLabel.Text = 'to';
            app.TracksVisLowerLimitEditField = uieditfield(app.TracksVisPanel, 'numeric');
            app.TracksVisLowerLimitEditField.Limits = [0 Inf];
            app.TracksVisLowerLimitEditField.ValueDisplayFormat = '%u';
            app.TracksVisLowerLimitEditField.ValueChangedFcn = createCallbackFcn(app, @TracksVisUnderlayTIFFCheckBoxValueChanged, true);
            app.TracksVisLowerLimitEditField.Tooltip = {'min: 0'};
            app.TracksVisLowerLimitEditFieldLabel = uilabel(app.TracksVisPanel);
            app.TracksVisLowerLimitEditFieldLabel.Text = 'from';
            app.TracksVisMaskTracksPanel = uipanel(app.TracksVisPanel);
            app.TracksVisMaskTracksPanel.Title = 'mask tracks';
            app.TracksVisEditMaskButton = uibutton(app.TracksVisMaskTracksPanel, 'push');
            app.TracksVisEditMaskButton.ButtonPushedFcn = createCallbackFcn(app, @TracksVisEditMaskButtonPushed, true);
            app.TracksVisEditMaskButton.Tag = 'edit mask';
            app.TracksVisEditMaskButton.Text = 'edit mask';
            app.TracksVisShowUnmaskedCheckBox = uicheckbox(app.TracksVisMaskTracksPanel);
            app.TracksVisShowUnmaskedCheckBox.ValueChangedFcn = createCallbackFcn(app, @TracksVisUnderlayTIFFCheckBoxValueChanged, true);
            app.TracksVisShowUnmaskedCheckBox.Tooltip = {'When a mask is present, this shows how the data would look without the mask. Analyses will always incorporate the mask, whether this box is checked or not.'};
            app.TracksVisShowUnmaskedCheckBox.Text = 'show unmasked tracks';
            app.TracksVisSaveTrackingImagesPanel = uipanel(app.TracksVisPanel);
            app.TracksVisSaveTrackingImagesPanel.Title = 'save tracking images';
            app.TracksVisSaveButton = uibutton(app.TracksVisSaveTrackingImagesPanel, 'push');
            app.TracksVisSaveButton.ButtonPushedFcn = createCallbackFcn(app, @TracksVisSaveButtonPushed, true);
            app.TracksVisSaveButton.Tag = 'save';
            app.TracksVisSaveButton.Text = 'save';
            app.TracksVisPlottingOptionsPanel = uipanel(app.TracksVisPanel);
            app.TracksVisPlottingOptionsPanel.Title = 'plotting options';
            app.TracksVisLineWidthEditField = uieditfield(app.TracksVisPlottingOptionsPanel, 'numeric');
            app.TracksVisLineWidthEditField.Limits = [0.5 4];
            app.TracksVisLineWidthEditField.ValueDisplayFormat = '%.1f';
            app.TracksVisLineWidthEditField.ValueChangedFcn = createCallbackFcn(app, @TracksVisUnderlayTIFFCheckBoxValueChanged, true);
            app.TracksVisLineWidthEditField.Value = 1;
            app.TracksVisLineWidthEditFieldLabel = uilabel(app.TracksVisPlottingOptionsPanel);
            app.TracksVisLineWidthEditFieldLabel.Text = 'line width';
            app.TracksVisScaleBarDropDown = uidropdown(app.TracksVisPlottingOptionsPanel);
            app.TracksVisScaleBarDropDown.Items = {'none', 'bar (white)', 'full (white)', 'bar (black)', 'full (black)'};
            app.TracksVisScaleBarDropDown.ValueChangedFcn = createCallbackFcn(app, @TracksVisUnderlayTIFFCheckBoxValueChanged, true);
            app.TracksVisScaleBarDropDown.Value = 'none';
            app.TracksVisScaleBarDropDownLabel = uilabel(app.TracksVisPlottingOptionsPanel);
            app.TracksVisScaleBarDropDownLabel.Text = 'scale bar';
            app.TracksVisWhiteBackgroundCheckBox = uicheckbox(app.TracksVisPlottingOptionsPanel);
            app.TracksVisWhiteBackgroundCheckBox.ValueChangedFcn = createCallbackFcn(app, @TracksVisUnderlayTIFFCheckBoxValueChanged, true);
            app.TracksVisWhiteBackgroundCheckBox.Text = 'white background';
            app.TracksVisUnderlayTIFFcheckBox = uicheckbox(app.TracksVisPlottingOptionsPanel);
            app.TracksVisUnderlayTIFFcheckBox.ValueChangedFcn = createCallbackFcn(app, @TracksVisUnderlayTIFFCheckBoxValueChanged, true);
            app.TracksVisUnderlayTIFFcheckBox.Text = 'underlay TIFF file';
            app.TracksVisColorSchemeDropDown = uidropdown(app.TracksVisPlottingOptionsPanel);
            app.TracksVisColorSchemeDropDown.Items = {'random', 'duration', 'displacement', 'range', 'mobility (D)', 'mobility (logD)', 'immobile/mobile (logD)'};
            app.TracksVisColorSchemeDropDown.ValueChangedFcn = createCallbackFcn(app, @TracksVisUnderlayTIFFCheckBoxValueChanged, true);
            app.TracksVisColorSchemeDropDown.Tag = 'color scheme (tracks)';
            app.TracksVisColorSchemeDropDown.Value = 'random';
            app.TracksVisColorSchemeDropDownLabel = uilabel(app.TracksVisPlottingOptionsPanel);
            app.TracksVisColorSchemeDropDownLabel.Text = 'color scheme';
            app.TracksVisColorMapDropDown = uidropdown(app.TracksVisPlottingOptionsPanel);
            app.TracksVisColorMapDropDown.Items = {'cycle 4 colors', 'cycle 6 colors', 'cycle 8 colors', 'cycle 10 colors'};
            app.TracksVisColorMapDropDown.ValueChangedFcn = createCallbackFcn(app, @TracksVisUnderlayTIFFCheckBoxValueChanged, true);
            app.TracksVisColorMapDropDown.Value = 'cycle 6 colors';
            app.TracksVisColorMapDropDownLabel = uilabel(app.TracksVisPlottingOptionsPanel);
            app.TracksVisColorMapDropDownLabel.Text = 'color map';
            app.TracksOpenParameterAppButton = uibutton(app.TrackingTab, 'push');
            app.TracksOpenParameterAppButton.ButtonPushedFcn = createCallbackFcn(app, @LocsOpenParameterAppButtonPushed, true);
            app.TracksOpenParameterAppButton.Tag = 'open parameter window (tracks)';
            app.TracksOpenParameterAppButton.Text = 'open parameter window';
            app.TransferToClusterAnalyisButton = uibutton(app.TrackingTab, 'push');
            app.TransferToClusterAnalyisButton.ButtonPushedFcn = createCallbackFcn(app, @TransferToClusterAnalyisButtonPushed, true);
            app.TransferToClusterAnalyisButton.Tag = 'Transfer to Cluster Analysis';
            app.TransferToClusterAnalyisButton.Text = 'Transfer to Cluster Analyis';
            app.TracksFitparamPanel = uipanel(app.TrackingTab);
            app.TracksFitparamPanel.Title = 'MSD fitting parameters';
            app.TracksMinTrackLengthEditFieldLabel = uilabel(app.TracksFitparamPanel);
            app.TracksMinTrackLengthEditFieldLabel.Text = 'min. track length (data points)';
            app.TracksAdjR2EditFieldLabel = uilabel(app.TracksFitparamPanel);
            app.TracksAdjR2EditFieldLabel.Text = 'min. adj. R² (indiv. MSD fits)';
            app.TracksLinFitPointsEditFieldLabel = uilabel(app.TracksFitparamPanel);
            app.TracksLinFitPointsEditFieldLabel.Text = 'data point for linear fits';
            app.TracksLinFitPointsEditField = uieditfield(app.TracksFitparamPanel, 'text');
            app.TracksLinFitPointsEditField.ValueChangedFcn = createCallbackFcn(app, @TracksFittingParametersChanged, true);
            app.TracksLinFitPointsEditField.Tag = 'MSDanalysis linFitPoints';
            app.TracksLinFitPointsEditField.Tooltip = {'Only the first N points will be included in the linear fit of individual MSD curves. If a track''s MSD curves misses any of these points, it will be discarded entirely. You can also specify that the first point will be ignored, e.g. by entering ''2-4''. This is often done as this time interval shows complex behavior (Kusumi, A. et al., Biophys. J. 65, 2021-2040 (1993)).'};
            app.TracksLinFitPointsEditField.Value = '4';
            app.TracksAdjR2editField = uieditfield(app.TracksFitparamPanel, 'numeric');
            app.TracksAdjR2editField.Limits = [0 1];
            app.TracksAdjR2editField.ValueDisplayFormat = '%.2f';
            app.TracksAdjR2editField.ValueChangedFcn = createCallbackFcn(app, @TracksFittingParametersChanged, true);
            app.TracksAdjR2editField.Tag = 'MSDanalysis adjR2';
            app.TracksAdjR2editField.Tooltip = {'Minimum adjusted R² value for the linear fit to qualify its results (i.e., the diffusion coefficient) for subsequent analyses.'};
            app.TracksAdjR2editField.Value = 0.4;
            app.TracksMinTrackLengthEditField = uieditfield(app.TracksFitparamPanel, 'numeric');
            app.TracksMinTrackLengthEditField.Limits = [3 40];
            app.TracksMinTrackLengthEditField.ValueDisplayFormat = '%u';
            app.TracksMinTrackLengthEditField.ValueChangedFcn = createCallbackFcn(app, @TracksFittingParametersChanged, true);
            app.TracksMinTrackLengthEditField.Tag = 'MSDanalysis minTrackLength';
            app.TracksMinTrackLengthEditField.Tooltip = {'Minimum track length (in frames, including gaps) to qualify for MSD analysis.'};
            app.TracksMinTrackLengthEditField.Value = 8;
            app.TracksFrameRateEditField = uieditfield(app.TracksFitparamPanel, 'numeric');
            app.TracksFrameRateEditField.Limits = [1 100];
            app.TracksFrameRateEditField.ValueDisplayFormat = '%.1f';
            app.TracksFrameRateEditField.ValueChangedFcn = createCallbackFcn(app, @TracksFittingParametersChanged, true);
            app.TracksFrameRateEditField.Tag = 'MSDanalysis frameRate';
            app.TracksFrameRateEditField.Tooltip = {'Frequency (in frames per second) at which consecutive images were acquired.'};
            app.TracksFrameRateEditField.Value = 20;
            app.TracksFrameRateEditFieldLabel = uilabel(app.TracksFitparamPanel);
            app.TracksFrameRateEditFieldLabel.Text = 'frame rate (Hz)';
            app.MSDanalysisButton = uibutton(app.TrackingTab, 'push');
            app.MSDanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @MSDanalysisButtonPushed, true);
            app.MSDanalysisButton.Tag = 'MSD analysis';
            app.MSDanalysisButton.Text = 'MSD analysis';
            app.ClusterAnalysisTab = uitab(app.TabGroup);
            app.ClusterAnalysisTab.Title = 'Cluster Analysis';
            app.ClusterAnalysisTab.Tag = 'cluster tab';
            app.ClusterVisAxes = uiaxes(app.ClusterAnalysisTab);
            app.ClusterVisAxes.XTick = [];
            app.ClusterVisAxes.XTickLabel = '';
            app.ClusterVisAxes.YTick = [];
            app.ClusterVisAxes.YTickLabel = '';
            app.ClusterOpenParameterAppButton = uibutton(app.ClusterAnalysisTab, 'push');
            app.ClusterOpenParameterAppButton.ButtonPushedFcn = createCallbackFcn(app, @LocsOpenParameterAppButtonPushed, true);
            app.ClusterOpenParameterAppButton.Tag = 'open parameter window (cluster)';
            app.ClusterOpenParameterAppButton.Text = 'open parameter window';
            app.ClusterFileInputPanel = uipanel(app.ClusterAnalysisTab);
            app.ClusterFileInputPanel.Title = 'file input';
            app.ClusterFileListBoxLabel = uilabel(app.ClusterFileInputPanel);
            app.ClusterFileListBoxLabel.Text = 'file list';
            app.ClusterFileListBox = uilistbox(app.ClusterFileInputPanel);
            app.ClusterFileListBox.Items = {};
            app.ClusterFileListBox.Multiselect = 'on';
            app.ClusterFileListBox.ValueChangedFcn = createCallbackFcn(app, @ClusterFileListBoxValueChanged, true);
            app.ClusterFileListBox.Value = {};
            app.ClusterIDpanel = uipanel(app.ClusterAnalysisTab);
            app.ClusterIDpanel.Title = 'cluster identification';
            app.clusterIDresetButton = uibutton(app.ClusterIDpanel, 'push');
            app.clusterIDresetButton.ButtonPushedFcn = createCallbackFcn(app, @clusterIDresetButtonPushed, true);
            app.clusterIDresetButton.Tag = 'reset';
            app.clusterIDresetButton.Text = 'reset';
            app.clusterIDrunButton = uibutton(app.ClusterIDpanel, 'push');
            app.clusterIDrunButton.ButtonPushedFcn = createCallbackFcn(app, @clusterIDrunButtonPushed, true);
            app.clusterIDrunButton.Tag = 'run';
            app.clusterIDrunButton.Text = 'run';
            app.clusterIDparam2EditFieldLabel = uilabel(app.ClusterIDpanel);
            app.clusterIDparam2EditFieldLabel.Visible = 'off';
            app.clusterIDparam2EditFieldLabel.Text = 'ε';
            app.clusterIDparam2editField = uieditfield(app.ClusterIDpanel, 'numeric');
            app.clusterIDparam2editField.ValueChangedFcn = createCallbackFcn(app, @clusterIDparametersChanged, true);
            app.clusterIDparam2editField.Visible = 'off';
            app.clusterIDparam2editField.Value = 10;
            app.clusterIDparam1EditFieldLabel = uilabel(app.ClusterIDpanel);
            app.clusterIDparam1EditFieldLabel.Text = 'α';
            app.clusterIDparam1editField = uieditfield(app.ClusterIDpanel, 'numeric');
            app.clusterIDparam1editField.Limits = [0 Inf];
            app.clusterIDparam1editField.ValueDisplayFormat = '%6.1f';
            app.clusterIDparam1editField.ValueChangedFcn = createCallbackFcn(app, @clusterIDparametersChanged, true);
            app.clusterIDparam1editField.Value = 2;
            app.clusterIDminNeditFieldLabel = uilabel(app.ClusterIDpanel);
            app.clusterIDminNeditFieldLabel.Text = {'at least N'; 'tracks per cluster'};
            app.clusterIDminNeditField = uieditfield(app.ClusterIDpanel, 'numeric');
            app.clusterIDminNeditField.Limits = [3 200];
            app.clusterIDminNeditField.ValueDisplayFormat = '%d';
            app.clusterIDminNeditField.ValueChangedFcn = createCallbackFcn(app, @clusterIDparametersChanged, true);
            app.clusterIDminNeditField.Tooltip = {'Minimum number of tracks or localizations in a cluster to qualify it for further analysis.'};
            app.clusterIDminNeditField.Value = 5;
            app.segmentationMethodPanel = uipanel(app.ClusterIDpanel);
            app.segmentationMethodPanel.Title = 'segmentation method';
            app.segmentationMethodLabel = uilabel(app.segmentationMethodPanel);
            app.segmentationMethodLabel.Interpreter = 'latex';
            app.segmentationMethodLabel.Text = '$\rm area_{i} < area_{thresh}$';
            app.ClusterIDdropDown = uidropdown(app.ClusterIDpanel);
            app.ClusterIDdropDown.Items = {'Voronoi (density, track centroids)', 'Voronoi (area, track centroids)', 'Voronoi (density, localizations)', 'Voronoi (area, localizations)', 'DBSCAN (track centroids)', 'DBSCAN (localizations)', 'NASTIC (tracks)'};
            app.ClusterIDdropDown.ValueChangedFcn = createCallbackFcn(app, @ClusterIDdropDownValueChanged, true);
            app.ClusterIDdropDown.Value = 'Voronoi (density, track centroids)';
            app.ClusterStatusPanel = uipanel(app.ClusterAnalysisTab);
            app.ClusterStatusPanel.Title = 'status';
            app.ClusterStatusLabel = uilabel(app.ClusterStatusPanel);
            app.ClusterStatusLabel.Text = 'idle';
            app.ClusterEstPanel = uipanel(app.ClusterAnalysisTab);
            app.ClusterEstPanel.Title = 'parameter estimation';
            app.ClusterEstAxes = uiaxes(app.ClusterEstPanel);
            title(app.ClusterEstAxes, 'data vs. CSR (Monte Carlo simulations)')
            xlabel(app.ClusterEstAxes, 'Voronoi first-rank density (1/nm²)')
            ylabel(app.ClusterEstAxes, 'counts')
            zlabel(app.ClusterEstAxes, 'Z')
            app.ClusterEstTransferButton = uibutton(app.ClusterEstPanel, 'push');
            app.ClusterEstTransferButton.ButtonPushedFcn = createCallbackFcn(app, @ClusterEstTransferButtonPushed, true);
            app.ClusterEstTransferButton.Tag = 'transfer';
            app.ClusterEstTransferButton.Text = 'transfer';
            app.ClusterEstParameterEditFieldLabel = uilabel(app.ClusterEstPanel);
            app.ClusterEstParameterEditFieldLabel.Interpreter = 'tex';
            app.ClusterEstParameterEditFieldLabel.Text = 'estimation of \alpha';
            app.ClusterEstRunNsimulationsButton = uibutton(app.ClusterEstPanel, 'push');
            app.ClusterEstRunNsimulationsButton.ButtonPushedFcn = createCallbackFcn(app, @ClusterEstRunNsimulationsButtonPushed, true);
            app.ClusterEstRunNsimulationsButton.Tag = 'run estimation';
            app.ClusterEstRunNsimulationsButton.Text = 'run N simulations';
            app.ClusterEstParameterEditField = uieditfield(app.ClusterEstPanel, 'numeric');
            app.ClusterEstParameterEditField.ValueDisplayFormat = '%5.2f';
            app.ClusterEstParameterEditField.Editable = 'off';
            app.ClusterEstNSimulationsEditFieldLabel = uilabel(app.ClusterEstPanel);
            app.ClusterEstNSimulationsEditFieldLabel.Text = 'N =';
            app.ClusterEstNSimulationsEditField = uieditfield(app.ClusterEstPanel, 'numeric');
            app.ClusterEstNSimulationsEditField.Limits = [1 100];
            app.ClusterEstNSimulationsEditField.RoundFractionalValues = 'on';
            app.ClusterEstNSimulationsEditField.ValueDisplayFormat = '%d';
            app.ClusterEstNSimulationsEditField.Value = 100;
            app.cPlotPanel = uipanel(app.ClusterAnalysisTab);
            app.cPlotPanel.Title = 'cluster plotting options';
            app.cPlotAreasColorDropDownLabel = uilabel(app.cPlotPanel);
            app.cPlotAreasColorDropDownLabel.Text = 'areas';
            app.cPlotAreasColorDropDown = uidropdown(app.cPlotPanel);
            app.cPlotAreasColorDropDown.Items = {'red', 'green', 'yellow', 'blue', 'orange', 'cyan', 'magenta', 'teal', 'lavender', 'maroon'};
            app.cPlotAreasColorDropDown.ValueChangedFcn = createCallbackFcn(app, @gPlotParametersChanged, true);
            app.cPlotAreasColorDropDown.Value = 'red';
            app.cPlotAreasSemiTransparentCheckBox = uicheckbox(app.cPlotPanel);
            app.cPlotAreasSemiTransparentCheckBox.ValueChangedFcn = createCallbackFcn(app, @gPlotParametersChanged, true);
            app.cPlotAreasSemiTransparentCheckBox.Text = 'semi-transparent';
            app.cPlotBoundariesColorDropDownLabel = uilabel(app.cPlotPanel);
            app.cPlotBoundariesColorDropDownLabel.Text = 'boundaries';
            app.cPlotBoundariesColorDropDown = uidropdown(app.cPlotPanel);
            app.cPlotBoundariesColorDropDown.Items = {'red', 'green', 'yellow', 'blue', 'orange', 'cyan', 'magenta', 'teal', 'lavender', 'maroon'};
            app.cPlotBoundariesColorDropDown.ValueChangedFcn = createCallbackFcn(app, @gPlotParametersChanged, true);
            app.cPlotBoundariesColorDropDown.Value = 'red';
            app.cPlotBoundariesLineWidthEditFieldLabel = uilabel(app.cPlotPanel);
            app.cPlotBoundariesLineWidthEditFieldLabel.Text = 'line width';
            app.cPlotBoundariesLineWidthEditField = uieditfield(app.cPlotPanel, 'numeric');
            app.cPlotBoundariesLineWidthEditField.Limits = [0.5 3];
            app.cPlotBoundariesLineWidthEditField.ValueDisplayFormat = '%.1f';
            app.cPlotBoundariesLineWidthEditField.ValueChangedFcn = createCallbackFcn(app, @gPlotParametersChanged, true);
            app.cPlotBoundariesLineWidthEditField.Value = 0.5;
            app.cPlotShowAlgCheckBox = uicheckbox(app.cPlotPanel);
            app.cPlotShowAlgCheckBox.ValueChangedFcn = createCallbackFcn(app, @cPlotShowAlgCheckBoxValueChanged, true);
            app.cPlotShowAlgCheckBox.Text = 'show Voronoi cells';
            app.cPlotShowAlgCheckBox.Value = true;
            app.gPlotPanel = uipanel(app.ClusterAnalysisTab);
            app.gPlotPanel.Title = 'general plotting options';
            app.gPlotColorMapDropDownLabel = uilabel(app.gPlotPanel);
            app.gPlotColorMapDropDownLabel.Text = 'color map';
            app.gPlotColorMapDropDown = uidropdown(app.gPlotPanel);
            app.gPlotColorMapDropDown.Items = {'cycle 4 colors', 'cycle 6 colors', 'cycle 8 colors', 'cycle 10 colors'};
            app.gPlotColorMapDropDown.ValueChangedFcn = createCallbackFcn(app, @gPlotColorMapDropDownValueChanged, true);
            app.gPlotColorMapDropDown.Value = 'cycle 6 colors';
            app.gPlotColorSchemeDropDownLabel = uilabel(app.gPlotPanel);
            app.gPlotColorSchemeDropDownLabel.Text = 'color scheme';
            app.gPlotColorSchemeDropDown = uidropdown(app.gPlotPanel);
            app.gPlotColorSchemeDropDown.Items = {'random', 'duration', 'displacement', 'range', 'mobility (D)', 'mobility (logD)', 'immobile/mobile (logD)'};
            app.gPlotColorSchemeDropDown.ValueChangedFcn = createCallbackFcn(app, @gPlotColorSchemeDropDownValueChanged, true);
            app.gPlotColorSchemeDropDown.Value = 'random';
            app.gPlotBaseDataDropDownLabel = uilabel(app.gPlotPanel);
            app.gPlotBaseDataDropDownLabel.Text = 'base data';
            app.gPlotBaseDataDropDown = uidropdown(app.gPlotPanel);
            app.gPlotBaseDataDropDown.Items = {'localizations', 'track centroids', 'tracks'};
            app.gPlotBaseDataDropDown.ValueChangedFcn = createCallbackFcn(app, @gPlotBaseDataDropDownValueChanged, true);
            app.gPlotBaseDataDropDown.Value = 'localizations';
            app.gPlotCentroidsSizeEditFieldLabel = uilabel(app.gPlotPanel);
            app.gPlotCentroidsSizeEditFieldLabel.Text = ' point size';
            app.gPlotPointSizeEditField = uieditfield(app.gPlotPanel, 'numeric');
            app.gPlotPointSizeEditField.Limits = [0 30];
            app.gPlotPointSizeEditField.ValueDisplayFormat = '%d';
            app.gPlotPointSizeEditField.ValueChangedFcn = createCallbackFcn(app, @gPlotParametersChanged, true);
            app.gPlotPointSizeEditField.Value = 5;
            app.gPlotLineWidthEditFieldLabel = uilabel(app.gPlotPanel);
            app.gPlotLineWidthEditFieldLabel.Text = 'line width';
            app.gPlotLineWidthEditField = uieditfield(app.gPlotPanel, 'numeric');
            app.gPlotLineWidthEditField.Limits = [0 4];
            app.gPlotLineWidthEditField.ValueDisplayFormat = '%.1f';
            app.gPlotLineWidthEditField.ValueChangedFcn = createCallbackFcn(app, @gPlotParametersChanged, true);
            app.gPlotLineWidthEditField.Value = 0.5;
            app.gPlotScaleBarLabel = uilabel(app.gPlotPanel);
            app.gPlotScaleBarLabel.Text = 'scale bar';
            app.gPlotScaleBarDropDown = uidropdown(app.gPlotPanel);
            app.gPlotScaleBarDropDown.Items = {'none', 'bar (white)', 'full (white)', 'bar (black)', 'full (black)'};
            app.gPlotScaleBarDropDown.ValueChangedFcn = createCallbackFcn(app, @gPlotParametersChanged, true);
            app.gPlotScaleBarDropDown.Value = 'none';
            app.gPlotWhiteBGcheckBox = uicheckbox(app.gPlotPanel);
            app.gPlotWhiteBGcheckBox.ValueChangedFcn = createCallbackFcn(app, @gPlotParametersChanged, true);
            app.gPlotWhiteBGcheckBox.Text = 'white background';
            app.ClusterSavePanel = uipanel(app.ClusterAnalysisTab);
            app.ClusterSavePanel.Title = 'save';
            app.ClusterSaveImageButton = uibutton(app.ClusterSavePanel, 'push');
            app.ClusterSaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @ClusterSaveImageButtonPushed, true);
            app.ClusterSaveImageButton.Tag = 'save image';
            app.ClusterSaveImageButton.Text = 'save image';
            app.ClusterShowDataButton = uibutton(app.ClusterSavePanel, 'push');
            app.ClusterShowDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClusterShowDataButtonPushed, true);
            app.ClusterShowDataButton.Tag = 'show data';
            app.ClusterShowDataButton.Text = 'show data';
            app.gPlotLowerLimitEditFieldLabel = uilabel(app.ClusterAnalysisTab);
            app.gPlotLowerLimitEditFieldLabel.Visible = 'off';
            app.gPlotLowerLimitEditFieldLabel.Text = 'from';
            app.gPlotLowerLimitEditField = uieditfield(app.ClusterAnalysisTab, 'numeric');
            app.gPlotLowerLimitEditField.ValueDisplayFormat = '%5.2f';
            app.gPlotLowerLimitEditField.ValueChangedFcn = createCallbackFcn(app, @gPlotParametersChanged, true);
            app.gPlotLowerLimitEditField.Visible = 'off';
            app.gPlotUpperLimitEditFieldLabel = uilabel(app.ClusterAnalysisTab);
            app.gPlotUpperLimitEditFieldLabel.Visible = 'off';
            app.gPlotUpperLimitEditFieldLabel.Text = 'to';
            app.gPlotUpperLimitEditField = uieditfield(app.ClusterAnalysisTab, 'numeric');
            app.gPlotUpperLimitEditField.ValueDisplayFormat = '%5.2f';
            app.gPlotUpperLimitEditField.ValueChangedFcn = createCallbackFcn(app, @gPlotParametersChanged, true);
            app.gPlotUpperLimitEditField.Visible = 'off';
            app.gPlotUpperLimitEditField.Value = 1;
            app.BatchAnalysisTab = uitab(app.TabGroup);
            app.BatchAnalysisTab.Title = 'Batch Analysis';
            app.BatchAnalysisTab.Tag = 'batch tab';
            app.BatchOpenParameterAppButton = uibutton(app.BatchAnalysisTab, 'push');
            app.BatchOpenParameterAppButton.ButtonPushedFcn = createCallbackFcn(app, @LocsOpenParameterAppButtonPushed, true);
            app.BatchOpenParameterAppButton.Tag = 'open parameter window (batch)';
            app.BatchOpenParameterAppButton.Text = 'open parameter window';
            app.BatchStartButton = uibutton(app.BatchAnalysisTab, 'push');
            app.BatchStartButton.ButtonPushedFcn = createCallbackFcn(app, @BatchStartButtonPushed, true);
            app.BatchStartButton.Tag = 'start batch analysis';
            app.BatchStartButton.Text = 'start batch analysis';
            app.resultsoutputfileEditFieldLabel = uilabel(app.BatchAnalysisTab);
            app.resultsoutputfileEditFieldLabel.Text = 'results output file';
            app.BatchOutputFileEditField = uieditfield(app.BatchAnalysisTab, 'text');
            app.BatchOutputFileEditField.Tag = 'files outputFile';
            app.AnalysisstepsLabel = uilabel(app.BatchAnalysisTab);
            app.AnalysisstepsLabel.Text = 'Analysis steps';
            app.BatchTextArea = uitextarea(app.BatchAnalysisTab);
            app.BatchTextArea.Tag = 'general batchText';
            app.BatchNoiseFileTable = uitable(app.BatchAnalysisTab);
            app.BatchNoiseFileTable.ColumnName = {'noise file'; 'folder'; 'ID'};
            app.BatchNoiseFileTable.RowName = {};
            app.BatchNoiseFileTable.Tag = 'files noiseFiles';
            app.BatchInputFileTable = uitable(app.BatchAnalysisTab);
            app.BatchInputFileTable.ColumnName = {'input file'; 'folder'; 'noise file ID'; 'mask'};
            app.BatchInputFileTable.RowName = {};
            app.BatchInputFileTable.ColumnEditable = [false false true true];
            app.BatchInputFileTable.CellEditCallback = createCallbackFcn(app, @BatchInputFileTableCellEdit, true);
            app.BatchInputFileTable.Tag = 'files inputFiles';
            % Show the figure after all components are created
            app.MainUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OneFlowTraX

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainUIFigure)
        end
    end
end