classdef chooseBatchParams < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        saveButton             matlab.ui.control.Button
        loadButton             matlab.ui.control.Button
        Tree                   matlab.ui.container.Tree
        GeneralNode            matlab.ui.container.TreeNode
        analysisSteps          matlab.ui.container.TreeNode
        rawDataOutput          matlab.ui.container.TreeNode
        FilesNode              matlab.ui.container.TreeNode
        InputFilesNode         matlab.ui.container.TreeNode
        NoiseFilesNode         matlab.ui.container.TreeNode
        OutputFileNode         matlab.ui.container.TreeNode
        LocalizationNode       matlab.ui.container.TreeNode
        LocParametersNode      matlab.ui.container.TreeNode
        LocLimitsNode          matlab.ui.container.TreeNode
        TrackingNode           matlab.ui.container.TreeNode
        TrackParametersNode    matlab.ui.container.TreeNode
        TrackLimitsNode        matlab.ui.container.TreeNode
        MSDanalysisNode        matlab.ui.container.TreeNode
        MSDparametersNode      matlab.ui.container.TreeNode
        ClusterAnalysisNode    matlab.ui.container.TreeNode
        ClustParametersNode    matlab.ui.container.TreeNode
        chosenAlgNode          matlab.ui.container.TreeNode
        AlgParametersNode      matlab.ui.container.TreeNode
        EdgeTrimmingNode       matlab.ui.container.TreeNode
        OptionsPanel           matlab.ui.container.Panel
        removeSelectionButton  matlab.ui.control.Button
        ListBox                matlab.ui.control.ListBox
        DropDown               matlab.ui.control.DropDown
        DropDownLabel          matlab.ui.control.Label
        CheckBox               matlab.ui.control.CheckBox
        ButtonGroup            matlab.ui.container.ButtonGroup
        RadButton1             matlab.ui.control.RadioButton
        RadButton2             matlab.ui.control.RadioButton
        UITable                matlab.ui.control.Table
        FileButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        hAll % Handle for all objects in the Options Panel
        lastDirectory = string.empty % Last directory the user has chosen.
        goPos % Original positions of graphic objects
              %  .UITable
              %  .ButtonGroup
              %  .CheckBox
              %  .ListBox
        isShortNames = true % checks if the user checked short names previously
    end
    
    properties (Access = public)
        S % Structure holding all parameters in memory.
        CallingApp % Main app that has called this one.
    end
    
    methods (Access = private)

        function showListBoxItems(app)
            if app.isShortNames == true
                app.CheckBox.Value = true;
                [~, app.ListBox.Items] = ...
                    cellfun(@fileparts, app.ListBox.ItemsData, 'uni', 0);
            else
                app.CheckBox.Value = false;
                app.ListBox.Items = app.ListBox.ItemsData;
            end
        end
        
        function getDefaultS(app)
            % This function will not be called in this app, it is here just for reference how the parameter structure
            % looks like. This function will only be executed in the calling app.
            app.S = struct;
            T = table('Size', [4 1], 'VariableTypes',{'logical'},'VariableNames',{'doAnalysis'},...
                'RowNames',{'localization','tracking','MSD analysis','cluster analysis'});
            T{:,:} = true(4,1);
            app.S.general.analysisSteps = T;
            T = table('Size', [2 2], 'VariableTypes',{'logical','string'},...
                'VariableNames',{'raw data output','file suffix'},...
                'RowNames',{'localization','tracking'});
            T(:,:) = {false, "_locs"; false, "_tracks"};
            app.S.general.rawDataOutput = T;
            app.S.files.inputFiles = cell(1,0);
            app.S.files.noiseFiles = cell(1,0);
            app.S.files.outputFile = '';
            app.S.localization.varianceMap = zeros(128, 'single');
            app.S.localization.offset = 100;
            app.S.localization.conversion = 0.48;
            app.S.localization.pixelSize = 100;
            app.S.localization.filterSize = 1.3;
            app.S.localization.cutoff = 3;
            app.S.localization.PSFROI = 7;
            T = table('Size', [3 2], 'VariableTypes',{'double','double'}, ...
                'VariableNames',{'lower limit','upper limit'},...
                'RowNames',{'PSF','photons','precision'});
            T(:,:) = {0 Inf;0 Inf;0 Inf};
            app.S.localization.limits = T;
            app.S.tracking.frameRate = 50;
            app.S.tracking.maxGapClose = 4;
            app.S.tracking.maxLinkDistance = 150;
            T = table('Size', [4 2], 'VariableTypes',{'double','double'}, ...
                'VariableNames',{'lower limit','upper limit'},...
                'RowNames',{'N', 'duration','displacement','range'});
            T(:,:) = {3 Inf;0 Inf;0 Inf; 0 Inf};
            app.S.tracking.limits = T;
            app.S.MSDanalysis.minTrackLength = 8;
            app.S.MSDanalysis.adjR2 = 0.4;
            app.S.MSDanalysis.linFitPoints = [2 4];
            app.S.MSDanalysis.splitValue = -1.75;
            app.S.cluster.algorithm = 'Voronoi';
            app.S.cluster.input = 'track centroids';
            app.S.cluster.minN = 5;
            app.S.cluster.Voronoi.method = 'density';
            app.S.cluster.Voronoi.alpha = 2;
            app.S.cluster.Voronoi.areaThreshold = 20;
            app.S.cluster.Voronoi.trimEdges = true;
            app.S.cluster.Voronoi.trimBoundary = 'AABB';
            app.S.cluster.Voronoi.trimMethod = 'intersect';
            app.S.cluster.DBSCAN.minPts = 4;
            app.S.cluster.DBSCAN.estimateEpsilon = true;
            app.S.cluster.DBSCAN.epsilon = 280;
            app.S.cluster.NASTIC.r = 1;
            app.S.cluster.segNASTIC.r = 1;
            app.S.cluster.segNASTIC.nSegmentOverlaps = 2;
        end
        
        function updateAnalysisSteps(app)
            % Some analysis steps are interdependent, e.g., the MSD analysis cannot be done without
            % tracking.
            doAnalysis = app.S.general.analysisSteps.doAnalysis;

            % Localization (1) will always be done (even if the user tries to uncheck it).
            doAnalysis(1) = true;

            % Although cluster analysis (4) can also be performed using just the localizations,
            % tracking (2) will be checked as well. In the actual batch analysis, the tracking
            % procedure will then be skipped. The MSD analysis (3), however, is truly dependent on
            % tracking.
            doAnalysis(2) = doAnalysis(2) | doAnalysis(3) | doAnalysis(4);

            % Update the batch structure with the (possibly corrected) selection.
            app.S.general.analysisSteps.doAnalysis = doAnalysis;
            if strcmp(app.UITable.Tag, 'Analysis Steps')
                % Also update the table if the action was triggered by the user (it can also be
                % triggered by loading a new batch analysis structure).
                app.UITable.Data.doAnalysis = doAnalysis;
            end

            % Populate the tree with the selected analysis nodes.
            % Tracking:
            if xor(doAnalysis(2), contains([app.Tree.Children.Text],'Tracking'))
                if doAnalysis(2)
                    app.TrackingNode = uitreenode(app.Tree, app.LocalizationNode, "after", ...
                        "Text", 'Tracking');
                    app.TrackParametersNode = uitreenode(app.TrackingNode, "Text", 'Parameters');
                    app.TrackLimitsNode = uitreenode(app.TrackingNode, "Text", 'Limits');
                    expand(app.TrackingNode);
                else
                    delete(app.TrackingNode);
                end
            else
                % Do nothing, the step choice is already reflected in the tree.
            end    

            % MSD Analysis:
            if xor(doAnalysis(3), contains([app.Tree.Children.Text],'MSD Analysis'))
                if doAnalysis(3)
                    app.MSDanalysisNode = uitreenode(app.Tree, app.TrackingNode, "after", ...
                        "Text", 'MSD Analysis');
                    app.MSDparametersNode = uitreenode(app.MSDanalysisNode, "Text", 'Parameters');
                    expand(app.MSDanalysisNode);
                else
                    delete(app.MSDanalysisNode);
                end
            else
                % Do nothing, the step choice is already reflected in the tree.
            end

            % Cluster Analysis:
            if xor(doAnalysis(4), contains([app.Tree.Children.Text],'Cluster Analysis'))
                if doAnalysis(4)
                    app.ClusterAnalysisNode = uitreenode(app.Tree, "Text", 'Cluster Analysis');
                    app.ClustParametersNode = uitreenode(app.ClusterAnalysisNode, ...
                        "Text", 'Parameters');
                    algorithm = app.S.cluster.algorithm;
                    app.chosenAlgNode = uitreenode(app.ClusterAnalysisNode, "Text", algorithm);
                    app.AlgParametersNode = uitreenode(app.chosenAlgNode, "Text", 'Parameters');
                    if strcmp(algorithm, 'Voronoi')
                        app.EdgeTrimmingNode = uitreenode(app.chosenAlgNode, "Text", 'Edge Trimming');
                    end
                    expand(app.ClusterAnalysisNode, 'all');
                else
                    delete(app.ClusterAnalysisNode);
                end
            else
                % Do nothing, the step choice is already reflected in the tree.
            end             
        end

        function updateBatchText(app)
            % Updates the text that describes the chosen analysis steps.

            % The first line just gives the number of files to be processed.
            firstLine = sprintf("batch processing of %u files, with:", numel(app.S.files.inputFiles));

            % Get the analysis steps chosen the user, add numbering in front.
            mainSteps = ["localization";"tracking";"MSD analysis";"cluster analysis"];
            chosenSteps = mainSteps(app.S.general.analysisSteps.doAnalysis);
            chosenSteps = compose("%u. ",(1:numel(chosenSteps))') + chosenSteps;

            clusterAlg = app.S.cluster.algorithm;
            if strcmp(clusterAlg,'Voronoi')
                clusterAlg = sprintf('%s (%s)',clusterAlg, app.S.cluster.Voronoi.method);
            end

            % Empty line if no cluster analysis was specified.
            if contains(chosenSteps(end),"cluster analysis")
                clusterSubline = sprintf("    %s, based on %s", clusterAlg, app.S.cluster.input);
            else
                clusterSubline = string.empty;
            end

            % Additional lines for output of lists.
            outputLines = [...
                sprintf("localization lists (...%s.dat)", app.S.general.rawDataOutput.("file suffix")(1)); ...
                sprintf("track lists (...%s.dat)", app.S.general.rawDataOutput.("file suffix")(2))];

            % Empty line if no additional list are requested.
            if any(app.S.general.rawDataOutput.("raw data output"))
                outputLines = [""; "additional output:"; ...
                    outputLines(app.S.general.rawDataOutput.("raw data output"))];
            else
                outputLines = string.empty;
            end

            % Put it all together for the batch text.
            app.S.general.batchText = [firstLine;chosenSteps;clusterSubline;outputLines];
        end
    end
    
    methods (Access = public)
        
        function resetTree(app, S)
            % Resets the tree using the current data. This happens, for example, when a parameter is
            % changed in the calling app. The updated data will be handed over as the S structure
            % and the tree is reset (so it can then be freshly populated with the new parameters
            % when the user clicks the corresponding nodes). The only exception is the Voronoi
            % algorithm that will have an extra node for the edge trimming that needs to be written
            % into the main tree.
            app.S = S;
            algorithm = app.S.cluster.algorithm;
            app.chosenAlgNode.Text = algorithm;
            delete(app.chosenAlgNode.Children);
            app.AlgParametersNode = uitreenode(app.chosenAlgNode, "Text", 'Parameters');
            if strcmp(algorithm, 'Voronoi')
                app.EdgeTrimmingNode = uitreenode(app.chosenAlgNode, "Text", 'Edge Trimming');
            end

            % Expand all nodes, do not select any nodes.
            expand(app.chosenAlgNode);
            app.Tree.SelectedNodes = [];

            % Hide all objects in the options window.
            set(app.hAll, 'Visible', 'off');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, S, lastDirectory)
            % Store the main app object that calls this app.
            app.CallingApp = caller;

            % Overwrite the S structure in this app with the one provided by the calling app.
            app.S = S;

            % Store the last directory provided by the calling app.
            app.lastDirectory = lastDirectory;
                       
            % Format the main nodes in bold and expand the tree. 
            addStyle(app.Tree, uistyle("FontWeight","bold"), "level", 1);
            expand(app.Tree, 'all');

            % Collect a handle for all objects in the Options panel.
            app.hAll = findobj(app.OptionsPanel,'-not', ...
                {'Type','uipanel','-or','Type','uiradiobutton'});

            % Store the positions of the graphic object in the options panel.
            app.goPos.UITable = app.UITable.Position;
            app.goPos.CheckBox = app.CheckBox.Position;
            app.goPos.ListBox = app.ListBox.Position;
            app.goPos.ButtonGroup = app.ButtonGroup.Position;

            % Reset the tree.
            resetTree(app, app.S);

            % Create the batch text for the currently set analysis steps.
            updateBatchText(app);
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            % First, make all objects invisible and reset all object positions.
            set(app.hAll, 'Visible', 'off'); drawnow
            app.UITable.Position = app.goPos.UITable;
            app.ListBox.Position = app.goPos.ListBox;
            app.CheckBox.Position = app.goPos.CheckBox;
            app.ButtonGroup.Position = app.goPos.ButtonGroup;

            % Make the 'Parameters' option unique.
            if strcmp(app.Tree.SelectedNodes.Text, 'Parameters')
                switch app.Tree.SelectedNodes.Parent.Text
                    case 'Localization'
                        chosenNode = 'LocParameters';
                    case 'Tracking'
                        chosenNode = 'TrackParameters';
                    case 'Cluster Analysis'
                        chosenNode = 'ClustParameters';
                    case 'MSD Analysis'
                        chosenNode = 'MSDparameters';
                    otherwise
                        % This must belong to the chosen algorithm.
                        chosenNode = 'AlgParameters';
                end
            else
                chosenNode = app.Tree.SelectedNodes.Text;
            end

            % Make the 'Limits' option unique.
            if strcmp(app.Tree.SelectedNodes.Text, 'Limits')
                switch app.Tree.SelectedNodes.Parent.Text
                    case 'Localization'
                        chosenNode = 'LocLimits';
                    case 'Tracking'
                        chosenNode = 'TrackLimits';
                end
            end
            
            switch chosenNode
                case 'Analysis Steps'
                    app.UITable.Tag = chosenNode;
                    app.UITable.Position = app.goPos.UITable + 47*[0 1 0 -1];
                    app.UITable.Tooltip = ...
                        "Choose the analyses that are performed during batch processing. Only " + ...
                        "the selected options will be executed (and their results written in " + ...
                        "the output file), even when they are still present in the selection " + ...
                        "tree. Required options will be auto-selected.";

                    % Restore the previously chosen analyses.
                    app.UITable.RowName = {'localization'; 'tracking'; 'MSD analysis';...
                        'cluster analysis'};
                    app.UITable.ColumnName = {};
                    app.UITable.Data = app.S.general.analysisSteps;
                    app.UITable.ColumnWidth = 'fit'; drawnow
                    app.UITable.ColumnWidth = '1x';

                    app.UITable.Visible = "on";

                case 'Raw Data Output'
                    app.UITable.Tag = chosenNode;
                    app.UITable.Position = app.goPos.UITable + 66*[0 1 0 -1];
                    app.UITable.Tooltip = ...
                        "Choose which raw data sets are stored during processing (e.g., to " + ...
                        "make the intermediate localization data accessible for additional " + ...
                        "analyses). The data will be stored as .txt files in the same folder as " + ...
                        "the input files, using their names as templates with the appended " + ...
                        "specified file suffix.";

                    % Restore the previously chosen raw data outputs and their suffixes.
                    app.UITable.RowName = {'localization'; 'tracking'};
                    app.UITable.ColumnName = ["", "file suffix"];
                    app.UITable.Data = app.S.general.rawDataOutput;
                    app.UITable.ColumnWidth = 'fit'; drawnow
                    app.UITable.ColumnWidth = '1x';

                    app.UITable.Visible = "on";

                case 'Input Files'
                    app.FileButton.Text = 'Add files';
                    app.CheckBox.Text = 'short names';
                    app.CheckBox.Tag = chosenNode;
                    app.FileButton.Tag = chosenNode;
                    app.FileButton.Tooltip = ...
                        "Adds files that will be batch processed. All of the files must have " + ...
                        "been acquired using the same parameters. They will be processed in " + ...
                        "the order they were added. Files added multiple times are included " + ...
                        "only once.";
                    app.removeSelectionButton.Tooltip = ...
                        "Removes the selected input files from the list.";

                    % Restore the list of previously chosen files.
                    app.ListBox.ItemsData = app.S.files.inputFiles;
                    showListBoxItems(app);
                    app.ListBox.Position = app.goPos.ListBox + [0 -234 110 +284];
                    app.ListBox.Visible = 'on';
                    app.CheckBox.Visible = 'on';
                    app.FileButton.Visible = 'on';
                    app.removeSelectionButton.Visible = 'on';

                    % Check if files are present in the list and enable/disable the 'remove selection' button
                    % accordingly.
                    app.removeSelectionButton.Enable = ~isempty(app.ListBox.ItemsData);

                case 'Noise Files'
                    app.FileButton.Text = 'Add files';
                    app.CheckBox.Text = 'short names';
                    app.CheckBox.Tag = chosenNode;
                    app.FileButton.Tag = chosenNode;
                    app.FileButton.Tooltip = ...
                        "Adds noise files for the input files that will batch processed " + ...
                        "(the assignment will be done later in the main app). They can " + ...
                        "be either a TIFF stacks containing a set of dark images, or " + ...
                        "single-image TIFF files where each pixel carries the value for the " + ...
                        "corresponding (previously calculated) variance. Files added multiple " + ...
                        "times are included only once.";
                    app.removeSelectionButton.Tooltip = ...
                        "Removes the selected noise files from the list.";

                    % Restore the list of previously chosen files.
                    app.ListBox.ItemsData = app.S.files.noiseFiles;
                    showListBoxItems(app);
                    app.ListBox.Position = app.goPos.ListBox + [0 -234 110 +284];
                    app.ListBox.Visible = 'on';
                    app.CheckBox.Visible = 'on';
                    app.FileButton.Visible = 'on';
                    app.removeSelectionButton.Visible = 'on';

                    % Check if files are present in the list and enable/disable the 'remove selection' button
                    % accordingly.
                    app.removeSelectionButton.Enable = ~isempty(app.ListBox.ItemsData);

                case 'Output File'
                    app.FileButton.Text = 'Choose output file';
                    app.CheckBox.Text = 'short names';
                    app.CheckBox.Tag = chosenNode;
                    app.FileButton.Tag = chosenNode;
                    app.FileButton.Tooltip = ...
                        "Sets the name and location of the output Excel file that will " + ...
                        "contain all relevant metadata and analysis results for each " + ...
                        "processed input file.";

                    % Restore the previously chosen file.
                    if isempty(app.S.files.outputFile)
                        app.ListBox.ItemsData = cell(0,1);
                    else
                        app.ListBox.ItemsData = cellstr(app.S.files.outputFile);
                    end
                    showListBoxItems(app);
                    app.ListBox.Position = app.goPos.ListBox + [0 31 110 19];
                    app.FileButton.Visible = 'on';
                    app.CheckBox.Visible = 'on';
                    app.ListBox.Visible = 'on';

                case 'LocParameters'
                    app.UITable.Tag = chosenNode;
                    app.UITable.Position = app.goPos.UITable;

                    app.UITable.Tooltip = [...
                        "offset (ADU): offset of the camera (without any light) that prevents " + ...
                        "negative values due to natural read noise fluctuations."; ...
                        "conversion (e-/ADU): factor to convert ADU into photoelectrons " + ...
                        "(reciprocal of the average pixel gain)."; ...
                        "pixel size (nm): length in the sample plane that is projected onto " + ...
                        "one camera pixel in the detection plane."; ...
                        "filter size (pixel): sigma of the Gaussian in the DoG filter to " + ...
                        "enhance the contrast for the basic detection of bright spots in each image."; ...
                        "cut-off (photons): minimal value for the brightest pixel in a spot " + ...
                        "to be included in the localization procedure."; ...
                        "PSF ROI size (pixel): width of the ROI square with which each " + ...
                        "eligible spot is cut out and processed further. Too small values may " + ...
                        "not capture the entire PSF, too large values may capture more than " + ...
                        "one. Must be an odd number."];
                    
                    % Restore the previously chosen localization parameters.
                    app.UITable.RowName = ...
                        {'offset (ADU)';'conversion (e-/ADU)';'pixel size (nm)'; ...
                        'filter size (pixel)';'cut-off (photons)';'PSF ROI size (pixel)'};
                    app.UITable.ColumnName = {};
                    L = struct2cell(app.S.localization);
                    app.UITable.Data = L(2:7);
                    app.UITable.ColumnWidth = 'fit'; drawnow
                    app.UITable.ColumnWidth = '1x';

                    app.UITable.Visible = "on";

                case 'LocLimits'
                    app.UITable.Tag = chosenNode;
                    app.UITable.Tooltip = [...
                        "Lower and upper property limits for single localization to be included " + ...
                        "in further analysis steps."; ...
                        "PSF (nm): sigma (both x and y) in nm of the 2D Gaussian fit."; ...
                        "photons: integrated number of photons calculated for each spot."; ...
                        "precision (nm): localization precision in nm calculated using the " + ...
                        "Cramér–Rao lower bound and the MLE algorithm."];

                    app.UITable.Position = app.goPos.UITable + 30*[0 1 0 -1];
                    app.UITable.RowName = ...
                        {'PSF (nm)';'photons';'precision (nm)'};
                    app.UITable.ColumnName = ...
                        ["lower" + newline + "limit"; "upper" + newline + "limit"];
                    app.UITable.Data = app.S.localization.limits;
                    app.UITable.ColumnWidth = 'fit'; drawnow
                    app.UITable.ColumnWidth = '1x';

                    app.UITable.Visible = "on";

                case 'TrackParameters'
                    app.UITable.Tag = chosenNode;
                    app.UITable.Position = app.goPos.UITable + 93*[0 1 0 -1];
                    app.UITable.Tooltip = [...
                        "max. linking distance (nm): maximum distance between two fluorophore positions in"; ...
                        "two consecutive frames to be assigned to the same track in nm."; ...
                        "max. gap closing (frames): maximum number of frames to look ahead for a " + ...
                        "fluorophore position to connect it to a previous one found in the same vicinity."]; 

                    % Restore the previously chosen tracking parameters.
                    app.UITable.RowName = {'max. linking distance (nm)'; 'max. gap closing (frames)'};
                    app.UITable.ColumnName = {};
                    T = struct2cell(app.S.tracking);
                    app.UITable.Data = T(1:2);
                    app.UITable.ColumnWidth = 'fit'; drawnow
                    app.UITable.ColumnWidth = '1x';

                    app.UITable.Visible = "on";

                case 'TrackLimits'
                    app.UITable.Tag = chosenNode;
                    app.UITable.Tooltip = [...
                        "Lower and upper property limits for tracks to be included in"; ...
                        "further analysis steps."; ...
                        "N: number of points the track consists of (lower limit: 3)."; ...
                        "duration (s): duration (incl. gaps) of the track in seconds."; ...
                        "displacement (nm): distance between the first and last point in nm."; ...
                        "range (nm): range estimation by encompassing all points of a track"; ...
                        "with a rectangle and calculating the length of its diagonal in nm."];

                    app.UITable.Position = app.goPos.UITable + 7*[0 1 0 -1];
                    app.UITable.RowName = ...
                        {'N','duration (s)','displ. (nm)','range (nm)'};
                    app.UITable.ColumnName = ...
                        ["lower" + newline + "limit"; "upper" + newline + "limit"];
                    app.UITable.Data = app.S.tracking.limits;
                    app.UITable.ColumnWidth = 'fit'; drawnow
                    app.UITable.ColumnWidth = '1x';

                    app.UITable.Visible = "on";

                case 'MSDparameters'
                    app.CheckBox.Tag = chosenNode;
                    app.CheckBox.Position = app.goPos.CheckBox - [123 228 0 0];
                    app.CheckBox.Text = 'exclude first point of linear fit';
                    app.CheckBox.Value = ~isscalar(app.S.MSDanalysis.linFitPoints);
                    app.CheckBox.Tooltip = "This excludes the first point from the linear fit. " + ...
                        "For example, with N = 4, only the points 2, 3 and 4 will be included " + ...
                        "in the linear fit. This is often done as this time interval shows " + ...
                        "complex behavior (Kusumi, A. et al., Biophys. J. 65, 2021-2040 (1993)).";

                    app.UITable.Tag = chosenNode;
                    app.UITable.Position = app.goPos.UITable + 24*[0 1 0 -1];
                    app.UITable.Tooltip = [...
                        "frame rate (Hz): frequency (in frames per second) at which consecutive " + ...
                        "images were acquired."; ...
                        "min. track length: minimum track length (in frames, including gaps) " + ...
                        "to qualify for MSD analysis."; ...
                        "min. adj. R²: minimum adjusted R² value for the linear fit to " + ...
                        "qualify its results (i.e., the diffusion coefficient) for subsequent " + ...
                        "analyses."; ...
                        "N points for linear fits: Only the first N points will be included in " + ...
                        "the linear fit of individual MSD curves. If a track's MSD curves misses " + ...
                        "any of these points, it will be discarded entirely."; ...
                        "split value: log10 value of the diffusion coefficient that separates " + ...
                        "the slower from the faster fraction of diffusing molecules."];

                    % Restore the previously chosen tracking parameters.
                    app.UITable.RowName = {'frame rate'; 'min. track length'; 'min. adj. R²'; ...
                        'N points for linear fits'; 'splitValue'};
                    app.UITable.ColumnName = {};
                    M = struct2cell(app.S.MSDanalysis);
                    M{4}=M{4}(end);
                    app.UITable.Data = M;
                    app.UITable.ColumnWidth = 'fit'; drawnow
                    app.UITable.ColumnWidth = '1x';

                    app.UITable.Visible = "on";
                    app.CheckBox.Visible = "on";

                case 'ClustParameters'
                    app.UITable.Tag = chosenNode;
                    app.UITable.Position = app.goPos.UITable + 117*[0 1 0 -1];
                    choiceString = {'locs', 'tracks'};
                    app.UITable.RowName = sprintf('at least N %s per cluster', ...
                        choiceString{strcmp(app.S.cluster.input, 'tracks') + 1});
                    app.UITable.ColumnName = {};
                    app.UITable.Data = app.S.cluster.minN;
                    app.UITable.ColumnWidth = 'fit'; drawnow
                    app.UITable.ColumnWidth = '1x';
                    app.UITable.Tooltip = "Minimum number of tracks or localizations in a " + ...
                        "cluster to qualify it for further analysis.";
                    
                    app.DropDown.Tag = chosenNode;
                    app.DropDown.Items = {'Voronoi','DBSCAN','NASTIC'};
                    %app.DropDown.Items = {'Voronoi','DBSCAN','NASTIC','segNASTIC'};
                    app.DropDownLabel.Text = 'algorithm';
                    app.DropDown.Value = app.S.cluster.algorithm;
                    app.DropDown.Tooltip = "Choose an algorithm to identify clusters.";
                   
                    app.ButtonGroup.Tag = chosenNode;
                    app.ButtonGroup.Title = 'input';
                    set(app.ButtonGroup.Children, {'Text'}, {'track centroids';'localizations'});
                    app.RadButton1.Value = strcmp(app.S.cluster.input, 'track centroids');
                    app.ButtonGroup.Tooltip = "Choose the data for the cluster " + ...
                        "analysis. ""track centroids"" will use the track centroids as the input, " + ...
                        "while ""localizations"" will use all localizations.";

                    app.UITable.Visible = "on";
                    app.DropDown.Visible = "on";
                    app.DropDownLabel.Visible = "on";
                    app.ButtonGroup.Visible = ~contains(app.DropDown.Value, 'NASTIC');

                case 'AlgParameters'
                    switch app.S.cluster.algorithm
                        case 'Voronoi'
                            app.ButtonGroup.Tag = [chosenNode,'Voronoi'];
                            app.ButtonGroup.Position(1) = app.goPos.ButtonGroup(1) - 107;
                            app.ButtonGroup.Title = 'find clusters by';                            
                            set(app.ButtonGroup.Children, {'Text'}, {'density';'area'});
                                                        
                            app.UITable.Tag = [chosenNode,'Voronoi'];
                            app.UITable.Position = app.goPos.UITable + 117*[0 1 0 -1];

                            % Restore the previously chosen algorithm parameters for Voronoi
                            % tessellation.
                            if strcmp(app.S.cluster.Voronoi.method, 'density')
                                app.RadButton1.Value = true;
                                app.UITable.RowName = {'alpha'};
                                app.UITable.ColumnName = {};
                                app.UITable.Data = app.S.cluster.Voronoi.alpha;
                                app.UITable.Tooltip = "Only those Voronoi cells with a first-rank " + ...
                                    "density above ""alpha"" times the average density are " + ...
                                    "potentially part of a cluster";
                            else
                                app.RadButton2.Value = true;
                                app.UITable.RowName = {'area threshold (µm²)'};
                                app.UITable.ColumnName = {};
                                app.UITable.Data = app.S.cluster.Voronoi.areaThreshold;
                                app.UITable.Tooltip = "Only those Voronoi cells with an area " + ...
                                    "below this threshold value in µm² are potentially part " + ...
                                    "of a cluster";
                            end
                            app.UITable.ColumnWidth = 'fit'; drawnow
                            app.UITable.ColumnWidth = '1x';

                            app.UITable.Visible = "on";
                            app.ButtonGroup.Visible = "on";

                        case 'DBSCAN'
                            app.UITable.Tag = [chosenNode,'DBSCAN'];
                            app.UITable.Tooltip = [...
                                "minimum points: minimum number of neighbors within " + ...
                                "a circle around the query point with radius " + char(949) + ...
                                ", including the query point."; ...
                                char(949) + " (nm): radius of the neighborhood around each " + ...
                                "query point in nm."];

                            % Restore the previously chosen algorithm parameters for DBSCAN.
                            app.UITable.RowName = {'minimum points'; [char(949), ' (nm)']};
                            app.UITable.ColumnName = {};
                            app.UITable.Position = app.goPos.UITable + 93*[0 1 0 -1];
                            app.UITable.Data = {app.S.cluster.DBSCAN.minPts; app.S.cluster.DBSCAN.epsilon};

                            app.UITable.ColumnWidth = 'fit'; drawnow
                            app.UITable.ColumnWidth = '1x';

                            app.UITable.Visible = "on";

                        case 'NASTIC'
                            app.UITable.Tag = [chosenNode,'NASTIC'];
                            app.UITable.Tooltip = ...
                                "radius factor: factor that increases each bounding box (around a circle with " + ...
                                "the same area as the convex hull of the corresponding track) to better " + ...
                                "represent odd track shapes.";
                            app.UITable.RowName = 'radius factor';
                            app.UITable.ColumnName = {};
                            app.UITable.Position = app.goPos.UITable + 117*[0 1 0 -1];
                            app.UITable.Data = {app.S.cluster.NASTIC.r};

                            app.UITable.ColumnWidth = 'fit'; drawnow
                            app.UITable.ColumnWidth = '1x';

                            app.UITable.Visible = "on";

                        case 'segNASTIC'
                            app.UITable.Tag = [chosenNode,'SegNASTIC'];
                            app.UITable.Tooltip = [...
                                "radius factor: factor that increases each bounding box (around a circle with " + ...
                                "the same area as the convex hull of the corresponding track) to better " + ...
                                "represent odd track shapes.";
                                "N segment overlaps: number of other segments one track segment has to overlap" + ...
                                "with (excluding internal track segment overlaps) to be part of a potential cluster"];
                                app.UITable.RowName = {'radius factor';'N segment overlaps'};
                                app.UITable.ColumnName = {};
                                app.UITable.Position = app.goPos.UITable + 93*[0 1 0 -1];
                                app.UITable.Data = {app.S.cluster.segNASTIC.r; ...
                                    app.S.cluster.segNASTIC.nSegmentOverlaps};

                            app.UITable.ColumnWidth = 'fit'; drawnow
                            app.UITable.ColumnWidth = '1x';

                            app.UITable.Visible = "on";

                        otherwise
                    end

                case 'Edge Trimming'
                    app.DropDown.Tag = chosenNode;
                    app.DropDownLabel.Text = 'boundary';
                    app.DropDown.Items = {'convhull', 'AABB', 'manual'};
                    if isnumeric(app.S.cluster.Voronoi.trimBoundary)
                        app.DropDown.Value = 'manual';
                    else
                        app.DropDown.Value = app.S.cluster.Voronoi.trimBoundary;
                    end
                    app.DropDown.Tooltip = [...
                        "convhull: a convex hull around all data points."; ...
                        "AABB: an axis-aligned bounding box that envelops all data points."; ...
                        "manual: user-defined limits that can be entered below."];

                    app.UITable.Tag = chosenNode;
                    app.UITable.RowName = {'x (nm)';'y (nm)'};
                    app.UITable.ColumnName = ["lower limit"; "upper limit"];
                    app.UITable.Data = num2cell(zeros(2));
                    app.UITable.Tooltip = "Lower and upper limits of the user-defined " + ...
                            "boundary rectangle, in the coordinate system of the data points.";

                    app.ButtonGroup.Tag = chosenNode;
                    app.ButtonGroup.Title = 'method';
                    
                    % Note that both 'intersect' and 'remove' are set as cell arrays. This is a
                    % workaround as 'remove' is a keyword and causes problems if not "hidden" in a
                    % cell array when using >set<. Accordingly, the text must be read out using {:}
                    % to unpack the cell array.
                    set(app.ButtonGroup.Children, {'Text'}, {{'remove'};{'intersect'}});
                    app.RadButton1.Value = strcmp(app.S.cluster.Voronoi.trimMethod, 'remove');

                    app.CheckBox.Tag = chosenNode;
                    app.CheckBox.Position(1) = app.goPos.CheckBox(1) - 120;
                    app.CheckBox.Text = 'trim Edges';
                    app.CheckBox.Tooltip = "Voronoi cells at the outer edges often extend " + ...
                        "beyond the original image dimensions. When ""trim Edges"" is selected, " + ...
                        "a boundary can be chosen to either remove all Voronoi cells that " + ...
                        "cross this boundary, or they can be intersected with this boundary, " + ...
                        "replacing them with clipped cells. When ""trim Edges"" is deselected, " + ...
                        "only Voronoi cells with inifinite areas will be disregarded in " + ...
                        "the cluster analysis";

                    % Check if trimming the edges was activated previously and set the visibility of
                    % the other elements accordingly.
                    app.CheckBox.Value = app.S.cluster.Voronoi.trimEdges;
                    [app.ButtonGroup.Visible, app.DropDown.Visible, app.DropDownLabel.Visible] = ...
                        deal(app.CheckBox.Value);

                    % Overwrite the default boundary limits with the previously chosen ones if they
                    % were stored as a numeric vector.
                    if isnumeric(app.S.cluster.Voronoi.trimBoundary)
                        app.UITable.Data = num2cell(reshape(app.S.cluster.Voronoi.trimBoundary,2,2)');
                    end

                    % Display the table only if trimming is activated and the manual option is
                    % selected.
                    if app.S.cluster.Voronoi.trimEdges && strcmp(app.DropDown.Value, 'manual')
                        app.UITable.Position = app.goPos.UITable + 66*[0 1 0 -1];
                        app.UITable.ColumnWidth = 'fit'; drawnow
                        app.UITable.ColumnWidth = '1x';
                        app.UITable.Visible = "on";
                    end

                    app.CheckBox.Visible = "on";                  

                otherwise
                    % Empty.
            end
        
        end

        % Button pushed function: FileButton
        function FileButtonPushed(app, event)
            switch app.FileButton.Tag
                case 'Input Files'
                    % Request one or more files to add to the current list.
                    [filename, pathname] = uigetfile({'*.tif';'*.TIF'}, ...
                        'Select SMLM file(s)', app.lastDirectory, 'MultiSelect','on');

                    % MATLAB app glitch: use this to return focus to the main window and also the calling app.
                    figure(app.CallingApp.MainUIFigure);
                    figure(app.UIFigure);

                    % Special handling of the input dialog results.
                    if isequal(filename,0) % User selected Cancel or closed the window.
                        return
                    elseif ~iscell(filename) %  User selected only one file.
                        filename = {filename};
                    end

                    % Store the current path.
                    app.lastDirectory = pathname;

                    % Build the full filenames.
                    fullFilenames = cellfun(@(x) fullfile(pathname, x), filename, 'uni', 0);

                    % Check the file integrity.
                    warning('off','imageio:tiffutils:libtiffWarning');
                    isReadable = false(size(fullFilenames));
                    for iFile = 1:numel(isReadable)
                        try
                            t = Tiff(fullFilenames{iFile});
                            close(t);
                            isReadable(iFile) = true;
                        catch
                            isReadable(iFile) = false;
                        end
                    end
                    warning('on','imageio:tiffutils:libtiffWarning');

                    if ~all(isReadable)
                        % Display an error message if any file could not be read, specifying the corresponding files.
                        message = vertcat("The following SMLM files could not be read:", filename(~isReadable)');
                        uialert(app.CallingApp.MainUIFigure, message, 'Warning', 'Icon', 'warning');

                        % Remove the unreadable files from the original filelist.
                        fullFilenames = fullFilenames(isReadable);
                    end

                    % Add to the existing entries, but prevent repetitions. Store the appended list
                    % and display it in the list box.
                    app.S.files.inputFiles = union(app.ListBox.ItemsData, fullFilenames, 'stable');
                    app.S.files.inputFiles = app.S.files.inputFiles(:);
                    app.ListBox.ItemsData = app.S.files.inputFiles;
                    showListBoxItems(app);

                    % Based on this new list, also read in the corresponding (valid) mask files and store the resulting
                    % mask polygons.
                    app.S = updateMasksForS(app.S);

                    % Update the new number of files in the batch text.
                    updateBatchText(app);

                case 'Noise Files'
                    % Request one or more files to add to the current list.
                    [filename, pathname] = uigetfile({'*.tif';'*.TIF'}, ...
                        'Select CMOS noise file(s)', app.lastDirectory, 'MultiSelect','on');

                    % MATLAB app glitch: use this to return focus to the main window and also the calling app.
                    figure(app.CallingApp.MainUIFigure);
                    figure(app.UIFigure);

                    % Special handling of the input dialog results.
                    if isequal(filename,0) % User selected Cancel or closed the window.
                        return
                    elseif ~iscell(filename) %  User selected only one file.
                        filename = {filename};
                    end

                    % Store the current path.
                    app.lastDirectory = pathname;

                    % Build the full filenames.
                    fullFilenames = cellfun(@(x) fullfile(pathname, x), filename, 'uni', 0);

                    % Check the file integrity.
                    warning('off','imageio:tiffutils:libtiffWarning');
                    isReadable = false(size(fullFilenames));
                    for iFile = 1:numel(isReadable)
                        try
                            t = Tiff(fullFilenames{iFile});
                            close(t);
                            isReadable(iFile) = true;
                        catch
                            isReadable(iFile) = false;
                        end
                    end
                    warning('on','imageio:tiffutils:libtiffWarning');

                    if ~all(isReadable)
                        % Display an error message if any file could not be read, specifying the corresponding files.
                        message = vertcat("The following noise files could not be read:", filename(~isReadable)');
                        uialert(app.CallingApp.MainUIFigure, message, 'Warning', 'Icon', 'warning');

                        % Remove the unreadable files from the original filelist.
                        fullFilenames = fullFilenames(isReadable);
                    end

                    % Add to the existing entries, but prevent repetitions. Store the appended list
                    % and display it in the list box.
                    app.S.files.noiseFiles = union(app.ListBox.ItemsData, fullFilenames, 'stable');
                    app.S.files.noiseFiles = app.S.files.noiseFiles(:);
                    app.ListBox.ItemsData = app.S.files.noiseFiles;
                    showListBoxItems(app);

                case 'Output File'
                    % Ask the user for a file name and path to save to data.
                    [filename, pathname] = ...
                        uiputfile('*.xlsx', 'Save batch analysis data', app.lastDirectory);

                    % MATLAB app glitch: use this to return focus to the main window and also the calling app.
                    figure(app.CallingApp.MainUIFigure);
                    figure(app.UIFigure);

                    % Special handling if the user selected Cancel or closed the window.
                    if isequal(filename, 0)
                        return
                    end

                    % Store the current path and the selected file. Display it in the list box.
                    app.lastDirectory = pathname;
                    app.S.files.outputFile = fullfile(pathname, filename);
                    app.ListBox.ItemsData = cellstr(app.S.files.outputFile);
                    showListBoxItems(app);

                otherwise
                    % Empty.
            end

            % Check if files are present in the list and enable/disable the 'remove selection' button accordingly.
            app.removeSelectionButton.Enable = ~isempty(app.ListBox.ItemsData);

            % Send the changed parameter structure to the calling app.
            app.CallingApp.externalChangeOfS(app.S);
        end

        % Button pushed function: removeSelectionButton
        function removeSelectionButtonPushed(app, event)
            % Check which files will remain.
            selectedFiles = app.ListBox.Value;
            allFiles = app.ListBox.ItemsData;
            remainingFiles = setxor(allFiles, selectedFiles, 'stable');

            % Store the curtailed list and display it in the list box.
            if strcmp(app.Tree.SelectedNodes.Text, 'Input Files')
                app.S.files.inputFiles = remainingFiles(:);

                % Also remove the corresponding mask files and polygons (empty or not).
                [~,removeIDs] = intersect(allFiles, selectedFiles);
                app.S.files.maskFiles(removeIDs) = [];
                app.S.tracking.maskPolygons(removeIDs) = [];

            elseif strcmp(app.Tree.SelectedNodes.Text, 'Noise Files')
                app.S.files.noiseFiles = remainingFiles(:);
            end
            app.ListBox.ItemsData = remainingFiles(:);
            showListBoxItems(app);

            % Check if files are present in the list and enable/disable the 'remove selection' button accordingly.
            app.removeSelectionButton.Enable = ~isempty(app.ListBox.ItemsData);

            % Send the changed parameter structure to the calling app.
            app.CallingApp.externalChangeOfS(app.S);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            switch app.DropDown.Tag
                case 'ClustParameters'
                    algorithm = app.DropDown.Value;
                    app.S.cluster.algorithm = algorithm;
                    app.chosenAlgNode.Text = algorithm;
                    delete(app.chosenAlgNode.Children);
                    app.AlgParametersNode = uitreenode(app.chosenAlgNode, "Text", 'Parameters');
                    if strcmp(algorithm, 'Voronoi')
                        app.EdgeTrimmingNode = uitreenode(app.chosenAlgNode, "Text", 'Edge Trimming');
                        app.ButtonGroup.Visible = "on";
                        if contains(app.S.cluster.input, "track")
                            app.S.cluster.input = "track centroids";
                            app.RadButton1.Value = true;
                        end
                    elseif strcmp(algorithm, 'DBSCAN')
                        app.ButtonGroup.Visible = "on";
                        if contains(app.S.cluster.input, "track")
                            app.S.cluster.input = "track centroids";
                            app.RadButton1.Value = true;
                        end
                    elseif strcmp(algorithm, 'NASTIC')
                        app.ButtonGroup.Visible = "off";
                        app.S.cluster.input = 'tracks';
                        app.UITable.RowName = 'at least N tracks per cluster';
                    elseif strcmp(algorithm, 'segNASTIC')
                        app.ButtonGroup.Visible = "off";
                        app.S.cluster.input = 'tracks';
                        app.UITable.RowName = 'at least N tracks per cluster';
                    end
                    expand(app.chosenAlgNode);
                    updateBatchText(app);

                case 'Edge Trimming'          
                    if strcmp(app.DropDown.Value, 'manual')
                        app.UITable.Position = app.goPos.UITable + 66*[0 1 0 -1];
                        if isnumeric(app.S.cluster.Voronoi.trimBoundary)
                            app.UITable.Data = ...
                                num2cell(reshape(app.S.cluster.Voronoi.trimBoundary,2,2)');
                        else
                            app.UITable.Data = num2cell(zeros(2));
                        end
                        app.UITable.ColumnWidth = 'fit'; drawnow
                        app.UITable.ColumnWidth = '1x';
                        app.UITable.Visible = "on";
                    else
                        app.S.cluster.Voronoi.trimBoundary = app.DropDown.Value;
                        app.UITable.Visible = "off";
                    end

                otherwise
            end

            % Send the changed parameter structure to the calling app.
            app.CallingApp.externalChangeOfS(app.S);
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            switch app.CheckBox.Tag
                case 'Edge Trimming'
                    [app.ButtonGroup.Visible, app.DropDown.Visible, app.DropDownLabel.Visible] = ...
                        deal(app.CheckBox.Value);
                    app.UITable.Visible = strcmp(app.DropDown.Value, 'manual') & app.CheckBox.Value;
                    app.S.cluster.Voronoi.trimEdges = app.CheckBox.Value;

                case {'Input Files', 'Noise Files', 'Output File', }
                    app.isShortNames = app.CheckBox.Value;
                    showListBoxItems(app);

                case 'MSDparameters'
                    if app.CheckBox.Value == true
                        app.S.MSDanalysis.linFitPoints = [2, app.UITable.Data{4}];
                    else
                        app.S.MSDanalysis.linFitPoints = app.UITable.Data{4};
                    end

                otherwise
            end

            % Send the changed parameter structure to the calling app.
            app.CallingApp.externalChangeOfS(app.S);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            switch app.UITable.Tag
                case 'Analysis Steps'                   
                    % The analysis steps are interdependent and will also update the tree structure.
                    app.S.general.analysisSteps = app.UITable.Data;
                    updateAnalysisSteps(app);
                    updateBatchText(app);

                case 'Raw Data Output'
                    app.S.general.rawDataOutput = app.UITable.Data;
                    updateBatchText(app);

                case 'LocParameters'
                    % Update the localization parameters in the batch structure.
                    app.S.localization.offset = app.UITable.Data{1};
                    app.S.localization.conversion = app.UITable.Data{2};
                    app.S.localization.pixelSize = app.UITable.Data{3};
                    app.S.localization.filterSize = app.UITable.Data{4};
                    app.S.localization.cutoff = app.UITable.Data{5};
                    
                    if ~mod(app.UITable.Data{6},2)
                        % For the PSF ROI, only odd values are allowed.
                        app.UITable.Data{6} = app.UITable.Data{6} + 1;
                    end
                    app.S.localization.PSFROI = app.UITable.Data{6};

                case 'LocLimits'
                    % Update the histogram limits in the batch structure.
                    app.S.localization.limits = app.UITable.Data;

                case 'TrackParameters'
                    % Update the tracking parameters in the batch structure.
                    app.S.tracking.maxGapClose = app.UITable.Data{2};
                    app.S.tracking.maxLinkDistance = app.UITable.Data{1};

                case 'TrackLimits'
                    % Update the histogram limits in the batch structure. Make sure that the minimum number
                    % localizations per track is 3.
                    if app.UITable.Data{1,1} < 3
                        app.UITable.Data{1,1:2} = [3, Inf];
                    end
                    app.S.tracking.limits = app.UITable.Data;

                case 'MSDparameters'
                    % Update the MSD parameters in the batch structure.
                    app.S.MSDanalysis.frameRate = app.UITable.Data{1};
                    app.S.MSDanalysis.minTrackLength = app.UITable.Data{2};
                    app.S.MSDanalysis.adjR2 = app.UITable.Data{3};
                    if app.CheckBox.Value == true
                        app.S.MSDanalysis.linFitPoints = [2, app.UITable.Data{4}];
                    else
                        app.S.MSDanalysis.linFitPoints = app.UITable.Data{4};
                    end
                    app.S.MSDanalysis.splitValue = app.UITable.Data{5};

                case 'ClustParameters'
                    % Update the general cluster analysis parameters in the batch structure.
                     app.S.cluster.minN = app.UITable.Data(1);

                case 'AlgParametersVoronoi'
                    % Update the Voronoi algorithm parameters in the batch structure.
                    if strcmp(app.S.cluster.Voronoi.method, 'density')
                        app.S.cluster.Voronoi.alpha = app.UITable.Data(1);
                    else
                        app.S.cluster.Voronoi.areaThreshold = app.UITable.Data(1);
                    end

                case 'AlgParametersDBSCAN'
                    % First, store the changed value.
                    switch event.Indices(1)
                        case 1
                            app.S.cluster.DBSCAN.minPts = app.UITable.Data{1};
                        case 2
                            app.S.cluster.DBSCAN.estimateEpsilon = app.UITable.Data{2};
                            if app.UITable.Data{2} == true
                                % Estimate epsilon switched to true.
                                app.UITable.RowName = {'minimum points';['estimate ', char(949)]};
                                app.UITable.Position = app.goPos.UITable + 93*[0 1 0 -1];
                                app.UITable.Data = {app.S.cluster.DBSCAN.minPts; true};           
                            else
                                % Estimate epsilon switched to false.
                                app.UITable.RowName = {'minimum points';['estimate ', char(949)]; ...
                                    char(949)};
                                app.UITable.Position = app.goPos.UITable + 70*[0 1 0 -1];
                                app.UITable.Data = {app.S.cluster.DBSCAN.minPts; false; ...
                                    app.S.cluster.DBSCAN.epsilon};
                            end

                        case 3
                            app.S.cluster.DBSCAN.epsilon = app.UITable.Data{3};
                        otherwise
                    end

                case 'AlgParametersNASTIC'
                    % Update the NASTIC algorithm parameters in the batch structure.
                    app.S.cluster.NASTIC.r = app.UITable.Data{1};

                case 'AlgParametersSegNASTIC'
                    % Update the segNASTIC algorithm parameters in the batch structure.
                    app.S.cluster.segNASTIC.r = app.UITable.Data{1};
                    app.S.cluster.segNASTIC.nSegmentOverlaps = app.UITable.Data{2};

                case 'Edge Trimming'
                    app.S.cluster.Voronoi.trimBoundary = reshape(cell2mat(app.UITable.Data)',1,4);

                otherwise

            end

            % Send the changed parameter structure to the calling app.
            app.CallingApp.externalChangeOfS(app.S);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            switch app.ButtonGroup.Tag
                case 'ClustParameters'
                    app.S.cluster.input = app.ButtonGroup.SelectedObject.Text;
                    choiceString = {'locs', 'tracks'};
                    app.UITable.RowName = sprintf('at least N %s per cluster', ...
                        choiceString{contains(app.S.cluster.input, 'track') + 1});

                case 'AlgParametersVoronoi'
                    method = app.ButtonGroup.SelectedObject.Text;
                    app.S.cluster.Voronoi.method = method;
                    if strcmp(method, 'density')
                        app.UITable.RowName = {'alpha'};
                        app.UITable.ColumnName = {};
                        app.UITable.Data = app.S.cluster.Voronoi.alpha;
                        app.UITable.Tooltip = "Only those Voronoi cells with a first-rank " + ...
                                    "density above ""alpha"" times the average density are " + ...
                                    "potentially part of a cluster";
                    else
                        app.UITable.RowName = {'area threshold'};
                        app.UITable.ColumnName = {};
                        app.UITable.Data = app.S.cluster.Voronoi.areaThreshold;
                        app.UITable.Tooltip = "Only those Voronoi cells with an area " + ...
                                    "below this threshold value in µm² are potentially part " + ...
                                    "of a cluster";
                    end
                    updateBatchText(app);

                case 'Edge Trimming'
                    app.S.cluster.Voronoi.trimMethod = app.ButtonGroup.SelectedObject.Text{:};

                otherwise

            end

            % Send the changed parameter structure to the calling app.
            app.CallingApp.externalChangeOfS(app.S);
        end

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.mat', 'Select parameter (.mat) file', ...
                app.lastDirectory);
            % MATLAB app glitch: use this to return focus to the main window and also the calling app.
            figure(app.CallingApp.MainUIFigure);
            figure(app.UIFigure);

            % Special handling of the input dialog results.
            if isequal(filename,0) % user selected Cancel or closed the window
                return
            end

            % Load the .mat file and store the current path.
            app.S = load(fullfile(pathname, filename));
            app.lastDirectory = pathname;

            % Hide all objects in the options panel and reset the tags.
            set(app.hAll, 'Visible', 'off'); drawnow
            [app.UITable.Tag, app.DropDown.Tag, app.ButtonGroup.Tag, app.FileButton.Tag] = deal('');

            % The stored analysis steps might influence the tree nodes, so update them. Delete the
            % entire cluster analysis node, so its contents will be repopulated according to the
            % stored algorithm.
            delete(app.ClusterAnalysisNode);
            updateAnalysisSteps(app);
            updateBatchText(app);

            % Unselect any nodes.
            app.Tree.SelectedNodes = [];

            % Send the loaded parameter structure to the calling app.
            app.CallingApp.externalChangeOfS(app.S);
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            % Ask the user for a file name and path to save to data. 
             [filename, pathname] = ...
                 uiputfile('*.mat','Save parameter (.mat) file', app.lastDirectory);

            % MATLAB app glitch: use this to return focus to the main window and also the calling app.
            figure(app.CallingApp.MainUIFigure);
            figure(app.UIFigure);

             % special handling if user selected Cancel or closed the window
             if isequal(filename,0)
                 return
             else
                 app.lastDirectory = pathname;
                 saveStruct = app.S;
                 save(fullfile(pathname, filename), '-struct', 'saveStruct');
             end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Close the parameter app.
            delete(app)   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'parameters';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OptionsPanel = uipanel(app.UIFigure);
            app.OptionsPanel.Title = 'Options';
            app.FileButton = uibutton(app.OptionsPanel, 'push');
            app.FileButton.ButtonPushedFcn = createCallbackFcn(app, @FileButtonPushed, true);
            app.FileButton.Text = 'choose output file';
            app.UITable = uitable(app.OptionsPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.ButtonGroup = uibuttongroup(app.OptionsPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'input';
            app.RadButton2 = uiradiobutton(app.ButtonGroup);
            app.RadButton2.Text = 'localizations';
            app.RadButton1 = uiradiobutton(app.ButtonGroup);
            app.RadButton1.Text = 'track centroids';
            app.RadButton1.Value = true;
            app.CheckBox = uicheckbox(app.OptionsPanel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = 'short names';
            app.CheckBox.Value = true;
            app.DropDownLabel = uilabel(app.OptionsPanel);
            app.DropDownLabel.Text = 'boundary';
            app.DropDown = uidropdown(app.OptionsPanel);
            app.DropDown.Items = {'Voronoi', 'manual', 'Option 3', 'Option 4'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Voronoi';
            app.ListBox = uilistbox(app.OptionsPanel);
            app.ListBox.Items = {'Item 4'};
            app.ListBox.Multiselect = 'on';
            app.ListBox.Value = {'Item 4'};
            app.removeSelectionButton = uibutton(app.OptionsPanel, 'push');
            app.removeSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @removeSelectionButtonPushed, true);
            app.removeSelectionButton.Text = 'remove selection';
            app.Tree = uitree(app.UIFigure);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.GeneralNode = uitreenode(app.Tree);
            app.GeneralNode.Text = 'General';
            app.analysisSteps = uitreenode(app.GeneralNode);
            app.analysisSteps.Text = 'Analysis Steps';
            app.rawDataOutput = uitreenode(app.GeneralNode);
            app.rawDataOutput.Text = 'Raw Data Output';
            app.FilesNode = uitreenode(app.Tree);
            app.FilesNode.Text = 'Files';
            app.InputFilesNode = uitreenode(app.FilesNode);
            app.InputFilesNode.Text = 'Input Files';
            app.NoiseFilesNode = uitreenode(app.FilesNode);
            app.NoiseFilesNode.Text = 'Noise Files';
            app.OutputFileNode = uitreenode(app.FilesNode);
            app.OutputFileNode.Text = 'Output File';
            app.LocalizationNode = uitreenode(app.Tree);
            app.LocalizationNode.Text = 'Localization';
            app.LocParametersNode = uitreenode(app.LocalizationNode);
            app.LocParametersNode.Text = 'Parameters';
            app.LocLimitsNode = uitreenode(app.LocalizationNode);
            app.LocLimitsNode.Text = 'Limits';
            app.TrackingNode = uitreenode(app.Tree);
            app.TrackingNode.Text = 'Tracking';
            app.TrackParametersNode = uitreenode(app.TrackingNode);
            app.TrackParametersNode.Text = 'Parameters';
            app.TrackLimitsNode = uitreenode(app.TrackingNode);
            app.TrackLimitsNode.Text = 'Limits';
            app.MSDanalysisNode = uitreenode(app.Tree);
            app.MSDanalysisNode.Text = 'MSD Analysis';
            app.MSDparametersNode = uitreenode(app.MSDanalysisNode);
            app.MSDparametersNode.Text = 'Parameters';
            app.ClusterAnalysisNode = uitreenode(app.Tree);
            app.ClusterAnalysisNode.Text = 'Cluster Analysis';
            app.ClustParametersNode = uitreenode(app.ClusterAnalysisNode);
            app.ClustParametersNode.Text = 'Parameters';
            app.chosenAlgNode = uitreenode(app.ClusterAnalysisNode);
            app.chosenAlgNode.Text = 'chosenAlgorithm';
            app.AlgParametersNode = uitreenode(app.chosenAlgNode);
            app.AlgParametersNode.Text = 'Parameters';
            app.EdgeTrimmingNode = uitreenode(app.chosenAlgNode);
            app.EdgeTrimmingNode.Text = 'Edge Trimming';
            app.loadButton = uibutton(app.UIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'load';
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = chooseBatchParams(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end