classdef RangeSlider < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        upLimEditFieldLabel  matlab.ui.control.Label
        upLimEditField       matlab.ui.control.NumericEditField
        loLimEditFieldLabel  matlab.ui.control.Label
        loLimEditField       matlab.ui.control.NumericEditField
        loLimSlider          matlab.ui.control.Slider
        upLimSlider          matlab.ui.control.Slider
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        ValueChanged
    end

    properties (Access = private)
        EnableOnOff logical = true;
        GlobalLimits (1,2) double = [0, 300];
        GlobalValues (1,2) double = [0, 300];
    end
    
    properties (Access = public)
        Enable logical = true;
        Limits (1,2) double {mustBeVector(Limits), mustBeFinite(Limits)} = [0 300];
        Value (1,2) double = [0 300];
    end
    
% This slider / edit field combo allows setting a range. The lower value can be set by interacting with the top slider
% or left edit field, the upper value by the bottom slider or right edit field. Programmatically, the range interval can
% be set by the property 'Value' (2-element vector). The slider limits can be set similarly by the property 'Limits'.
% Any input is checked for feasibility and the range values are adjusted accordingly (forcing them into the limits or
% switching them around to prevent "negative" ranges). The 'Limits' and 'Value' vectors can have any order, they will be
% sorted ascendingly internally. Any value/limit changes will update all components.
% Setting the sliders to the minimum or maximum value (given by the limits), results in values of 0 and Inf,
% respectively. Conversely, setting the edit fields to 0 or Inf will set the sliders to their minimum or maximum
% position.
% So, by setting the sliders to the minimum/maximum positions, the user can signal that no limits should be used
% (although the sliders still appear with a certain range). Setting values to 0 or Inf in the edit fields pushes the
% slider knobs to their minimum/maximum positions to indicate that there are no limits in place.
    
    methods (Access = private)
        
        function adjustValues(comp, values)
            % Sort the values if they were given in the wrong order.
            values = sort(values);

            % When the chosen values hit or go beyond the limits, set them to 0 for the lower value and Inf for the
            % upper value.
            if values(1) <= comp.GlobalLimits(1)
                comp.GlobalValues(1) = 0;
            else
                comp.GlobalValues(1) = max(comp.GlobalLimits(1), values(1));
            end

            if values(2) >= comp.GlobalLimits(2)
                comp.GlobalValues(2) = Inf;
             else
                comp.GlobalValues(2) = min(comp.GlobalLimits(2), values(2));
            end
        end
        
        function updateComponents(comp)
            % Set the components to the certified values.
            comp.loLimEditField.Value = comp.GlobalValues(1);
            comp.upLimEditField.Value = comp.GlobalValues(2);
            [comp.loLimSlider.Limits, comp.upLimSlider.Limits] = deal(comp.GlobalLimits);
            
            if comp.loLimEditField.Value == 0
                comp.loLimSlider.Value = comp.loLimSlider.Limits(1);
            else
                comp.loLimSlider.Value = comp.GlobalValues(1);
            end

            if comp.upLimEditField.Value == Inf
                comp.upLimSlider.Value = comp.upLimSlider.Limits(2);
            else
                comp.upLimSlider.Value = comp.GlobalValues(2);
            end

            % The input values might have been adjusted, so store the certified ones in the
            % main component. This way, the user can try to set one value (by interacting with the
            % components) or both values (by setting the main component property 'Value') and
            % immediately read them out again to check if they were accepted or had to be adjusted.
            comp.Limits = comp.GlobalLimits;
            comp.Value = comp.GlobalValues;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: loLimEditField, loLimSlider, 
        % ...and 2 other components
        function loLimSliderValueChanged(comp, event)
            switch event.Source.Tag
                case 'lo'
                    % The user changed either the value of the lower limit edit field or the lower slider. Combine with
                    % the value of the upper limit.
                    values = [round(event.Source.Value), comp.upLimEditField.Value];

                case 'up'
                    % The user changed either the value of the upper limit edit field or the upper
                    % slider. Combine with the value of the lower limit.
                    values = [comp.loLimEditField.Value, round(event.Source.Value)];
            end
            
            % Adjust the values (maybe reversing them or forcing them into the limits) and update the components.
            adjustValues(comp, values);
            updateComponents(comp);
            
            % Notify the executing app that the user has interacted with the component (i.e., changed the value of
            % either slider or edit field.
            notify(comp, 'ValueChanged');
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components.

            % Process the limits first. They can have the same value but must otherwise be ascending. They can only be
            % set via the 'Limits' property of the main component.
            comp.GlobalLimits = round(sort(comp.Limits));

            % Process the input value vector. Depending on the limits, one or both values must be adjusted.
            adjustValues(comp, comp.Value);

            % Update all components.
            updateComponents(comp);

            % Enable/disable all components.
            [comp.upLimEditField.Enable, comp.loLimEditField.Enable, ...
                comp.loLimSlider.Enable, comp.upLimSlider.Enable] = deal(comp.Enable);
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 471 79];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.upLimSlider = uislider(comp);
            comp.upLimSlider.Limits = [0 300];
            comp.upLimSlider.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @loLimSliderValueChanged, true);
            comp.upLimSlider.Tag = 'up';
            comp.upLimSlider.Value = 300;
            comp.loLimSlider = uislider(comp);
            comp.loLimSlider.Limits = [0 300];
            comp.loLimSlider.MajorTicks = [];
            comp.loLimSlider.MajorTickLabels = {'{}'};
            comp.loLimSlider.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @loLimSliderValueChanged, true);
            comp.loLimSlider.MinorTicks = [];
            comp.loLimSlider.Tag = 'lo';
            comp.loLimEditField = uieditfield(comp, 'numeric');
            comp.loLimEditField.ValueDisplayFormat = '%.0f';
            comp.loLimEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @loLimSliderValueChanged, true);
            comp.loLimEditField.Tag = 'lo';
            comp.loLimEditFieldLabel = uilabel(comp);
            comp.loLimEditFieldLabel.Text = 'lower limit';
            comp.upLimEditField = uieditfield(comp, 'numeric');
            comp.upLimEditField.ValueDisplayFormat = '%.0f';
            comp.upLimEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @loLimSliderValueChanged, true);
            comp.upLimEditField.Tag = 'up';
            comp.upLimEditField.Value = 300;
            comp.upLimEditFieldLabel = uilabel(comp);
            comp.upLimEditFieldLabel.Text = 'upper limit';
        end
    end
end